classdef PolarimetryPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolarimetryAnalysisUIFigure   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ImageTool                     matlab.ui.control.Image
        ImageMicroscope               matlab.ui.control.Image
        PolarimetryMethod             matlab.ui.control.DropDown
        CloseFiguresButton            matlab.ui.control.Button
        PolarimetryAnalysisLogo       matlab.ui.control.Button
        DropDownTool                  matlab.ui.control.DropDown
        DownloadFolderButton          matlab.ui.control.Button
        AnalysisButton                matlab.ui.control.Button
        AddROIButton                  matlab.ui.control.Button
        DownloadFileButton            matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        FluorescenceTab               matlab.ui.container.Tab
        StackSlider                   matlab.ui.control.Slider
        StackSliderLabel              matlab.ui.control.Label
        Reload                        matlab.ui.control.Button
        NameOfFile                    matlab.ui.control.EditField
        ContrastFluoButton            matlab.ui.control.Button
        DetAngle                      matlab.ui.control.Button
        MaximumEditField              matlab.ui.control.NumericEditField
        MaximumEditFieldLabel         matlab.ui.control.Label
        ContrastSlider                matlab.ui.control.Slider
        UIAxesFluo                    matlab.ui.control.UIAxes
        ThresholdingMaskTab           matlab.ui.container.Tab
        ColormapThrshldButton         matlab.ui.control.Button
        ContrastThrshldButton         matlab.ui.control.Button
        ContrastThrshldSlider         matlab.ui.control.Slider
        ExportMask                    matlab.ui.control.Button
        NoBackgroundButton            matlab.ui.control.Button
        TransparencyLabel             matlab.ui.control.Label
        TransparencySlider            matlab.ui.control.Slider
        ILowMaximumSlider             matlab.ui.control.Slider
        ILowImaxLabel                 matlab.ui.control.Label
        ILowEditField                 matlab.ui.control.NumericEditField
        ILowEditFieldLabel            matlab.ui.control.Label
        UIAxesThrshld                 matlab.ui.control.UIAxes
        OptionsTab                    matlab.ui.container.Tab
        ShowIndividualFitCheckBox     matlab.ui.control.CheckBox
        Switchminmax                  matlab.ui.control.Switch
        SwitchminmaxLabel             matlab.ui.control.Label
        PlotoptionsPanel              matlab.ui.container.Panel
        NumberofpixelsseparatingsticksLabel  matlab.ui.control.Label
        PixelsPerStickWidthLabel      matlab.ui.control.Label
        PixelsPerStickSpinnerWidth    matlab.ui.control.Spinner
        PixelsPerStickSpinnerHeight   matlab.ui.control.Spinner
        PixelsPerStickHeightLabel     matlab.ui.control.Label
        AddAxesCheckBox               matlab.ui.control.CheckBox
        UITableVariable               matlab.ui.control.Table
        UITableDisplay                matlab.ui.control.Table
        DeleteAllButton               matlab.ui.control.Button
        perROISwitch                  matlab.ui.control.Switch
        perROILabel                   matlab.ui.control.Label
        UITableSaveExtension          matlab.ui.control.Table
        AdvancedTab                   matlab.ui.container.Tab
        OffsetanglePanel              matlab.ui.container.Panel
        Switchoffset                  matlab.ui.control.Switch
        OffsetAngleDegEditField       matlab.ui.control.NumericEditField
        OffsetangledegEditFieldLabel  matlab.ui.control.Label
        DarkPanel                     matlab.ui.container.Panel
        CalculatedDarkValueEditField  matlab.ui.control.NumericEditField
        UserDarkValueEditField        matlab.ui.control.NumericEditField
        UserdarkvalueEditFieldLabel   matlab.ui.control.Label
        CalculatedDarkValueCheckBox   matlab.ui.control.CheckBox
        DiskConeCalibrationDataPanel  matlab.ui.container.Panel
        Angles4polarDropDown          matlab.ui.control.DropDown
        SelectDiskCone                matlab.ui.control.DropDown
        NameOfDiskCone                matlab.ui.control.EditField
        DisplayConeButton             matlab.ui.control.Button
        RotationPanel                 matlab.ui.container.Panel
        FigureRotationEditField       matlab.ui.control.NumericEditField
        FigureLabel                   matlab.ui.control.Label
        StickRotationEditField        matlab.ui.control.NumericEditField
        StickLabel                    matlab.ui.control.Label
        RemovebackgroundPanel         matlab.ui.container.Panel
        NoiseremovallevelEditField    matlab.ui.control.NumericEditField
        NoiseremovallevelEditFieldLabel  matlab.ui.control.Label
        NoiseHeightEditField          matlab.ui.control.NumericEditField
        NoiseheightEditFieldLabel     matlab.ui.control.Label
        NoiseWidthEditField           matlab.ui.control.NumericEditField
        NoisewidthEditFieldLabel      matlab.ui.control.Label
        ClickBackgroundButton         matlab.ui.control.Button
        NoiseFactorEditField          matlab.ui.control.NumericEditField
        NoisefactorEditFieldLabel     matlab.ui.control.Label
        BinningPanel                  matlab.ui.container.Panel
        BinHeightEditField            matlab.ui.control.NumericEditField
        BinheightEditFieldLabel       matlab.ui.control.Label
        BinWidthEditField             matlab.ui.control.NumericEditField
        BinwidthEditFieldLabel        matlab.ui.control.Label
        AboutTab                      matlab.ui.container.Tab
        ButtonMail                    matlab.ui.control.Button
        ButtonManual                  matlab.ui.control.Button
        ButtonGithub                  matlab.ui.control.Button
        CheckWebsite                  matlab.ui.control.Button
        InfoAbout                     matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Itot % Mean intensity
        ROI % ROI map with ROI index numbers
        int_roi % index of ROIs
        Stack % stack
        P % parameters
        Folder % folder name for batch
        MaskFolder % folder name for masks
        Sumcor % mean intensity 
        AdvanceFile % automatic or manual advance for batch
        path % path of folder
        fs % file stack names for batch
        Mask % Mask 
        Patch % Patch objects
        SingleFile % Single file or folder
        ROI_ILow % same as ROI; values of ILow instead of the ROI index numbers
        ClickbackgroundChanged % if true, change of Noise_tot
        ImageThrshld % Label for image Thresholding 
        Variables % Structure of the variables to be displayed (rho, psi, s2, s4, etc...) 
        ThrshldColormap % colormap used for thresholding image
        DiskData % names of disk cones used in the app (1PF)
        DiskDataLabels % labels identifying the disk cones (1PF)
        DiskDataTooltip % tooltip for the disk cones (1PF)
        tform % tform for registration (4POLAR)
        xpos % x-positions of the rectangles for registration (4POLAR)
        ypos % y-positions of the rectangles for registration (4POLAR)
        npix % number of pixels for padding rectangles (4POLAR)
        invKmat % Inverse of the calibration matrix (4POLAR)
        order % Order of the polarizations: order(i)=polarization of the i-th panel (4POLAR)
    end
    
    methods (Access = private)
        
        function Represent_Fluo(app, Itot, DrawNow)
            if ~DrawNow
                XLimFluo = app.UIAxesFluo.XLim;
                YLimFluo = app.UIAxesFluo.YLim;
            end
            if app.StackSlider.Value==0
                field = (app.Itot-min(Itot(:)))/(max(Itot(:))-min(Itot(:)));
            elseif app.StackSlider.Value<=app.Stack.nangle
                field = (app.Stack.Values(:,:,app.StackSlider.Value)-min(app.Stack.Values(:)))/(max(app.Stack.Values(:))-min(app.Stack.Values(:)));
            end
            im = imadjust(imsharpen(field),[0 1],[0 1],app.ContrastSlider.Value);
            if app.FigureRotationEditField.Value~=0
                im = imrotate(im,app.FigureRotationEditField.Value,'bilinear','crop');
            end
            imagesc(app.UIAxesFluo,im);
            axis(app.UIAxesFluo,'image');
            colormap(app.UIAxesFluo,gray); 
            if ~DrawNow 
                app.UIAxesFluo.XLim = XLimFluo;
                app.UIAxesFluo.YLim = YLimFluo;
            end
            if app.int_roi~=0 && ~isempty(app.Patch.XData)
                for it = 1:app.int_roi
                    prot = patch(app.UIAxesFluo,'XData',app.Patch.XData{it},'YData',app.Patch.YData{it},'FaceAlpha',0,'EdgeColor','w');
                    rotate(prot,[0 0 -1],app.FigureRotationEditField.Value,[app.Stack.Width/2,app.Stack.Height/2,0]);
                    htext = text(app.UIAxesFluo,app.Patch.XData{it}(1),app.Patch.YData{it}(1),num2str(it),'FontSize',20,'Color','w');
                    rotate(htext,[0 0 -1],app.FigureRotationEditField.Value,[app.Stack.Width/2,app.Stack.Height/2,0]);
                end
            end
        end
        
        function [Sumcor, Itot] = Define_Itot(app, Stack)
            dark = ComputeDark(app,Stack);
            app.CalculatedDarkValueEditField.ValueDisplayFormat = Stack.ValueDisplayFormat;
            app.CalculatedDarkValueEditField.Value = dark;
            if ~app.CalculatedDarkValueCheckBox.Value
                dark = app.UserDarkValueEditField.Value;
            end    
            Sumcor = sum((Stack.Values-dark).*(Stack.Values>=dark),3);       
            if app.BinHeightEditField.Value~=1 || app.BinWidthEditField.Value~=1
                BIN = ones(app.BinHeightEditField.Value,app.BinWidthEditField.Value);                                                     
                Itot = convn(Sumcor,BIN)/app.BinHeightEditField.Value/app.BinWidthEditField.Value; 
            else
                Itot = Sumcor;
            end
            app.MaximumEditField.ValueDisplayFormat = Stack.ValueDisplayFormat;
            app.MaximumEditField.Value = max(Itot(:));
        end
        
        function PolarimetryAnalysis(app, Stack, P, Mask, ROI)
            Chi2Threshold = 500; 
            [file.folder,file.name,~] = fileparts(P.filename);
            if app.CalculatedDarkValueCheckBox.Value
                dark = app.CalculatedDarkValueEditField.Value;
            else
                dark = app.UserDarkValueEditField.Value;
            end
            stack = Stack.Values-dark-app.NoiseremovallevelEditField.Value;
            stack = stack.*(stack>=0); 
            switch app.PolarimetryMethod.Value
                case '1PF'
                    field = padarray(sqrt(stack),[0 0 1],'circular','post');
                case {'CARS','SRS','SHG','2PF'}
                    field = padarray(stack,[0 0 1],'circular','post');
                case {'4POLAR 2D','4POLAR 3D'}
                    field = stack;
                    field(:,:,[2 3]) = stack(:,:,[3 2]);
            end
            if app.BinHeightEditField.Value~=1 || app.BinWidthEditField.Value~=1
                BIN = ones(app.BinHeightEditField.Value,app.BinWidthEditField.Value);
                field = convn(field,BIN);        
            end
            switch app.PolarimetryMethod.Value
                case {'1PF','CARS','SRS','SHG','2PF'}
                    Angle1D = linspace(0,180,Stack.nangle+1)+180-app.OffsetAngleDegEditField.Value;
                    Angle3D = reshape(Angle1D,1,1,[]);
                    E2 = cosd(2*Angle3D)+1i*sind(2*Angle3D);
                    A0 = trapz(Angle1D,field,3)/180;
                    A2 = 2*trapz(Angle1D,field.*E2,3)/180;
                    field_fit = A0+real(A2.*conj(E2));
                    A2 = A2./A0;
                    if any(strcmp(app.PolarimetryMethod.Value,{'CARS','SRS','SHG','2PF'}))
                        E4 = E2.^2;
                        A4 = 2*trapz(Angle1D,field.*E4,3)/180;
                        field_fit = field_fit+real(A4.*conj(E4));
                        A4 = A4./A0;
                    end
                    Chi2 = mean((field-field_fit).^2./field_fit,3); 
                case '4POLAR 3D'
                    M = tensorprod(app.invKmat,field,2,3);
                    S = M(1,:,:)+M(2,:,:)+M(3,:,:);
                    Pxy = reshape((M(1,:,:)-M(2,:,:))./S,size(ROI));
                    Puv = reshape(2*M(4,:,:)./S,size(ROI));
                    Pzz = reshape(M(3,:,:)./S,size(ROI));
                    lam = reshape((1-(Pzz+sqrt(Puv.^2+Pxy.^2)))/2,size(ROI));
                    A0 = sum(field,3)/4;
                case '4POLAR 2D'
                    M = tensorprod(app.invKmat,field,2,3);
                    S = M(1,:,:)+M(2,:,:);
                    Pxy = reshape((M(1,:,:)-M(2,:,:))./S,size(ROI));
                    Puv = reshape(2*M(3,:,:)./S,size(ROI));
                    lam = reshape((1-sqrt(Puv.^2+Pxy.^2))/2,size(ROI));
                    A0 = sum(field,3)/4;
            end        
            Rho_.Name = 'Rho';
            Rho_.LaTeX = '$\rho$';
            Rho_.Value = NaN(size(A0));
            Rho_.Display = app.UITableVariable.Data{1,1};
            Rho_.Min = app.UITableVariable.Data{1,2};
            Rho_.Max = app.UITableVariable.Data{1,3};
            Rho_.Colormap = hsv;
            switch app.PolarimetryMethod.Value
                case '1PF'
                    A2n = real(A2);
                    B2n = imag(A2);
                    [Y,X] = find(((ROI>0).*Mask~=0).*(abs(A2)<1).*(Chi2<=Chi2Threshold).*(Chi2>0));
                    ind = sub2ind(size(A0),Y,X);
                    Rho = app.P.RoTest(sub2ind(size(app.P.RoTest), 1+round((A2n(ind)+1)*(app.P.NbMapValues-1)/2),...
                        1+round((B2n(ind)+1)*(app.P.NbMapValues-1)/2)))+90;
                    Psi = app.P.PsiTest(sub2ind(size(app.P.RoTest), 1+round((A2n(ind)+1)*(app.P.NbMapValues-1)/2),...
                        1+round((B2n(ind)+1)*(app.P.NbMapValues-1)/2))); 
                    Filter = find((~isnan(Rho))&(~isnan(Psi)));
                    ind = sub2ind(size(A0),Y(Filter),X(Filter));
                    Rho_.Value(ind) = wrapTo360(2*(180-Rho(Filter)+app.StickRotationEditField.Value))/2;
                    Psi_.Name = 'Psi';
                    Psi_.LaTeX = '$\psi$';
                    Psi_.Value = NaN(size(A0));
                    Psi_.Value(ind) = Psi(Filter);
                    Psi_.Display = app.UITableVariable.Data{2,1};
                    Psi_.Min = app.UITableVariable.Data{2,2};
                    Psi_.Max = app.UITableVariable.Data{2,3};
                    Psi_.Colormap = jet; 
                    Int = A0(Filter);
                    X = X(Filter);
                    Y = Y(Filter);
                case {'CARS','SRS','2PF'}
                    [Y,X] = find(((ROI>0).*Mask~=0).*(abs(A2)<1).*(abs(A4)<1).*(Chi2<=Chi2Threshold).*(Chi2>0));
                    ind = sub2ind(size(A0),Y,X);
                    Rho_.Value(ind) = 0.5*rad2deg(angle(A2(ind)));
                    Rho_.Value(ind) = wrapTo360(2*(180-Rho_.Value(ind)+app.StickRotationEditField.Value))/2;
                    S2_.Name = 'S2';
                    S2_.LaTeX = '$S_2$';
                    S2_.Value = NaN(size(A0));
                    S2_.Value(ind) = 1.5*abs(A2(ind));
                    S2_.Display = app.UITableVariable.Data{2,1};
                    S2_.Min = app.UITableVariable.Data{2,2};
                    S2_.Max = app.UITableVariable.Data{2,3};
                    S2_.Colormap = jet;
                    S4_.Name = 'S4';
                    S4_.LaTeX = '$S_4$';
                    S4_.Value = NaN(size(A0));
                    S4_.Value(ind) = 6*abs(A4(ind)).*cosd(4*(0.25*rad2deg(angle(A4(ind)))-Rho_.Value(ind))); 
                    S4_.Display = app.UITableVariable.Data{3,1};
                    S4_.Min = app.UITableVariable.Data{3,2};
                    S4_.Max = app.UITableVariable.Data{3,3};
                    S4_.Colormap = jet;
                    Int = A0(ind);
                case 'SHG'
                    [Y,X] = find(((ROI>0).*Mask~=0).*(abs(A2)<1).*(abs(A4)<1).*(Chi2<=Chi2Threshold).*(Chi2>0));
                    ind = sub2ind(size(A0),Y,X);
                    Rho_.Value(ind) = 0.5*rad2deg(angle(A2(ind)));
                    Rho_.Value(ind) = wrapTo360(2*(180-Rho_.Value(ind)+app.StickRotationEditField.Value))/2;
                    S_SHG_.Name = 'S_SHG';
                    S_SHG_.LaTeX = '$S_\mathrm{SHG}$';
                    S_SHG_.Value = NaN(size(A0));
                    S_SHG_.Value(ind) = -0.5*(abs(A4(ind))-abs(A2(ind)))./(abs(A4(ind))+abs(A2(ind)))-0.65;
                    S_SHG_.Display = app.UITableVariable.Data{2,1};
                    S_SHG_.Min = app.UITableVariable.Data{2,2};
                    S_SHG_.Max = app.UITableVariable.Data{2,3};
                    S_SHG_.Colormap = jet;
                    Int = A0(ind);
                case '4POLAR 3D'
                    [Y,X] = find(((ROI>0).*Mask~=0).*(lam<1/3).*(lam>0).*(Pzz>lam));
                    ind = sub2ind(size(A0),Y,X);
                    Rho_.Value(ind) = 0.5*atan2d(Puv(ind),Pxy(ind));                  
                    Rho_.Value(ind) = wrapTo360(2*(180-Rho_.Value(ind)+app.StickRotationEditField.Value))/2;
                    Psi_.Name = 'Psi';
                    Psi_.LaTeX = '$\psi$';
                    Psi_.Value = NaN(size(A0));
                    Psi_.Value(ind) = 2*acosd((-1+sqrt(9-24*lam(ind)))/2);
                    Psi_.Display = app.UITableVariable.Data{2,1};
                    Psi_.Min = app.UITableVariable.Data{2,2};
                    Psi_.Max = app.UITableVariable.Data{2,3};
                    Psi_.Colormap = jet;
                    Eta_.Name = 'Eta';
                    Eta_.LaTeX = '$\eta$';
                    Eta_.Value = NaN(size(A0)); 
                    Eta_.Value(ind) = acosd(sqrt((Pzz(ind)-lam(ind))./(1-3*lam(ind))));
                    Eta_.Display = app.UITableVariable.Data{3,1};
                    Eta_.Min = app.UITableVariable.Data{3,2};
                    Eta_.Max = app.UITableVariable.Data{3,3};
                    Eta_.Colormap = parula;
                    Int = A0(ind);
                 case '4POLAR 2D'
                    [Y,X] = find(((ROI>0).*Mask~=0).*(lam<1/3).*(lam>0));
                    ind = sub2ind(size(A0),Y,X);
                    Rho_.Value(ind) = 0.5*atan2d(Puv(ind),Pxy(ind));                  
                    Rho_.Value(ind) = wrapTo360(2*(180-Rho_.Value(ind)+app.StickRotationEditField.Value))/2;
                    Psi_.Name = 'Psi';
                    Psi_.LaTeX = '$\psi$';
                    Psi_.Value = NaN(size(A0));
                    Psi_.Value(ind) = 2*acosd((-1+sqrt(9-24*lam(ind)))/2);
                    Psi_.Display = app.UITableVariable.Data{2,1};
                    Psi_.Min = app.UITableVariable.Data{2,2};
                    Psi_.Max = app.UITableVariable.Data{2,3};
                    Psi_.Colormap = jet;
                    Int = A0(ind);
            end
            Int_ = NaN(size(A0));
            X_ = NaN(size(A0));
            Y_ = NaN(size(A0));
            Int_(ind) = Int;
            X_(ind) = X;
            Y_(ind) = Y;
            if any(strcmp(app.PolarimetryMethod.Value,{'1PF','CARS','SRS','SHG','2PF'}))
                Chi2_ = NaN(size(A0));
                Chi2_(ind) = Chi2(ind);
            end
            %% Background fluo for Composite and Stick figures
            imbackground = imadjust(imsharpen(app.Itot/max(app.Itot(:))),[0 1],[0 1],app.ContrastSlider.Value);
            if app.FigureRotationEditField.Value~=0
                imbackground = imrotate(imbackground,app.FigureRotationEditField.Value,'bilinear','crop');
            end
            %% Generation of the plots
            figComposite = Composite(app,Rho_,imbackground,true,file);
            Sticks(app,Rho_,imbackground,Rho_.Value,true,file,Stack);
            Histos(app,Rho_,ROI,'polar1',file);
            switch app.PolarimetryMethod.Value
                case {'1PF','4POLAR 2D'}
                    Composite(app,Psi_,imbackground,false,file);
                    Sticks(app,Psi_,imbackground,Rho_.Value,false,file,Stack);
                    Histos(app,Psi_,ROI,'normal',file);
                case {'CARS','SRS','2PF'}
                    Composite(app,S2_,imbackground,false,file);
                    Sticks(app,S2_,imbackground,Rho_.Value,false,file,Stack);
                    Histos(app,S2_,ROI,'normal',file);
                    Composite(app,S4_,imbackground,false,file);             
                    Sticks(app,S4_,imbackground,Rho_.Value,false,file,Stack);              
                    Histos(app,S4_,ROI,'normal',file);
                case 'SHG'
                    Composite(app,S_SHG_,imbackground,false,file);
                    Sticks(app,S_SHG_,imbackground,Rho_.Value,false,file,Stack);
                    Histos(app,S_SHG_,ROI,'normal',file);
                case '4POLAR 3D'
                    Composite(app,Psi_,imbackground,false,file);
                    Sticks(app,Psi_,imbackground,Rho_.Value,false,file,Stack);
                    Histos(app,Psi_,ROI,'normal',file);
                    Composite(app,Eta_,imbackground,false,file);
                    Sticks(app,Eta_,imbackground,Rho_.Value,false,file,Stack);
                    Histos(app,Eta_,ROI,'polar2',file);
            end
            %% Polar response visualization at a pixel
            Show = 'Yes';
            if app.ShowIndividualFitCheckBox.Value && any(strcmp(app.PolarimetryMethod.Value,{'1PF','CARS','SRS','SHG','2PF'}))
                selectionOK = uiconfirm(app.PolarimetryAnalysisUIFigure,{'<b>(1)</b> Zoom into the region of interest, then press OK',...
                   '<b>(2)</b> Click on the pixel where you want to see the polar plots'},'Polar response visualization at a pixel',...
                   'Icon','Icons/round_timeline_black_48dp.png','Interpreter','html');
                while strcmp(Show,'Yes') && strcmp(selectionOK,'OK')
                    figure(figComposite);
                    [x,y] = ginput(1);
                    x = round(x);
                    y = round(y);
                    signal = field(y,x,:);
                    signal_fit = field_fit(y,x,:);
                    alpha = linspace(0,180,Stack.nangle+1);
                    rho = wrapTo360(2*(Rho_.Value(y,x)+app.FigureRotationEditField.Value))/2;
                    figure('name','Individual fit');
                    clf;
                    subplot(2,1,1);
                    plot(alpha,signal(:),'*',alpha,signal_fit(:),'-','LineWidth',2);
                    xlabel('$\alpha$','Interpreter','latex','FontSize',20); ylabel('counts','FontSize',20);
                    switch app.PolarimetryMethod.Value
                        case {'1PF','4POLAR 2D'}
                            title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $\psi =$ ' num2str(Psi_.Value(y,x),'%.2f')];
                        case {'CARS','SRS','2PF'}
                            title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $S_2 =$ ' num2str(S2_.Value(y,x),'%.2f')   ', $S_4 = $' num2str(S4_.Value(y,x),'%.2f')];
                        case 'SHG'
                            title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $S_\mathrm{SHG} =$ ' num2str(S_SHG_.Value(y,x),'%.2f')];
                        case '4POLAR 3D'
                            title_fig = ['$\rho =$ ' num2str(rho,'%.2f') ',   $\psi =$ ' num2str(Psi_.Value(y,x),'%.2f') ',   $\eta =$ ' num2str(Eta_.Value(y,x),'%.2f')];
                    end
                    title(title_fig,'interpreter','latex','FontSize',20);
                    xlim([0 180]);
                    set(gca,'FontSize',20)
                    subplot(2,1,2);
                    plot(alpha,signal(:)-signal_fit(:),'+',alpha,2*sqrt(signal_fit(:)),'r-',alpha,-2*sqrt(signal_fit(:)),'r-','LineWidth',2);
                    xlabel('$\alpha$','Interpreter','latex','FontSize',20); ylabel('Residuals','FontSize',20);
                    title(['$\chi_2 =$ ' num2str(Chi2_(y,x),'%.2f') ',   $I =$ ' num2str(app.Itot(y,x),app.Stack.ValueDisplayFormat)],'interpreter','latex','FontSize',20);
                    xlim([0 180]);
                    set(gca,'FontSize',20)
                    Show = uiconfirm(app.PolarimetryAnalysisUIFigure,'To see another data plot, change the region of interest and press Yes', ...
                        'Polar response visualization at a pixel','Options',{'Yes','No'},'DefaultOption',2,'Icon','Icons/round_timeline_black_48dp.png');
                end
            end 
            %% Saving to Microsoft Excel
            if app.UITableSaveExtension.Data{4}
                Tdata = [];
                if strcmp(app.perROISwitch.Value,'On')
                    for it = 1:max(ROI(:))
                        Rho = wrapTo360(2*(Rho_.Value(ROI==it & isfinite(Rho_.Value))+app.FigureRotationEditField.Value))/2;
                        MeanRho = circularmean(app,Rho);
                        DeltaRho = wrapTo180(2*(Rho-MeanRho))/2;
                        Int = Int_(ROI==it & isfinite(Rho_.Value));
                        N = numel(Rho);
                        switch app.PolarimetryMethod.Value
                            case {'1PF','4POLAR 2D'}
                                Psi = Psi_.Value(ROI==it & isfinite(Rho_.Value));
                                Results = [it,MeanRho,std(DeltaRho),mean(DeltaRho),mean(Psi),std(Psi),mean(Int),std(Int),mean(Int)*Stack.nangle,N]; 
                            case {'CARS','SRS','2PF'}
                                S2 = S2_.Value(ROI==it & isfinite(Rho_.Value));
                                S4 = S4_.Value(ROI==it & isfinite(Rho_.Value));
                                Results = [it,MeanRho,std(DeltaRho),mean(DeltaRho),mean(S2),std(S2),mean(S4),std(S4),mean(Int),std(Int),mean(Int)*Stack.nangle,N];
                            case 'SHG'
                                S_SHG = S_SHG_.Value(ROI==it & isfinite(Rho_.Value));
                                Results = [it,MeanRho,std(DeltaRho),mean(DeltaRho),mean(S_SHG),std(S_SHG),mean(Int),std(Int),mean(Int)*Stack.nangle,N];
                            case '4POLAR 3D'
                                Psi = Psi_.Value(ROI==it & isfinite(Rho_.Value));
                                Eta = Eta_.Value(ROI==it & isfinite(Rho_.Value));
                                Results = [it,MeanRho,std(DeltaRho),mean(DeltaRho),mean(Psi),std(Psi),mean(Eta),std(Eta),mean(Int),std(Int),mean(Int)*Stack.nangle,N];
                        end
                        Tdata = cat(1,Tdata,Results);
                    end
                else
                    Rho = wrapTo360(2*(Rho_.Value(ROI~=0 & isfinite(Rho_.Value))+app.FigureRotationEditField.Value))/2;
                    MeanRho = circularmean(app,Rho);
                    DeltaRho = wrapTo180(2*(Rho-MeanRho))/2;
                    Int = Int_(ROI~=0 & isfinite(Rho_.Value));
                    N = numel(Rho);
                    switch app.PolarimetryMethod.Value
                        case {'1PF','4POLAR 2D'}
                            Psi = Psi_.Value(ROI~=0 & isfinite(Rho_.Value));
                            Results = [1,MeanRho,std(DeltaRho),mean(DeltaRho),mean(Psi),std(Psi),mean(Int),std(Int),mean(Int)*Stack.nangle,N]; 
                        case {'CARS','SRS','2PF'}
                            S2 = S2_.Value(ROI~=0 & isfinite(Rho_.Value));
                            S4 = S4_.Value(ROI~=0 & isfinite(Rho_.Value));
                            Results = [1,MeanRho,std(DeltaRho),mean(DeltaRho),mean(S2),std(S2),mean(S4),std(S4),mean(Int),std(Int),mean(Int)*Stack.nangle,N];
                        case 'SHG'
                            S_SHG = S_SHG_.Value(ROI~=0 & isfinite(Rho_.Value));
                            Results = [1,MeanRho,std(DeltaRho),mean(DeltaRho),mean(S_SHG),std(S_SHG),mean(Int),std(Int),mean(Int)*Stack.nangle,N];
                        case '4POLAR 3D'
                            Psi = Psi_.Value(ROI~=0 & isfinite(Rho_.Value));
                            Eta = Eta_.Value(ROI~=0 & isfinite(Rho_.Value));
                            Results = [1,MeanRho,std(DeltaRho),mean(DeltaRho),mean(Psi),std(Psi),mean(Eta),std(Eta),mean(Int),std(Int),mean(Int)*Stack.nangle,N];    
                    end
                    Tdata = cat(1,Tdata,Results);
                end
                switch app.PolarimetryMethod.Value
                    case {'1PF','4POLAR 2D'}
                        T = array2table(Tdata,'VariableNames',...
                            {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanPsi','StdPsi','MeanInt','StdInt','TotalInt','N'});
                        T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T repmat({matlab.lang.makeValidName(app.NameOfDiskCone.Value)},size(Tdata,1),1)];
                        T.Properties.VariableNames{'Var12'} = 'Disk Cone';
                    case {'CARS','SRS','2PF'}
                        T = array2table(Tdata,'VariableNames',...
                            {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanS2','StdS2','MeanS4','StdS4','MeanInt','StdInt','TotalInt','N'});
                        T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T];
                    case 'SHG'
                        T = array2table(Tdata,'VariableNames',...
                            {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanS_SHG','StdS_SHG','MeanInt','StdInt','TotalInt','N'});
                        T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T];
                    case '4POLAR 3D'
                        T = array2table(Tdata,'VariableNames',...
                            {'ROI','MeanRho','StdRho','MeanDeltaRho','MeanPsi','StdPsi','MeanEta','StdEta','MeanInt','StdInt','TotalInt','N'});
                        T = [repmat({matlab.lang.makeValidName(file.name)},size(Tdata,1),1) T];
                end
                T.Properties.VariableNames{'Var1'} = 'File';
                if app.SingleFile
                    writetable(T,[file.folder filesep file.name '_Stats.xlsx'],'Sheet',1,'WriteMode','Append','WriteRowNames',true);
                else
                    writetable(T,[file.folder filesep 'Folder_Stats.xlsx'],'Sheet',1,'WriteMode','Append','WriteRowNames',true);
                end
            end
            %% Saving to Matlab data files
            if app.UITableSaveExtension.Data{3}
                if strcmp(app.perROISwitch.Value,'On')
                    for it = 1:max(ROI(:))
                        Rho = Rho_.Value(ROI==it & isfinite(Rho_.Value));
                        Int = Int_(ROI==it & isfinite(Rho_.Value));
                        X = X_(ROI==it & isfinite(Rho_.Value));
                        Y = Y_(ROI==it & isfinite(Rho_.Value));
                        switch app.PolarimetryMethod.Value
                            case {'1PF','4POLAR 2D'}
                                Psi = Psi_.Value(ROI==it & isfinite(Rho_.Value));
                                save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','Psi','Int','X','Y');
                            case {'CARS','SRS','2PF'}
                                S2 = S2_.Value(ROI==it & isfinite(Rho_.Value));
                                S4 = S4_.Value(ROI==it & isfinite(Rho_.Value));
                                save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','S2','S4','Int','X','Y');
                            case 'SHG'
                                S_SHG = S_SHG_.Value(ROI==it & isfinite(Rho_.Value));
                                save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','S_SHG','Int','X','Y');
                            case {'4POLAR 3D'}
                                Psi = Psi_.Value(ROI==it & isfinite(Rho_.Value));
                                Eta = Eta_.Value(ROI==it & isfinite(Rho_.Value));
                                save([file.folder filesep file.name '_ROI' num2str(it) '.mat'],'Rho','Psi','Eta','Int','X','Y');
                        end
                    end
                else
                    Rho = Rho_.Value(ROI~=0 & isfinite(Rho_.Value));
                    Int = Int_(ROI~=0 & isfinite(Rho_.Value));
                    X = X_(ROI~=0 & isfinite(Rho_.Value));
                    Y = Y_(ROI~=0 & isfinite(Rho_.Value));
                    switch app.PolarimetryMethod.Value
                        case {'1PF','4POLAR 2D'}
                            Psi = Psi_.Value(ROI~=0 & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '.mat'],'Rho','Psi','Int','X','Y');
                        case {'CARS','SRS','2PF'}
                            S2 = S2_.Value(ROI~=0 & isfinite(Rho_.Value));
                            S4 = S4_.Value(ROI~=0 & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '.mat'],'Rho','S2','S4','Int','X','Y');
                        case 'SHG'
                            S_SHG = S_SHG_.Value(ROI~=0 & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '.mat'],'Rho','S_SHG','Int','X','Y');
                        case '4POLAR 3D'
                            Psi = Psi_.Value(ROI~=0 & isfinite(Rho_.Value));
                            Eta = Eta_.Value(ROI~=0 & isfinite(Rho_.Value));
                            save([file.folder filesep file.name '.mat'],'Rho','Psi','Eta','Int','X','Y');
                    end
                end
            end
            if app.UITableSaveExtension.Data{5}
                video = VideoWriter([file.folder filesep file.name '.mp4'],'MPEG-4');
                video.FrameRate = 2;
                video.Quality = 100;
                open(video);
                fig = figure('Name',file.name);
                ax = axes(fig);
                for ind = 1:Stack.nangle
                    field = Stack.Values(:,:,ind)/max(Stack.Values,[],'all');
                    im = imadjust(imsharpen(field),[0 1],[0 1],app.ContrastSlider.Value);
                    if app.FigureRotationEditField.Value~=0
                        im = imrotate(im,app.FigureRotationEditField.Value,'bilinear','crop');
                    end
                    imagesc(ax,im);
                    colormap(ax,gray); 
                    axis image;
                    title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                    if ~app.AddAxesCheckBox.Value
                        set(ax,'visible','off');
                    end
                    frame = getframe(fig);
                    writeVideo(video,frame);
                end
                close(video);
                close(fig)
            end
        end
        
        function Initialize(app)
            app.Patch.XData = [];
            app.Patch.YData = [];
            % Fluorescence & Thresholding
            app.int_roi = 0;
            app.ContrastSlider.Enable = 'on';
            app.ContrastThrshldSlider.Enable = 'on';
            app.ILowEditField.Enable = 'on';
            app.ILowMaximumSlider.Enable = 'on';
            app.TransparencySlider.Enable = 'on';
            app.DetAngle.Enable = 'on';
            app.Reload.Enable = 'on';
            app.ContrastFluoButton.Enable ='on';
            app.ContrastThrshldButton.Enable = 'on';
            app.NoBackgroundButton.Enable = 'on';
            app.ExportMask.Enable = 'on';
            app.ColormapThrshldButton.Enable = 'on';
            app.ContrastSlider.Value = 1;
            app.ILowMaximumSlider.Value = 0;
            app.UIAxesThrshld.CLim(1) = 0;
            app.StackSlider.Enable = 'on';
            app.StackSlider.Limits = [0 app.Stack.nangle];
            switch app.PolarimetryMethod.Value
                case {'1PF','CARS','SRS','SHG','2PF'}
                    app.StackSlider.MajorTicks = linspace(0,app.Stack.nangle,min(10,app.Stack.nangle+1));
                    app.StackSlider.MajorTickLabels = cellstr(string(app.StackSlider.MajorTicks));  
                    app.StackSlider.MajorTickLabels{1} = 'T';
                case {'4POLAR 2D','4POLAR 3D'}
                    app.StackSlider.MajorTicks = linspace(0,4,5);
                    app.StackSlider.MajorTickLabels = {'T','0','45','90','135'};
            end
            app.StackSlider.MinorTicks = 0:app.Stack.nangle;
            app.StackSliderLabel.Visible = 'on';
            app.StackSlider.Enable = 'on';
            app.StackSlider.Visible = 'on';
            % Advanced Options
            app.NoiseWidthEditField.Value = 3;
            app.NoiseHeightEditField.Value = 3;
            app.NoiseFactorEditField.Value = 1;
            app.NoiseremovallevelEditField.Value = 0;
        end
        
        function Represent_Thrshld(app, Itot, DrawNow, FirstDraw)
            Ithrshld = Itot/max(Itot(:));
            if strcmp(app.DropDownTool.Value,'Mask (manual)') || strcmp(app.DropDownTool.Value,'Mask (auto)')
                [~,name,~] = fileparts(app.P.filename);
                Name_Mask = [app.MaskFolder filesep name '.png'];
                if isfile(Name_Mask)
                    BinarizedIm = imread(Name_Mask);
                    MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                end
                Ithrshld = Ithrshld.*MaskTh;
            end
            if DrawNow
                if ~FirstDraw
                    XLimThrshld = app.UIAxesThrshld.XLim;
                    YLimThrshld = app.UIAxesThrshld.YLim;
                end
                colormap(app.UIAxesThrshld,app.ThrshldColormap);
                im = imadjust(imsharpen(Itot/max(Itot(:))),[0 1],[0 1],app.ContrastThrshldSlider.Value);
                app.ImageThrshld = imagesc(app.UIAxesThrshld,im);
                axis(app.UIAxesThrshld,'image'); 
                if ~FirstDraw
                    app.UIAxesThrshld.XLim = XLimThrshld;
                    app.UIAxesThrshld.YLim = YLimThrshld;
                end
            end
            app.ImageThrshld.AlphaData = ones(size(Ithrshld));
            app.ImageThrshld.AlphaData(Ithrshld<=app.ILowMaximumSlider.Value) = app.TransparencySlider.Value*app.ImageThrshld.AlphaData(Ithrshld<=app.ILowMaximumSlider.Value);
            if app.int_roi~=0 && ~isempty(app.Patch.XData)
                for it = 1:app.int_roi
                    patch(app.UIAxesThrshld,'XData',app.Patch.XData{it},'YData',app.Patch.YData{it},'FaceAlpha',0,'EdgeColor','w');
                    text(app.UIAxesThrshld,app.Patch.XData{it}(1),app.Patch.YData{it}(1),num2str(it),'FontSize',20,'Color','w');
                end
            end
        end
        
        function ShowSaveFluo(app)
            [file.folder,file.name,~] = fileparts(app.P.filename);
            fig = figure('Name','Fluorescence');
            figAxes = axes(fig);
            figAxes.Colormap = app.UIAxesFluo.Colormap;
            figAxes.YDir = app.UIAxesFluo.YDir;
            copyobj(app.UIAxesFluo.XAxis.Parent.Children,figAxes);
            xlim auto, ylim auto, axis image
            if ~app.AddAxesCheckBox.Value
                set(gca,'visible','off');
            end
            title(file.name,'FontSize',14,'Interpreter','none','FontWeight','normal');
            set(findall(gca,'type','text'),'visible','on');
            drawnow
            if app.UITableDisplay.Data{4,2}
                savefig(fig,[file.folder filesep file.name '_Fluo.fig']);
            end
            if app.UITableSaveExtension.Data{2}
                saveas(fig,[file.folder filesep file.name '_Fluo.tif'])
            end
            if ~app.UITableDisplay.Data{4,1}
                close(fig);
            end 
        end
        
        function Dark = ComputeDark(~, Stack)
            SizeCell = 20;
            NCellHeight = floor(Stack.Height/SizeCell);
            NCellWidth = floor(Stack.Width/SizeCell);
            Ncol = SizeCell*ones(1,NCellWidth);
            Nrow = SizeCell*ones(1,NCellHeight);
            cropIm = Stack.Values(1:SizeCell*NCellHeight,1:SizeCell*NCellWidth,1:Stack.nangle);
            ImCG = mat2cell(cropIm,Nrow,Ncol,Stack.nangle);
            mImCG = zeros(NCellHeight,NCellWidth);
            for it = 1:NCellHeight
                for jt = 1:NCellWidth
                    CellInd = ImCG{it,jt}(:,:,1);
                    mImCG(it,jt) = mean(CellInd(CellInd~=0));
                end
            end
            val = min(mImCG,[],'all');
            [IndI, IndJ] = find(mImCG==val);
            CellInd = ImCG{IndI,IndJ};
            Dark = mean(CellInd(CellInd~=0));
        end
        
        function Stack = OpenFile(app, filename)
            info = imfinfo(filename);
            nangle = numel(info);
            Stack.Values = zeros(info(1).Height,info(1).Width,nangle,'double');
            Stack.Height = info(1).Height;
            Stack.Width = info(1).Width;
            Stack.nangle = nangle;
            TifLink = Tiff(filename,'r');
            for k = 1:nangle
               TifLink.setDirectory(k);
               Stack.Values(:,:,k) = TifLink.read();
            end
            TifLink.close();
            [~,name,~] = fileparts(filename);
            app.NameOfFile.Visible = 'on';
            app.NameOfFile.Value = name;
            if info(1).BitsPerSample == 32
                Stack.ValueDisplayFormat = '%.2f';
                app.UserDarkValueEditField.ValueDisplayFormat = '%.2f';
                app.UserDarkValueEditField.Value = min(Stack.Values(:));
                app.CalculatedDarkValueCheckBox.Value = false;
                app.UserDarkValueEditField.Enable = 'on';
            else
                Stack.ValueDisplayFormat = '%.0f';
                app.UserDarkValueEditField.ValueDisplayFormat = '%.0f';
                app.UserDarkValueEditField.Value = 480;
                app.CalculatedDarkValueCheckBox.Value = true;
                app.UserDarkValueEditField.Enable = 'off';
            end
            if strcmp(app.PolarimetryMethod.Value,'4POLAR 2D') || strcmp(app.PolarimetryMethod.Value,'4POLAR 3D')
                Stack = slice4POLAR(app,Stack);
            end
        end
        
        function figComposite = Composite(app, data, imbackground, orientation, file)
            if data.Display && app.UITableDisplay.Data{1,1}
                title_fig = ['Composite of ' data.Name];
                suffix = ['_Composite' data.Name];
                figComposite = figure('Name',title_fig);
                ax1 = axes(figComposite);
                imagesc(ax1,imbackground); 
                colormap(ax1,gray); 
                c1 = colorbar; axis image; c1.Visible = 'off';
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figComposite);
                im = data.Value;
                if app.FigureRotationEditField.Value~=0
                    if orientation
                        im = wrapTo360(2*(im+app.FigureRotationEditField.Value))/2;
                    end
                    im = imrotate(im,app.FigureRotationEditField.Value,'nearest','crop');
                    im(im==0) = NaN;
                end
                h2 = imagesc(ax2,im,[data.Min data.Max]); 
                colormap(ax2,data.Colormap); 
                colorbar; axis image;
                alpha(h2,isfinite(im)*1);
                ax2.Visible = 'off';
                linkaxes([ax1,ax2],'xy');
                title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                set(findall(gca,'type','text'),'visible','on');
                drawnow
                if app.UITableDisplay.Data{1,2}
                    savefig(figComposite,[file.folder filesep file.name suffix '.fig']);
                end
                if app.UITableSaveExtension.Data{2}
                    saveas(figComposite,[file.folder filesep file.name suffix '.tif'])
                end
            else
                figComposite = [];
            end
        end
        
        function Sticks(app, data, imbackground, rho, orientation, file, Stack)
            if data.Display && app.UITableDisplay.Data{2,1}
                W = 0.2;                 % width of sticks
                L = 6;                   % length of sticks
                title_fig = [data.Name ' Sticks'];
                suffix = ['_' data.Name 'Sticks'];
                figSticks = figure('Name',title_fig);
                ax1 = axes(figSticks); 
                imagesc(ax1,imbackground); 
                colormap(ax1,gray); 
                c1 = colorbar; axis image; c1.Visible = 'off'; 
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figSticks);
                Rho_r = rho(1:app.PixelsPerStickSpinnerHeight.Value:end,1:app.PixelsPerStickSpinnerWidth.Value:end);
                data_r = data.Value(1:app.PixelsPerStickSpinnerHeight.Value:end,1:app.PixelsPerStickSpinnerWidth.Value:end);
                [X,Y] = meshgrid(1:app.PixelsPerStickSpinnerWidth.Value:Stack.Width+app.BinWidthEditField.Value-1, ...
                    1:app.PixelsPerStickSpinnerHeight.Value:Stack.Height+app.BinHeightEditField.Value-1);
                X = X(isfinite(Rho_r));
                Y = Y(isfinite(Rho_r));
                data_r = data_r(isfinite(Rho_r));
                Rho_r = Rho_r(isfinite(Rho_r));
                if orientation
                    data_r_color = wrapTo360(2*(data_r+app.FigureRotationEditField.Value))/2;
                else
                    data_r_color = data_r;
                end
                Vertex_r = (data_r_color.*ones(1,4)).';
                prot = patch(ax2,[X+L/2*cosd(Rho_r)+W/2*sind(Rho_r) X-L/2*cosd(Rho_r)+W/2*sind(Rho_r)...
                    X-L/2*cosd(Rho_r)-W/2*sind(Rho_r) X+L/2*cosd(Rho_r)-W/2*sind(Rho_r)].',...
                    [Y-L/2*sind(Rho_r)+W/2*cosd(Rho_r) Y+L/2*sind(Rho_r)+W/2*cosd(Rho_r)...
                    Y+L/2*sind(Rho_r)-W/2*cosd(Rho_r) Y-L/2*sind(Rho_r)-W/2*cosd(Rho_r)].',data_r.',...
                    'LineWidth',2,'EdgeAlpha',0.2,'EdgeColor','flat','FaceVertexCData',Vertex_r(:));
                if app.FigureRotationEditField.Value~=0
                    rotate(prot,[0 0 -1],app.FigureRotationEditField.Value,[Stack.Width/2,Stack.Height/2,0]);
                end
                colormap(ax2,data.Colormap);
                colorbar; 
                set(gca,'YDir','reverse');
                caxis([data.Min data.Max]);
                axis(ax2,'image'); 
                ax2.Visible = 'off'; 
                linkaxes([ax1,ax2],'xy');
                title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                set(findall(gca,'type','text'),'visible','on');
                drawnow
                if app.UITableDisplay.Data{2,2}
                    savefig(figSticks,[file.folder filesep file.name suffix '.fig']);
                end
                if app.UITableSaveExtension.Data{2}
                    saveas(figSticks,[file.folder filesep file.name suffix '.tif'])
                end
            end
        end
        
        function Histos(app, data, ROI, type, file)
            if data.Display && app.UITableDisplay.Data{3,1}
                if strcmp(app.perROISwitch.Value,'On')
                    for it = 1:max(ROI(:))
                        title_fig = [data.Name ' Histogram for ROI ' num2str(it)]; 
                        suffix = ['_Histo' data.Name '_perROI_' num2str(it)];
                        figHisto = figure('Name',title_fig);
                        if strcmp(type,'polar1')
                            data_vals = wrapTo360(2*(data.Value(ROI==it & isfinite(data.Value))+app.FigureRotationEditField.Value))/2;
                            meandata = circularmean(app, data_vals);
                            DeltaRho = wrapTo180(2*(data_vals-meandata))/2;
                            pax = polaraxes(figHisto);
                            polarhistogram(pax,deg2rad(data_vals),deg2rad(0:3:180));
                            pax.ThetaLim = [0 180];
                            set(gca,'FontSize',16);
                            annotation(figHisto,'textbox',[0.2 0.99 1 0],'String',file.name,'LineStyle','none','FontSize',14,'FitBoxToText','on','Interpreter','none');
                            annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',['$\rho = $' num2str(meandata,'%.2f') ' $\pm$ ' ...
                                num2str(std(DeltaRho),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                        elseif strcmp(type,'polar2')
                            data_vals = data.Value(ROI==it & isfinite(data.Value));
                            pax = polaraxes(figHisto);
                            polarhistogram(pax,deg2rad(data_vals),deg2rad(0:2:90));
                            pax.ThetaLim = [0 90];
                            pax.ThetaDir = 'clockwise';
                            pax.ThetaZeroLocation = 'top';
                            set(gca,'FontSize',16);
                            annotation(figHisto,'textbox',[0.6 0.99 1 0],'String',file.name,'LineStyle','none','FontSize',14,'FitBoxToText','on','Interpreter','none');
                            annotation(figHisto,'textbox',[0.6 0.93 0 0],'String',['$\rho = $' num2str(mean(data_vals),'%.2f') ' $\pm$ ' ...
                                num2str(std(data_vals),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                        elseif strcmp(type,'normal')
                            data_vals = data.Value(ROI==it & isfinite(data.Value));
                            [Nb,Values] = histcounts(data_vals,data.Min:data.Max/60:data.Max);
                            b = bar(Values(1:end-1),Nb,'histc');
                            b.FaceColor = 'flat';
                            b.CData = linspace(1,2,length(Nb));
                            colormap(data.Colormap);
                            xlim([data.Min data.Max]); 
                            xlabel(data.LaTeX,'Interpreter','latex','FontSize',20);
                            set(gca,'FontSize',16)
                            title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                            annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',[data.LaTeX ' = ' num2str(mean(data_vals),'%.2f') ' $\pm$ ' ...
                                num2str(std(data_vals),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                        end
                        drawnow
                        if app.UITableDisplay.Data{3,2}
                            savefig(figHisto,[file.folder filesep file.name suffix '.fig']);
                        end
                        if app.UITableSaveExtension.Data{2}
                            saveas(figHisto,[file.folder filesep file.name suffix '.tif'])
                        end
                    end
                else
                    title_fig = [data.Name ' Histogram']; 
                    suffix = ['_Histo',data.Name '.fig'];
                    figHisto = figure('Name',title_fig);
                    if strcmp(type,'polar1')
                        data_vals = wrapTo360(2*(data.Value(ROI~=0 & isfinite(data.Value))+app.FigureRotationEditField.Value))/2;
                        meandata = circularmean(app, data_vals);
                        DeltaRho = wrapTo180(2*(data_vals-meandata))/2;
                        pax = polaraxes(figHisto);
                        polarhistogram(pax,deg2rad(data_vals),deg2rad(0:3:180));
                        pax.ThetaLim = [0 180];
                        set(gca,'FontSize',16)
                        annotation(figHisto,'textbox',[0.2 0.99 1 0],'String',file.name,'LineStyle','none','FontSize',14,'FitBoxToText','on','Interpreter','none');
                        annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',['$\rho = $' num2str(meandata,'%.2f') ' $\pm$ ' ...
                            num2str(std(DeltaRho),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                    elseif strcmp(type,'polar2')
                        data_vals = data.Value(ROI~=0 & isfinite(data.Value));
                        pax = polaraxes(figHisto);
                        polarhistogram(pax,deg2rad(data_vals),deg2rad(0:2:90));
                        pax.ThetaLim = [0 90];
                        pax.ThetaDir = 'clockwise';
                        pax.ThetaZeroLocation = 'top';
                        set(gca,'FontSize',16);
                        annotation(figHisto,'textbox',[0.6 0.99 1 0],'String',file.name,'LineStyle','none','FontSize',14,'FitBoxToText','on','Interpreter','none');
                        annotation(figHisto,'textbox',[0.6 0.93 0 0],'String',['$\eta = $' num2str(mean(data_vals),'%.2f') ' $\pm$ ' ...
                            num2str(std(data_vals),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                    elseif strcmp(type,'normal')
                        data_vals = data.Value(ROI~=0 & isfinite(data.Value));
                        [Nb,Values] = histcounts(data_vals,data.Min:data.Max/60:data.Max);
                        b = bar(Values(1:end-1),Nb,'histc');
                        b.FaceColor = 'flat';
                        b.CData = linspace(1,2,length(Nb));
                        colormap(data.Colormap);
                        set(gca,'FontSize',16)
                        xlim([data.Min data.Max]); 
                        xlabel(data.LaTeX,'Interpreter','latex','FontSize',20);
                        title(file.name,'Interpreter','none','FontSize',14,'FontWeight','normal');
                        annotation(figHisto,'textbox',[0.2 0.91 0 0],'String',[data.LaTeX ' $=$ ' num2str(mean(data_vals),'%.2f') ' $\pm$ ' ...
                            num2str(std(data_vals),'%.2f')],'LineStyle','none','FontSize',20,'FitBoxToText','on','Interpreter','latex');
                    end
                    drawnow
                    if app.UITableDisplay.Data{3,2}
                        savefig(figHisto,[file.folder filesep file.name suffix '.fig']);
                    end
                    if app.UITableSaveExtension.Data{2}
                        saveas(figHisto,[file.folder filesep file.name suffix '.tif'])
                    end
                end
            end
        end
        
        function meanrho = circularmean(~, rho)
            meanrho = wrapTo360(2*atan2d(mean(sind(2*rho)),mean(cosd(2*rho)))/2)/2;
        end
        
        function stack4polar = slice4POLAR(app, stack)
            stack4polar.nangle = 4;
            stack4polar.Height = max(app.ypos(2,:)-app.ypos(1,:))+2*app.npix;
            stack4polar.Width = max(app.xpos(2,:)-app.xpos(1,:))+2*app.npix;
            stack4polar.ValueDisplayFormat = stack.ValueDisplayFormat;
            stack4polar.Values = zeros(stack4polar.Height,stack4polar.Width,4);
            ims = cell(1,4);
            for it = 1:4
                ddx = stack4polar.Width-app.npix+app.xpos(1,it)-1;
                ddy = stack4polar.Height-app.npix+app.ypos(1,it)-1;
                ims{it} = stack.Values(app.ypos(1,it)-app.npix:ddy,app.xpos(1,it)-app.npix:ddx,1);
            end
            ims_reg = ims;
            for it = 2:4
                ims_reg{it} = imwarp(ims{it},app.tform{1,it},'OutputView',imref2d(size(ims{1})));
                ims_reg{it} = imwarp(ims_reg{it},app.tform{2,it},'OutputView',imref2d(size(ims{1})));
            end
            for it = 1:4
                stack4polar.Values(:,:,app.order(it)) = ims_reg{it};
            end
        end
        
        function Initialize_DiskCone(app)
            load('DiskCones/Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0.mat','RoTest','PsiTest','NbMapValues');
            app.NameOfDiskCone.Value = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
            app.P.RoTest = double(RoTest);
            app.P.PsiTest = double(PsiTest);
            app.P.NbMapValues = NbMapValues;
            app.OffsetAngleDegEditField.Value = 85;
        end
        
        function Initialize_Noise(app)
            app.ILowMaximumSlider.Value = 0;
            app.UIAxesThrshld.CLim(1) = 0;
            app.NoiseWidthEditField.Value = 3;
            app.NoiseHeightEditField.Value = 3;
            app.NoiseFactorEditField.Value = 1;
            app.NoiseremovallevelEditField.Value = 0;
        end
        
        function Initialize_Table(app, mode)
            app.UITableDisplay.Data(:,1) = {false};
            app.UITableDisplay.Data(:,2) = {false};
            app.UITableVariable.Data(:,1) = {false};
            switch mode
                case 'part'
                    app.UITableSaveExtension.Data{1} = false;
                case 'all'
                    app.UITableSaveExtension.Data(:) = {false};
            end
            if strcmp(app.PolarimetryMethod.Value,'4POLAR 2D') || strcmp(app.PolarimetryMethod.Value,'4POLAR 3D')
                app.ShowIndividualFitCheckBox.Enable = 'off';
                app.ShowIndividualFitCheckBox.Visible = 'off';
                app.ShowIndividualFitCheckBox.Value = false;
            else
                app.ShowIndividualFitCheckBox.Enable = 'on';
                app.ShowIndividualFitCheckBox.Visible = 'on';
                app.ShowIndividualFitCheckBox.Value = false;
            end
        end
        
        function determine_order(app,polquad)
            switch polquad
                case 'UL0-UR45-LR90-LL135'
                    app.order = [1 2 3 4];
                case 'UL0-UR45-LR135-LL90'
                    app.order = [1 2 4 3];
                case 'UL0-UR90-LR45-LL135'
                    app.order = [1 3 2 4];
                case 'UL0-UR90-LR135-LL45'
                    app.order = [1 3 4 2];
                case 'UL0-UR135-LR45-LL90'
                    app.order = [1 4 2 3];
                case 'UL0-UR135-LR90-LL45'
                    app.order = [1 4 3 2];
                case 'UL45-UR0-LR90-LL135'
                    app.order = [2 1 3 4];
                case 'UL45-UR0-LR135-LL90'
                    app.order = [2 1 4 3];
                case 'UL45-UR90-LR0-LL135'
                    app.order = [2 3 1 4];
                case 'UL45-UR90-LR135-LL0'
                    app.order = [2 3 4 1];
                case 'UL45-UR135-LR0-LL90'
                    app.order = [2 4 1 3];
                case 'UL45-UR135-LR90-LL0'
                    app.order = [2 4 3 1];
                case 'UL90-UR0-LR45-LL135'
                    app.order = [3 1 2 4];
                case 'UL90-UR0-LR135-LL45'
                    app.order = [3 1 4 2];
                case 'UL90-UR45-LR0-LL135'
                    app.order = [3 2 1 4];
                case 'UL90-UR45-LR135-LL0'
                    app.order = [3 2 4 1];
                case 'UL90-UR135-LR0-LL45'
                    app.order = [3 4 1 2];
                case 'UL90-UR135-LR45-LL0'
                    app.order = [3 4 2 1];
                case 'UL135-UR0-LR45-LL90'
                    app.order = [4 1 2 3];
                case 'UL135-UR0-LR90-LL45'
                    app.order = [4 1 3 2];
                case 'UL135-UR45-LR0-LL90'
                    app.order = [4 2 1 3];
                case 'UL135-UR45-LR90-LL0'
                    app.order = [4 2 3 1];
                case 'UL135-UR90-LR0-LL45'
                    app.order = [4 3 1 2];
                case 'UL135-UR90-LR45-LL0'
                    app.order = [4 3 2 1];
            end
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uialert(app.PolarimetryAnalysisUIFigure,{'<b>Welcome to Polarimetry Analysis</b>',' ','   <b>(1)</b>  Select a polarimetry method',...
               '   <b>(2)</b>  Download a file or a folder','   <b>(3)</b>  Select a method of analysis','   <b>(4)</b>  Select one or several regions of interest',...
               '   <b>(5)</b>  Click on Analysis',' ','<a href="https://github.com/cchandre/Polarimetry">For more information, visit the GitHub page</a>'},'Polarimetry Analysis','Icon','Icons/round_blur_circular_black_48dp.png','Interpreter','html');
            app.UITableDisplay.RowName = {'Composite','Sticks','Histogram','Fluorescence'};
            app.UITableDisplay.Data = {false,false;false,false;false,false;false,false};
            app.UITableVariable.RowName = {char(961),char(936)};
            app.UITableVariable.Data = {false,0,180;false,40,180};
            app.UITableSaveExtension.RowName = {'figures (.fig)','figures (.tif)','data (.mat)','mean values (.xlsx)','stack (.mp4)'};
            app.UITableSaveExtension.Data = {false;false;false;false;false}; 
            app.ClickbackgroundChanged = false;
            Initialize_DiskCone(app)
            app.UIAxesThrshld.Toolbar.Visible = 'on';
            app.UIAxesFluo.Toolbar.Visible = 'on';
            app.ThrshldColormap = 'hot';
            app.DiskData = {'488 nm (no distortions)','561 nm (no distortions)','640 nm (no distortions)','488 nm (16/03/2020 - 12/04/2022)',...
                '561 nm (16/03/2020 - 12/04/2022)','640 nm (16/03/2020 - 12/04/2022)','488 nm (13/12/2019 - 15/03/2020)',...
                '561 nm (13/12/2019 - 15/03/2020)','640 nm (13/12/2019 - 15/03/2020)','488 nm (before 13/12/2019)','561 nm (before 13/12/2019)',...
                '640 nm (before 13/12/2019)','no distortions (before 12/04/2022)','other'};
            app.DiskDataLabels = string(1:length(app.DiskData));
            app.DiskDataTooltip = {'Select disk cone depending on wavelength and acquisition date'};
            app.SelectDiskCone.Items = app.DiskData;
            app.SelectDiskCone.ItemsData = app.DiskDataLabels;
            app.SelectDiskCone.Tooltip = app.DiskDataTooltip;
        end

        % Button pushed function: PolarimetryAnalysisLogo
        function PolarimetryAnalysisLogoPushed(app, event)
            if app.TabGroup.SelectedTab ~= app.AboutTab
                app.TabGroup.SelectedTab = app.AboutTab;
            else 
                app.TabGroup.SelectedTab = app.FluorescenceTab;
            end
        end

        % Button pushed function: DownloadFileButton
        function DownloadFileButtonPushed(app, event)
            app.SingleFile = true;
            app.AdvanceFile = false;
            app.DropDownTool.Value = 'Thresholding (manual)';
            [file,app.path] = uigetfile({'*.tiff;*.tif'});
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryAnalysisUIFigure);
            if ~isequal(file, 0)
                app.P.filename = [app.path file];
                app.Stack = OpenFile(app,app.P.filename);
                Initialize(app);
                [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);
                Represent_Fluo(app,app.Itot,true);
                Represent_Thrshld(app,app.Itot,true,true);
                app.ROI = zeros(size(app.Itot));
                app.ROI_ILow = zeros(size(app.Itot));
                app.Mask = zeros(size(app.Itot));
                app.DropDownTool.Enable = 'on';
                app.ImageTool.Enable = 'on';
                app.AddROIButton.Enable = 'on';
                app.AnalysisButton.Enable = 'on';
            end
        end

        % Button pushed function: DownloadFolderButton
        function DownloadFolderButtonPushed(app, event)
            app.SingleFile = false;
            app.DropDownTool.Value = 'Thresholding (manual)';
            app.Folder = uigetdir;
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryAnalysisUIFigure);
            if isequal(app.Folder,0) || (isempty(dir([app.Folder filesep '*.tif'])) && isempty(dir([app.Folder filesep '*.tiff'])))
                uialert(app.PolarimetryAnalysisUIFigure,'The folder does not contain <tt>.tiff</tt> or <tt>.tif</tt> files','Empty folder',...
                    'Icon','Icons/round_warning_black_48dp.png','Interpreter','html');
            else
                app.fs = matlab.io.datastore.DsFileSet(app.Folder,'FileExtensions',{'.tif','.tiff'});
                app.path = [app.Folder filesep];
                if hasfile(app.fs)
                    file_n = nextfile(app.fs);
                    app.P.filename = char(file_n.FileName); 
                    app.Stack = OpenFile(app,app.P.filename);   
                    Initialize(app);
                    [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);                 
                    Represent_Fluo(app, app.Itot,true);
                    Represent_Thrshld(app,app.Itot,true,true);
                    app.ROI = zeros(size(app.Itot));
                    app.ROI_ILow = zeros(size(app.Itot));
                    app.Mask = zeros(size(app.Itot));
                    app.DropDownTool.Enable = 'on';
                    app.ImageTool.Enable = 'on';
                    app.AddROIButton.Enable = 'on';
                    app.AnalysisButton.Enable = 'on';
                end
            end
        end

        % Value changed function: DropDownTool
        function DropDownToolValueChanged(app, event)
            switch app.DropDownTool.Value
                case 'Thresholding (auto)'
                    app.AdvanceFile = true;
                case 'Thresholding (manual)'
                    app.AdvanceFile = false;
                case 'Mask (auto)'
                    app.AdvanceFile = true;
                    app.MaskFolder = uigetdir;
                    app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
                    app.ILowEditField.Value = min(app.Itot(:));
                    app.ILowMaximumSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    Represent_Thrshld(app,app.Itot,false,false);
                case 'Mask (manual)'
                    app.AdvanceFile = false;
                    app.MaskFolder = uigetdir;
                    app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
                    app.ILowEditField.Value = min(app.Itot(:));
                    app.ILowMaximumSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    Represent_Thrshld(app,app.Itot,false,false);
            end
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ThresholdingMaskTab;
            h_roi = drawpolygon(app.UIAxesThrshld);
            selection = uiconfirm(app.PolarimetryAnalysisUIFigure,'Add ROI?','Confirm Close',...
                'Icon','Icons/round_center_focus_weak_black_48dp.png','Options',{'Yes','No'},'DefaultOption',1);
            if strcmp(selection,'Yes')
                BW = createMask(h_roi);
                app.int_roi = app.int_roi+1;
                app.ROI(BW) = app.int_roi;
                app.ROI_ILow(BW) = app.ILowEditField.Value;
                app.Patch.XData{app.int_roi} = h_roi.Position(:,1);
                app.Patch.YData{app.int_roi} = h_roi.Position(:,2);
                Represent_Fluo(app,app.Itot,false);
                Represent_Thrshld(app,app.Itot,false,false);
            end
            delete(h_roi);
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            app.AnalysisButton.Icon = 'Icons/round_pause_circle_filled_black_48dp.png';
            app.AnalysisButton.BackgroundColor = [1.00,0.50,0.31];
            pause(1/5);
            if app.int_roi == 0
                app.ROI = ones(size(app.Itot)); 
                app.ROI_ILow = app.ILowEditField.Value*app.ROI;
                app.Mask = zeros(size(app.Itot)); 
            end
            if app.AdvanceFile && ~isempty(app.fs)
                for it = 1:app.fs.NumFiles 
                    if app.ClickbackgroundChanged
                        app.P.Noise_tot = app.NoiseremovallevelEditField.Value;               
                    else
                        app.P.Noise_tot = 0;
                    end
                    MaskTh = ones(size(app.Itot));
                    if strcmp(app.DropDownTool.Value,'Mask (auto)')
                        [~,name,~] = fileparts(app.P.filename);
                        Name_Mask = [app.MaskFolder filesep name '.png'];
                        if isfile(Name_Mask)
                            BinarizedIm = imread(Name_Mask);
                            MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                        end
                    end
                    app.Mask = (app.Itot>=app.ROI_ILow).*MaskTh;
                    PolarimetryAnalysis(app,app.Stack,app.P,app.Mask,app.ROI);
                    if app.UITableDisplay.Data{4,1} || app.UITableDisplay.Data{4,2} 
                        ShowSaveFluo(app);
                    end
                    if hasfile(app.fs)
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName);
                        app.Stack = OpenFile(app,app.P.filename);
                        [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);  
                        Represent_Fluo(app,app.Itot,true);
                        Represent_Thrshld(app,app.Itot,true,true);
                        pause(1/5);
                    end
                end
                uialert(app.PolarimetryAnalysisUIFigure,'End of analysis','End of analysis','Icon','Icons/round_check_circle_outline_black_48dp.png');       
                reset(app.fs);
                app.int_roi = 0;
                Initialize_Noise(app)
                file_n = nextfile(app.fs);
                app.P.filename = char(file_n.FileName); 
                app.Stack = OpenFile(app,app.P.filename);
                [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);  
                app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
                app.ILowEditField.Value = min(app.Itot(:));
                Represent_Fluo(app,app.Itot,true);
                Represent_Thrshld(app,app.Itot,true,true);
                app.ROI = zeros(size(app.Itot));
                app.ROI_ILow = zeros(size(app.Itot));
                app.Mask = zeros(size(app.Itot));
            elseif ~app.AdvanceFile 
                if app.ClickbackgroundChanged
                    app.P.Noise_tot = app.NoiseremovallevelEditField.Value;
                else 
                    app.P.Noise_tot = 0;
                end
                app.ClickbackgroundChanged = false;
                app.NoiseremovallevelEditField.Value = 0;
                MaskTh = ones(size(app.Itot));
                if strcmp(app.DropDownTool.Value,'Mask (manual)')
                    [~,name,~] = fileparts(app.P.filename);
                    Name_Mask = [app.MaskFolder filesep name '.png'];
                    if isfile(Name_Mask)
                        BinarizedIm = imread(Name_Mask);
                        MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                    end
                end
                app.Mask = (app.Itot>=app.ROI_ILow).*MaskTh;
                PolarimetryAnalysis(app,app.Stack,app.P,app.Mask,app.ROI);
                if app.UITableDisplay.Data{4,1} || app.UITableDisplay.Data{4,2}
                    ShowSaveFluo(app);
                end
                figure(app.PolarimetryAnalysisUIFigure);
                if ~app.SingleFile 
                    if hasfile(app.fs)
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName);
                        app.Stack = OpenFile(app,app.P.filename);
                        [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);
                        app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
                        app.ILowEditField.Value = min(app.Itot(:));
                        Initialize_Noise(app)
                    else
                        uialert(app.PolarimetryAnalysisUIFigure,'End of analysis','End of analysis',...
                            'Icon','Icons/round_check_circle_outline_black_48dp.png');
                        reset(app.fs);
                        app.int_roi = 0;
                        app.ILowMaximumSlider.Value = 0;
                        app.UIAxesThrshld.CLim(1) = 0;
                        app.NoiseWidthEditField.Value = 3;
                        app.NoiseHeightEditField.Value = 3;
                        app.NoiseFactorEditField.Value = 1;
                        app.NoiseremovallevelEditField.Value = 0;
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName); 
                        app.Stack = OpenFile(app,app.P.filename);
                        [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack); 
                        app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
                        app.ILowEditField.Value = min(app.Itot(:));
                        Represent_Fluo(app,app.Itot,true);
                        Represent_Thrshld(app,app.Itot,true,true);
                        app.ROI = zeros(size(app.Itot));
                        app.ROI_ILow = zeros(size(app.Itot));
                        app.Mask = zeros(size(app.Itot));
                    end
                end
                app.Patch.XData = [];
                app.Patch.YData = [];
                Represent_Fluo(app,app.Itot,true);
                Represent_Thrshld(app,app.Itot,true,true);
                app.int_roi = 0;
                app.ROI = zeros(size(app.Itot));
                app.ROI_ILow = zeros(size(app.Itot));
                app.Mask = zeros(size(app.Itot)); 
                if app.SingleFile
                    app.DropDownTool.Value = 'Thresholding (manual)'; 
                    app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
                    app.ILowEditField.Value = min(app.Itot(:));
                    Initialize_Noise(app)
                    Represent_Fluo(app,app.Itot,true);
                    Represent_Thrshld(app,app.Itot,true,true);
                end
            end 
            app.AnalysisButton.Icon = 'Icons/round_play_circle_filled_black_48dp.png';
            app.AnalysisButton.BackgroundColor = [0.68,0.82,0.68];
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            if app.ContrastSlider.Value<=0.001
                app.ContrastSlider.Value = 0.001;
            end
            Represent_Fluo(app,app.Itot,false);
        end

        % Button pushed function: DetAngle
        function DetAngleButtonPushed(app, event)
            hline = drawline(app.UIAxesFluo);
            slope = atand((hline.Position(1,2)-hline.Position(2,2))/(hline.Position(2,1)-hline.Position(1,1)));
            slope = wrapTo360(2*slope)/2;
            uialert(app.PolarimetryAnalysisUIFigure,['Angle = ' num2str(slope) ' (deg)'],'Value of the angle',...
                'Icon','Icons/round_square_foot_black_48dp.png');
            delete(hline);
        end

        % Value changed function: ILowEditField
        function ILowEditFieldValueChanged(app, event)
            if app.ILowEditField.Value>=0.999*max(app.Itot(:))
                app.ILowEditField.Value = 0.999*max(app.Itot(:));
            end
            app.ILowMaximumSlider.Value = (app.ILowEditField.Value-min(app.Itot(:)))/(max(app.Itot(:))-min(app.Itot(:)));
            Represent_Thrshld(app,app.Itot,false,false);
        end

        % Value changed function: ILowMaximumSlider
        function ILowMaximumSliderValueChanged(app, event)
            if app.ILowMaximumSlider.Value>=0.999
                app.ILowMaximumSlider.Value = 0.999;
            end
            app.ILowEditField.ValueDisplayFormat = app.Stack.ValueDisplayFormat;
            app.ILowEditField.Value = (max(app.Itot(:))-min(app.Itot(:)))*app.ILowMaximumSlider.Value+min(app.Itot(:));
            Represent_Thrshld(app,app.Itot,false,false);
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            if app.TransparencySlider.Value<=0.001
                app.TransparencySlider.Value = 0.001;
            end
            Represent_Thrshld(app,app.Itot,false,false);
        end

        % Button pushed function: ExportMask
        function ExportMaskButtonPushed(app, event)
            [filepath, name, ~] = fileparts(app.P.filename);
            selection = uiconfirm(app.PolarimetryAnalysisUIFigure,'Create Mask by ROI or by Intensity', 'Create Mask',...
               'Options',{'ROI','Intensity','ROI x Intensity','Cancel'},'DefaultOption',1,'CancelOption',3, ...
               'Icon','Icons/round_open_in_new_black_48dp.png');
            if strcmp(selection,'ROI')
                A = app.ROI;
                A(app.ROI~=0) = 1;
                imwrite(A,[filepath filesep name '_mask.png']);
            elseif strcmp(selection,'Intensity')
                A = zeros(size(app.Itot));
                A(app.Itot>=app.ILowEditField.Value) = 1;
                imwrite(A,[filepath filesep name '_mask.png']);
            elseif strcmp(selection,'ROI x Intensity')
                A = zeros(size(app.Itot));
                A(app.Itot>=app.ROI_ILow) = 1;
                imwrite(A.*app.ROI,[filepath filesep name '_mask.png']);
            end
        end

        % Button pushed function: NoBackgroundButton
        function NoBackgroundButtonPushed(app, event)
            if app.ThresholdingMaskTab.BackgroundColor(1) == 0
                app.ThresholdingMaskTab.BackgroundColor = [0.65 0.65 0.65];
                app.ContrastThrshldSlider.FontColor = [0 0 0];
                app.TransparencySlider.FontColor = [0 0 0];
                app.ILowMaximumSlider.FontColor = [0 0 0];
                app.NoBackgroundButton.Icon = "Icons/round_image_black_48dp.png";
                app.ILowEditFieldLabel.FontColor = [0 0 0];
                app.ILowImaxLabel.FontColor = [0 0 0];
                app.TransparencyLabel.FontColor = [0 0 0];
            else
                app.ThresholdingMaskTab.BackgroundColor = [0 0 0];
                app.ContrastThrshldSlider.FontColor = [1 1 1];
                app.TransparencySlider.FontColor = [1 1 1];
                app.ILowMaximumSlider.FontColor = [1 1 1];
                app.NoBackgroundButton.Icon = "Icons/outline_insert_photo_black_48dp.png";               
                app.ILowEditFieldLabel.FontColor = [1 1 1];
                app.ILowImaxLabel.FontColor = [1 1 1];
                app.TransparencyLabel.FontColor = [1 1 1];
            end 
            Represent_Thrshld(app,app.Itot,false,false);
        end

        % Cell edit callback: UITableSaveExtension
        function UITableSaveExtensionCellEdit(app, event)
            if ~app.UITableSaveExtension.Data{1}
                app.UITableDisplay.Data(cell2mat(app.UITableDisplay.Data(:,2))==1,2)={false};
            end
            if app.UITableSaveExtension.Data{1} && ~any(cell2mat(app.UITableDisplay.Data(:,2)))
                uialert(app.PolarimetryAnalysisUIFigure,'Select the type of figures to be saved (Display table, Save column)',...
                    'Polarimetry Analysis','Icon','Icons/round_add_photo_alternate_black_48dp.png');
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            Initialize_Table(app,'part')
        end

        % Value changed function: ShowIndividualFitCheckBox
        function ShowIndividualFitCheckBoxValueChanged(app, event)
            if app.ShowIndividualFitCheckBox.Value 
                app.UITableDisplay.Data{1,1} = true;
                app.UITableVariable.Data{1,1} = true;
            else
                app.UITableDisplay.Data{1,1} = false;
                app.UITableVariable.Data{1,1} = false;
            end
        end

        % Value changed function: CalculatedDarkValueCheckBox
        function CalculatedDarkValueCheckBoxValueChanged(app, event)
            if app.CalculatedDarkValueCheckBox.Value
                app.UserDarkValueEditField.Enable = 'off';
                app.CalculatedDarkValueEditField.Visible = 'on';
            elseif ~app.CalculatedDarkValueCheckBox.Value
                app.UserDarkValueEditField.Enable = 'on';
            end
            [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);
            Represent_Fluo(app,app.Itot,true);
            Represent_Thrshld(app,app.Itot,true,true);
        end

        % Button pushed function: ClickBackgroundButton
        function ClickBackgroundButtonPushed(app, event)
            app.ClickbackgroundChanged = true;
            if ~isfield(app.P,'filename')
                uialert(app.PolarimetryAnalysisUIFigure,'Download a file or a folder','Polarimetry Analysis', ...
                    'Icon','Icons/round_add_photo_alternate_black_48dp.png');
            else    
                app.TabGroup.SelectedTab = app.FluorescenceTab;
                app.PolarimetryAnalysisUIFigure.HandleVisibility = 'on';
                [x,y] = ginput(1);
                app.PolarimetryAnalysisUIFigure.HandleVisibility = 'off';
                if ~isempty(x) && ~isempty(y)
                    x1 = round(x)-(app.NoiseWidthEditField.Value-1)/2;
                    x2 = round(x)+(app.NoiseWidthEditField.Value-1)/2;
                    y1 = round(y)-(app.NoiseHeightEditField.Value-1)/2;
                    y2 = round(y)+(app.NoiseHeightEditField.Value-1)/2;
                    app.NoiseremovallevelEditField.Value = mean(app.Itot(y1:y2,x1:x2),'all')...
                        /app.Stack.nangle*app.NoiseFactorEditField.Value;
                end
                app.TabGroup.SelectedTab = app.AdvancedTab;
            end
        end

        % Value changed function: BinWidthEditField
        function BinWidthEditFieldValueChanged(app, event)
            BIN = ones(app.BinHeightEditField.Value,app.BinWidthEditField.Value);                                                     
            app.Itot = convn(app.Sumcor,BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value;
            app.ROI = zeros(size(app.Itot));
            app.ROI_ILow = zeros(size(app.Itot));
            app.Mask = zeros(size(app.Itot));
            app.Patch.XData = [];
            app.Patch.YData = [];
            app.int_roi = 0;
            Represent_Fluo(app,app.Itot,false);
            Represent_Thrshld(app,app.Itot,true,false);
        end

        % Value changed function: BinHeightEditField
        function BinHeightEditFieldValueChanged(app, event)
            BIN = ones(app.BinHeightEditField.Value,app.BinWidthEditField.Value);                                                     
            app.Itot = convn(app.Sumcor,BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value;
            app.ROI = zeros(size(app.Itot));
            app.ROI_ILow = zeros(size(app.Itot));
            app.Mask = zeros(size(app.Itot));
            app.Patch.XData = [];
            app.Patch.YData = [];
            app.int_roi = 0;
            Represent_Fluo(app,app.Itot,false);
            Represent_Thrshld(app,app.Itot,true,false);
        end

        % Button pushed function: DisplayConeButton
        function DisplayConeButtonPushed(app, event)
            if strcmp(app.PolarimetryMethod.Value,'1PF')
                max(app.P.PsiTest(:))
                figure('Name',['Disk Cone : ' app.NameOfDiskCone.Value]);
                subplot(1,2,1);
                imshow(app.P.RoTest,jet); axis image; title('Rho Test');
                set(gca,'visible','on');
                subplot(1,2,2)
                imshow(app.P.PsiTest,jet); axis image; title('Psi Test');   
                set(gca,'visible','on');
            end
        end

        % Value changed function: FigureRotationEditField
        function FigureRotationEditFieldValueChanged(app, event)
            Represent_Fluo(app,app.Itot,false);
        end

        % Callback function
        function PolarimetryAnalysisAboutPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
        end

        % Value changed function: ContrastThrshldSlider
        function ContrastThrshldSliderValueChanged(app, event)
            if app.ContrastThrshldSlider.Value<=0.001
                app.ContrastThrshldSlider.Value = 0.001;
            end
            Represent_Thrshld(app,app.Itot,true,false);
        end

        % Value changed function: SelectDiskCone
        function SelectDiskConeValueChanged(app, event)
            switch app.PolarimetryMethod.Value
                case '1PF'
                    filepath = ['DiskCones' filesep];
                    switch app.SelectDiskCone.Value
                        case '1'
                            name = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 85;
                        case '2'
                            name = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 30;
                        case '3'
                            name = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 35;
                        case '4'
                            name = 'Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '5'
                            name = 'Disk_Ga-0.2_Pa0_Ta0_Gb0.1_Pb0_Tb0_Gc-0.2_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '6'
                            name = 'Disk_Ga-0.2_Pa0_Ta45_Gb0.1_Pb0_Tb45_Gc-0.1_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '7'
                            name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb20_Tb45_Gc-0.2_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '8'
                            name = 'Disk_Ga-0.2_Pa0_Ta0_Gb0.2_Pb20_Tb0_Gc-0.2_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '9'
                            name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb10_Tb45_Gc-0.2_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '10'
                            name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb10_Tb45_Gc0.1_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '11'
                            name = 'Disk_Ga0.1_Pa0_Ta45_Gb-0.1_Pb20_Tb0_Gc-0.1_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '12'
                            name = 'Disk_Ga-0.1_Pa10_Ta0_Gb0.1_Pb30_Tb0_Gc0.2_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '13'
                            name = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
                            app.OffsetAngleDegEditField.Value = 100;
                        case '14'
                            [file,filepath] = uigetfile('Disk*.mat');
                            [~,name,~] = fileparts([filepath file]);
                            app.OffsetAngleDegEditField.Value = 0;
                    end
                    load([filepath name '.mat'],'RoTest','PsiTest','NbMapValues');
                    app.NameOfDiskCone.Value = name;
                    app.P.RoTest = double(RoTest);
                    app.P.PsiTest = double(PsiTest);
                    app.P.NbMapValues = NbMapValues;
                case '4POLAR 3D' 
                    filepath = ['CalibrationData' filesep];
                    switch app.SelectDiskCone.Value 
                        case '1'
                            name = 'Calib_20221102_3D';
                        case '2'
                            name = 'Calib_20221011_3D';
                        case '3'
                            [file,filepath] = uigetfile('Calib*_3D.mat');
                            [~,name,~] = fileparts([filepath file]);
                    end
                    app.OffsetAngleDegEditField.Value = 0;
                    app.NameOfDiskCone.Value = name;
                    load([filepath name '.mat'],'K');
                    app.invKmat = inv(K);
                case '4POLAR 2D'
                    filepath = ['CalibrationData' filesep];
                    switch app.SelectDiskCone.Value 
                        case '1'
                            name = 'Calib_20221102_2D';
                        case '2'
                            name = 'Calib_20221011_2D';
                        case '3'
                            [file,filepath] = uigetfile('Calib*_2D.mat');
                            [~,name,~] = fileparts([filepath file]);
                    end
                    app.OffsetAngleDegEditField.Value = 0;
                    app.NameOfDiskCone.Value = name;
                    load([filepath name '.mat'],'K');
                    app.invKmat = inv(K);
            end
        end

        % Button pushed function: CheckWebsite
        function CheckWebsiteButtonPushed(app, event)
            web('https://www.fresnel.fr/polarimetry')
        end

        % Button pushed function: Reload
        function ReloadButtonPushed(app, event)
            app.int_roi = 0;
            app.Patch.XData = [];
            app.Patch.YData = [];
            Represent_Fluo(app,app.Itot,true);
            Represent_Thrshld(app,app.Itot,true,true);
            app.ROI = zeros(size(app.Itot));
            app.ROI_ILow = zeros(size(app.Itot));
            app.Mask = zeros(size(app.Itot));
        end

        % Value changed function: PolarimetryMethod
        function PolarimetryMethodValueChanged(app, event)
            cla(app.UIAxesFluo)
            switch app.PolarimetryMethod.Value
                case '1PF'
                    Initialize_Table(app,'all')
                    app.OffsetAngleDegEditField.Value = 85;
                    app.UITableVariable.Position = [358 407 222 73];
                    app.UITableVariable.RowName = {char(961),char(936)};
                    app.UITableVariable.Data = {false,0,180;false,40,180};    
                    app.UITableVariable.ColumnFormat{2} = 'short';
                    app.UITableVariable.ColumnFormat{3} = 'short';
                    app.DiskConeCalibrationDataPanel.Visible = 'on';
                    app.SelectDiskCone.Items = app.DiskData;
                    app.SelectDiskCone.ItemsData = app.DiskDataLabels;
                    app.SelectDiskCone.Tooltip = app.DiskDataTooltip;
                    app.DisplayConeButton.Visible = 'on';
                    Initialize_DiskCone(app)
                    app.Angles4polarDropDown.Visible = 'off';
                    app.Angles4polarDropDown.Enable = 'off';
                case {'CARS','SRS','2PF'}
                    Initialize_Table(app,'all')
                    app.OffsetAngleDegEditField.Value = 0;
                    app.UITableVariable.Position = [358 385 222 95];
                    app.UITableVariable.RowName = {char(961),'S2','S4'};
                    app.UITableVariable.Data = {false,0,180;false,0,1;false,-1,1};
                    app.UITableVariable.ColumnFormat{2} = 'bank';
                    app.UITableVariable.ColumnFormat{3} = 'bank';
                    app.DiskConeCalibrationDataPanel.Visible = 'off';
                case 'SHG'
                    Initialize_Table(app,'all')             
                    app.OffsetAngleDegEditField.Value = 0;
                    app.UITableVariable.Position = [358 407 222 73];
                    app.UITableVariable.RowName = {char(961),'S'}; 
                    app.UITableVariable.Data = {false,0,180;false,-1,1};
                    app.UITableVariable.ColumnFormat{2} = 'bank';
                    app.UITableVariable.ColumnFormat{3} = 'bank';
                    app.DiskConeCalibrationDataPanel.Visible = 'off';
                case {'4POLAR 2D','4POLAR 3D'}
                    Initialize_Table(app,'all')
                    app.OffsetAngleDegEditField.Value = 0;
                    app.UITableVariable.Position = [358 385 222 95];
                    app.UITableVariable.RowName = {char(961),char(936),char(951)};
                    app.UITableVariable.Data = {false,0,180;false,40,180;false,0,90};    
                    app.UITableVariable.ColumnFormat{2} = 'short';
                    app.UITableVariable.ColumnFormat{3} = 'short';
                    app.DiskConeCalibrationDataPanel.Visible = 'on';               
                    app.SelectDiskCone.Tooltip = {'Select .mat file containing the calibration data'};
                    app.DisplayConeButton.Visible = 'off';
                    if strcmp(app.PolarimetryMethod.Value,'4POLAR 3D')
                        app.UITableVariable.Position = [358 385 222 95];
                        app.UITableVariable.RowName = {char(961),char(936),char(951)};
                        app.UITableVariable.Data = {false,0,180;false,40,180;false,0,90};
                        app.SelectDiskCone.Items = {'2022 11 02 (3D)','2022 10 11 (3D)','other'};  
                        app.NameOfDiskCone.Value = 'Calib_20221102 (3D)';
                        load('CalibrationData/Calib_20221102_3D.mat','K');                       
                        app.invKmat = inv(K);
                    elseif strcmp(app.PolarimetryMethod.Value,'4POLAR 2D')
                        app.UITableVariable.Position = [358 385 222 73];
                        app.UITableVariable.RowName = {char(961),char(936)};
                        app.UITableVariable.Data = {false,0,180;false,40,180};
                        app.SelectDiskCone.Items = {'2022 11 02 (2D)','2022 10 11 (2D)','other'};
                        app.NameOfDiskCone.Value = 'Calib_20221102 (2D)';
                        load('CalibrationData/Calib_20221102_2D.mat','K');                     
                        app.invKmat = inv(K);
                    end
                    app.SelectDiskCone.ItemsData = string(1:length(app.SelectDiskCone.Items));
                    app.Angles4polarDropDown.Visible = 'on';
                    app.Angles4polarDropDown.Enable = 'on';
                    app.Angles4polarDropDown.Value = 'UL90-UR0-LR45-LL135';
                    determine_order(app,app.Angles4polarDropDown.Value)
                    register = uiconfirm(app.PolarimetryAnalysisUIFigure,{'<b>Perform:</b> Select a beads file (<tt>*.tif</tt>)',...
                        '<b>Load:</b> Select a registration file (<tt>*_reg.mat</tt>)',' ',...
                        'Registration is performed with <tt>Whitelight.tif</tt> which should be in the same folder as the beads file'}, ...
                        'Registration','Options',{'Perform','Load','Cancel'},'DefaultOption',2,'Icon','Icons/round_blur_circular_black_48dp.png',...
                        'Interpreter','html');
                    switch register
                        case 'Perform'
                            app.npix = 5;
                            [filename,path_] = uigetfile('*.tif','Select a beads file');
                            info = imfinfo([path_ filename]);
                            beadstack.Values = zeros(info(1).Height,info(1).Width,1,'double');
                            beadstack.Height = info(1).Height;
                            beadstack.Width = info(1).Width;
                            beadstack.nangle = 1;
                            beadstack.Values(:,:,1) = Tiff([path_ filename],'r').read();
                            dark = ComputeDark(app,beadstack);
                            itot = sum((beadstack.Values-dark).*(beadstack.Values>=dark),3);
                            Whitelight = Tiff([path_ 'Whitelight.tif'],'r').read();
                            B = bwboundaries(imbinarize(Whitelight,graythresh(Whitelight)),'noholes');
                            B = B(cellfun(@(x)length(x)>=200,B));
                            xpos_ = zeros(2,4);
                            ypos_ = zeros(2,4);
                            for it = 1:length(B)
                               xpos_(1:2,it) = [min(B{it}(:,2)) max(B{it}(:,2))];
                               ypos_(1:2,it) = [min(B{it}(:,1)) max(B{it}(:,1))];
                            end
                            dx = max(xpos_(2,:)-xpos_(1,:));
                            dy = max(ypos_(2,:)-ypos_(1,:));
                            ind = 1:4;
                            [~,Iul] = min(xpos_(1,:)+1i*ypos_(1,:));
                            [~,Ilr] = max(xpos_(1,:)+1i*ypos_(1,:));
                            ind(ind==Iul|ind==Ilr) = [];
                            [~,Iur] = min(ypos_(1,ind));
                            [~,Ill] = max(ypos_(1,ind));
                            app.xpos(:,1:4) = xpos_(:,[Iul ind(Iur) Ilr ind(Ill)]);
                            app.ypos(:,1:4) = ypos_(:,[Iul ind(Iur) Ilr ind(Ill)]);
                            ims = cell(1,4);
                            for it = 1:length(ims)
                                ddx = dx+app.npix+app.xpos(1,it)-1;
                                ddy = dy+app.npix+app.ypos(1,it)-1;
                                ims{it} = itot(app.ypos(1,it)-app.npix:ddy,app.xpos(1,it)-app.npix:ddx);
                                ims{it} = ims{it}/max(ims{it}(:));
                                ims{it} = (ims{it}>=graythresh(ims{it})).*ims{it};
                            end
                            [optimizer,metric] = imregconfig('multimodal');
                            optimizer.MaximumIterations = 1000;
                            optimizer.InitialRadius = 1e-3;
                            optimizer.Epsilon = 1.5e-6;
                            optimizer.GrowthFactor = 1.0001;
                            ims_reg = ims;
                            tform_ = cell(2,length(ims));
                            tform_{1,1} = rigid2d;
                            tform_{2,1} = rigid2d;
                            for it = 2:length(ims)
                                tform_{1,it} = imregtform(ims{it},ims{1},'translation',optimizer,metric);
                                ims_reg{it} = imwarp(ims{it},tform_{1,it},'OutputView',imref2d(size(ims{1})));
                                tform_{2,it} = imregtform(ims_reg{it},ims{1},'affine',optimizer,metric);
                                ims_reg{it} = imwarp(ims_reg{it},tform_{2,it},'OutputView',imref2d(size(ims{1})));
                            end
                            fig = figure('Name','Quality of calibration');
                            titles = {'UL','UR','LR','LL'};
                            panel = [1 2 4 3];
                            for it = 2:length(ims)
                                subplot(2,2,panel(it));
                                imshowpair(ims{1},ims_reg{it},'Scaling','joint'); axis image;
                                set(gca,'visible','off');
                                title(titles{it})
                            end
                            ok_reg = uiconfirm(app.PolarimetryAnalysisUIFigure,'Are you okay with this registration?', ...
                                'Registration','Options',{'Yes','Yes and Save','No'},'DefaultOption',2,'Icon','Icons/round_blur_circular_black_48dp.png');
                            switch ok_reg
                                case 'Yes'
                                    close(fig)
                                    app.tform = tform_;
                                case 'Yes and Save'
                                    close(fig)
                                    [~,name,~] = fileparts([path_ filename]);
                                    xpos_ = app.xpos;
                                    ypos_ = app.ypos;
                                    npix_ = app.npix;
                                    save([path_ name '_reg.mat'],'tform_','xpos_','ypos_','npix_');
                                    app.tform = tform_;
                                case 'No'
                                    close(fig)
                                    app.PolarimetryMethod.Value = '1PF';
                                    app.PolarimetryMethod.ValueChangedFcn(app,event)
                                    app.tform = [];
                            end
                        case 'Load'
                            [filename,path_] = uigetfile('*_reg.mat','Select a registration file');
                            load([path_ filename],'tform_','xpos_','ypos_','npix_');
                            app.tform = tform_;
                            app.xpos = xpos_;
                            app.ypos = ypos_;
                            app.npix = npix_;
                        case 'Cancel'
                            app.PolarimetryMethod.Value = '1PF';
                            app.PolarimetryMethod.ValueChangedFcn(app,event)
                    end
            end
        end

        % Button pushed function: ContrastThrshldButton
        function ContrastThrshldButtonPushed(app, event)
            if app.ContrastThrshldSlider.Value<=0.5
                app.ContrastThrshldSlider.Value = 0.5;
                Represent_Thrshld(app,app.Itot,true,false);
            else
                app.ContrastThrshldSlider.Value = 1;
                Represent_Thrshld(app,app.Itot,true,false);
            end
        end

        % Button pushed function: ContrastFluoButton
        function ContrastFluoButtonPushed(app, event)
            if app.ContrastSlider.Value<=0.5
                app.ContrastSlider.Value = 0.5;
                Represent_Fluo(app,app.Itot,false);
            else
                app.ContrastSlider.Value = 1;
                Represent_Fluo(app,app.Itot,false);
            end
        end

        % Display data changed function: UITableDisplay
        function UITableDisplayDisplayDataChanged(app, event)
            if any(cell2mat(app.UITableDisplay.Data(:,2)))
                app.UITableSaveExtension.Data(1) = {true};
            else
                app.UITableSaveExtension.Data(1) = {false};
            end
            app.UITableDisplay.Data(cell2mat(app.UITableDisplay.Data(:,2))==1,1) = {true};
        end

        % Value changed function: Switchminmax
        function SwitchminmaxValueChanged(app, event)
            if strcmp(app.Switchminmax.Value,'On')
                app.UITableVariable.ColumnEditable(2) = true;
                app.UITableVariable.ColumnEditable(3) = true;
            else
                app.UITableVariable.ColumnEditable(2) = false;
                app.UITableVariable.ColumnEditable(3) = false;
            end
        end

        % Value changed function: Switchoffset
        function SwitchoffsetValueChanged(app, event)
            if strcmp(app.Switchoffset.Value,'On')
                app.OffsetAngleDegEditField.Editable = true;
            else
                app.OffsetAngleDegEditField.Editable = false;
            end
        end

        % Display data changed function: UITableVariable
        function UITableVariableDisplayDataChanged(app, event)
            app.UITableVariable.Data{1,2} = 0;
            app.UITableVariable.Data{1,3} = 180;
        end

        % Button pushed function: ButtonGithub
        function ButtonGithubPushed(app, event)
            web('https://github.com/cchandre/Polarimetry')
        end

        % Button pushed function: ButtonManual
        function ButtonManualPushed(app, event)
            web('https://github.com/cchandre/Polarimetry/blob/master/README.md')
        end

        % Value changing function: StackSlider
        function StackSliderValueChanging(app, event)
            app.StackSlider.Value = round(event.Value);
            Represent_Fluo(app,app.Itot,false);
        end

        % Value changed function: StackSlider
        function StackSliderValueChanged(app, event)
            app.StackSlider.Value = round(app.StackSlider.Value);
        end

        % Button pushed function: ButtonMail
        function ButtonMailPushed(app, event)
            web('mailto:cristel.chandre@cnrs.fr')
        end

        % Button pushed function: ColormapThrshldButton
        function ColormapThrshldButtonPushed(app, event)
            if strcmp(app.ThrshldColormap,'hot')
                app.ThrshldColormap = 'gray';
            else
                app.ThrshldColormap = 'hot';
            end
            Represent_Thrshld(app,app.Itot,true,false);
        end

        % Value changed function: UserDarkValueEditField
        function UserDarkValueEditFieldValueChanged(app, event)
            [app.Sumcor,app.Itot] = Define_Itot(app,app.Stack);
            Represent_Fluo(app,app.Itot,true);
            Represent_Thrshld(app,app.Itot,true,true);            
        end

        % Value changed function: Angles4polarDropDown
        function Angles4polarDropDownValueChanged(app, event)
            determine_order(app,app.Angles4polarDropDown.Value)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PolarimetryAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {572, 572};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {173, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PolarimetryAnalysisUIFigure = uifigure('Visible', 'off');
            app.PolarimetryAnalysisUIFigure.Name = 'Polarimetry Analysis';
            app.PolarimetryAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PolarimetryAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DownloadFileButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFileButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFileButtonPushed, true);
            app.DownloadFileButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_add_photo_alternate_black_48dp.png');
            app.DownloadFileButton.Text = 'Download file ';
            app.AddROIButton = uibutton(app.LeftPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_center_focus_weak_black_48dp.png');
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Tooltip = {'Add a region of interest'};
            app.AddROIButton.Text = 'Add ROI';
            app.AnalysisButton = uibutton(app.LeftPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_play_circle_filled_black_48dp.png');
            app.AnalysisButton.Enable = 'off';
            app.AnalysisButton.Tooltip = {'Polarimetry analysis'};
            app.AnalysisButton.Text = 'Analysis';
            app.DownloadFolderButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFolderButtonPushed, true);
            app.DownloadFolderButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_create_new_folder_black_48dp.png');
            app.DownloadFolderButton.Text = 'Download folder';
            app.DropDownTool = uidropdown(app.LeftPanel);
            app.DropDownTool.Items = {'Thresholding (manual)', 'Thresholding (auto)', 'Mask (manual)', 'Mask (auto)'};
            app.DropDownTool.ValueChangedFcn = createCallbackFcn(app, @DropDownToolValueChanged, true);
            app.DropDownTool.Enable = 'off';
            app.DropDownTool.Tooltip = {'Select the method of analysis: intensity thresholding or segmentation mask, for single file analysis ("manual") or batch processing ("auto"). The mask has to be binary and in a png format and have the same file name as the respective polarimetry data file.'};
            app.DropDownTool.Value = 'Thresholding (manual)';
            app.PolarimetryAnalysisLogo = uibutton(app.LeftPanel, 'push');
            app.PolarimetryAnalysisLogo.ButtonPushedFcn = createCallbackFcn(app, @PolarimetryAnalysisLogoPushed, true);
            app.PolarimetryAnalysisLogo.Icon = fullfile(pathToMLAPP, 'Icons', 'round_blur_circular_black_48dp.png');
            app.PolarimetryAnalysisLogo.Text = {'POLARIMETRY '; 'ANALYSIS'};
            app.CloseFiguresButton = uibutton(app.LeftPanel, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_close_black_48dp.png');
            app.CloseFiguresButton.Tooltip = {'Close all .fig files'};
            app.CloseFiguresButton.Text = 'Close figures';
            app.PolarimetryMethod = uidropdown(app.LeftPanel);
            app.PolarimetryMethod.Items = {'1PF', 'CARS', 'SRS', 'SHG', '2PF', '4POLAR 2D', '4POLAR 3D'};
            app.PolarimetryMethod.ValueChangedFcn = createCallbackFcn(app, @PolarimetryMethodValueChanged, true);
            app.PolarimetryMethod.Value = '1PF';
            app.ImageMicroscope = uiimage(app.LeftPanel);
            app.ImageMicroscope.ImageSource = fullfile(pathToMLAPP, 'Icons', 'round_biotech_black_48dp.png');
            app.ImageTool = uiimage(app.LeftPanel);
            app.ImageTool.Enable = 'off';
            app.ImageTool.ImageSource = fullfile(pathToMLAPP, 'Icons', 'round_build_black_48dp.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FluorescenceTab = uitab(app.TabGroup);
            app.FluorescenceTab.Title = 'Fluorescence';
            app.UIAxesFluo = uiaxes(app.FluorescenceTab);
            app.UIAxesFluo.XTick = [];
            app.UIAxesFluo.YTick = [];
            app.ContrastSlider = uislider(app.FluorescenceTab);
            app.ContrastSlider.Limits = [0 1];
            app.ContrastSlider.MajorTicks = [0 0.5 1];
            app.ContrastSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Enable = 'off';
            app.ContrastSlider.Tooltip = {''};
            app.ContrastSlider.Value = 1;
            app.MaximumEditFieldLabel = uilabel(app.FluorescenceTab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.FluorescenceTab, 'numeric');
            app.MaximumEditField.Limits = [0 Inf];
            app.MaximumEditField.ValueDisplayFormat = '%.0f';
            app.MaximumEditField.Editable = 'off';
            app.MaximumEditField.Enable = 'off';
            app.DetAngle = uibutton(app.FluorescenceTab, 'push');
            app.DetAngle.ButtonPushedFcn = createCallbackFcn(app, @DetAngleButtonPushed, true);
            app.DetAngle.Icon = fullfile(pathToMLAPP, 'Icons', 'round_square_foot_black_48dp.png');
            app.DetAngle.Enable = 'off';
            app.DetAngle.Tooltip = {'Left click and hold to trace a line segment and determine its angle'};
            app.DetAngle.Text = '';
            app.ContrastFluoButton = uibutton(app.FluorescenceTab, 'push');
            app.ContrastFluoButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastFluoButtonPushed, true);
            app.ContrastFluoButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_brightness_6_black_48dp.png');
            app.ContrastFluoButton.Enable = 'off';
            app.ContrastFluoButton.Tooltip = {'The chosen contrast will be the one used for the fluorescence images in figures'};
            app.ContrastFluoButton.Text = '';
            app.NameOfFile = uieditfield(app.FluorescenceTab, 'text');
            app.NameOfFile.Editable = 'off';
            app.NameOfFile.Visible = 'off';
            app.NameOfFile.Tooltip = {''};
            app.Reload = uibutton(app.FluorescenceTab, 'push');
            app.Reload.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.Reload.Icon = fullfile(pathToMLAPP, 'Icons', 'baseline_restart_alt_black_48dp.png');
            app.Reload.Enable = 'off';
            app.Reload.Tooltip = {'Erase ROIs and reload image'};
            app.Reload.Text = '';
            app.StackSliderLabel = uilabel(app.FluorescenceTab);
            app.StackSliderLabel.Enable = 'off';
            app.StackSliderLabel.Visible = 'off';
            app.StackSliderLabel.Text = 'Stack';
            app.StackSlider = uislider(app.FluorescenceTab);
            app.StackSlider.Limits = [0 18];
            app.StackSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18];
            app.StackSlider.MajorTickLabels = {'T', '2', '4', '6', '8', '10', '12', '14', '16', '18'};
            app.StackSlider.ValueChangedFcn = createCallbackFcn(app, @StackSliderValueChanged, true);
            app.StackSlider.ValueChangingFcn = createCallbackFcn(app, @StackSliderValueChanging, true);
            app.StackSlider.MinorTicks = [];
            app.StackSlider.Enable = 'off';
            app.StackSlider.Visible = 'off';
            app.StackSlider.Tooltip = {'Slider at T for the total intensity fluorescence, otherwise drag through the images of the stack'};
            app.ThresholdingMaskTab = uitab(app.TabGroup);
            app.ThresholdingMaskTab.Title = 'Thresholding/Mask';
            app.UIAxesThrshld = uiaxes(app.ThresholdingMaskTab);
            app.UIAxesThrshld.XTick = [];
            app.UIAxesThrshld.YTick = [];
            app.ILowEditFieldLabel = uilabel(app.ThresholdingMaskTab);
            app.ILowEditFieldLabel.Text = 'ILow';
            app.ILowEditField = uieditfield(app.ThresholdingMaskTab, 'numeric');
            app.ILowEditField.ValueDisplayFormat = '%.0f';
            app.ILowEditField.ValueChangedFcn = createCallbackFcn(app, @ILowEditFieldValueChanged, true);
            app.ILowEditField.Enable = 'off';
            app.ILowImaxLabel = uilabel(app.ThresholdingMaskTab);
            app.ILowImaxLabel.Text = 'ILow/Maximum';
            app.ILowMaximumSlider = uislider(app.ThresholdingMaskTab);
            app.ILowMaximumSlider.Limits = [0 1];
            app.ILowMaximumSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ILowMaximumSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ILowMaximumSlider.ValueChangedFcn = createCallbackFcn(app, @ILowMaximumSliderValueChanged, true);
            app.ILowMaximumSlider.Enable = 'off';
            app.TransparencySlider = uislider(app.ThresholdingMaskTab);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.MajorTicks = [0 0.5 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.Enable = 'off';
            app.TransparencySlider.Tooltip = {'adjust the transparency of the background image'};
            app.TransparencyLabel = uilabel(app.ThresholdingMaskTab);
            app.TransparencyLabel.Text = 'Transparency';
            app.NoBackgroundButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.NoBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @NoBackgroundButtonPushed, true);
            app.NoBackgroundButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_image_black_48dp.png');
            app.NoBackgroundButton.Enable = 'off';
            app.NoBackgroundButton.Tooltip = {'Change background to enhance visibility'};
            app.NoBackgroundButton.Text = '';
            app.ExportMask = uibutton(app.ThresholdingMaskTab, 'push');
            app.ExportMask.ButtonPushedFcn = createCallbackFcn(app, @ExportMaskButtonPushed, true);
            app.ExportMask.Icon = fullfile(pathToMLAPP, 'Icons', 'round_open_in_new_black_48dp.png');
            app.ExportMask.Enable = 'off';
            app.ExportMask.Tooltip = {'Export mask as .png '};
            app.ExportMask.Text = '';
            app.ContrastThrshldSlider = uislider(app.ThresholdingMaskTab);
            app.ContrastThrshldSlider.Limits = [0 1];
            app.ContrastThrshldSlider.MajorTicks = [0 0.5 1];
            app.ContrastThrshldSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastThrshldSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastThrshldSliderValueChanged, true);
            app.ContrastThrshldSlider.Enable = 'off';
            app.ContrastThrshldSlider.Tooltip = {''};
            app.ContrastThrshldSlider.Value = 1;
            app.ContrastThrshldButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.ContrastThrshldButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastThrshldButtonPushed, true);
            app.ContrastThrshldButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_brightness_6_black_48dp.png');
            app.ContrastThrshldButton.Enable = 'off';
            app.ContrastThrshldButton.Tooltip = {''};
            app.ContrastThrshldButton.Text = '';
            app.ColormapThrshldButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.ColormapThrshldButton.ButtonPushedFcn = createCallbackFcn(app, @ColormapThrshldButtonPushed, true);
            app.ColormapThrshldButton.Icon = fullfile(pathToMLAPP, 'Icons', 'outline_palette_black_48dp.png');
            app.ColormapThrshldButton.Enable = 'off';
            app.ColormapThrshldButton.Tooltip = {'Change the colormap used for thresholding (''hot'' or ''gray'')'};
            app.ColormapThrshldButton.Text = '';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.UITableSaveExtension = uitable(app.OptionsTab);
            app.UITableSaveExtension.ColumnName = {'Save extension'};
            app.UITableSaveExtension.RowName = {};
            app.UITableSaveExtension.ColumnEditable = true;
            app.UITableSaveExtension.CellEditCallback = createCallbackFcn(app, @UITableSaveExtensionCellEdit, true);
            app.perROILabel = uilabel(app.OptionsTab);
            app.perROILabel.Text = 'per ROI:';
            app.perROISwitch = uiswitch(app.OptionsTab, 'slider');
            app.perROISwitch.Tooltip = {'show and save data/figures separately for each region of interest'};
            app.DeleteAllButton = uibutton(app.OptionsTab, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_delete_forever_black_48dp.png');
            app.DeleteAllButton.Tooltip = {'Reinitialize the tables Show/Save and Variable'};
            app.DeleteAllButton.Text = '';
            app.UITableDisplay = uitable(app.OptionsTab);
            app.UITableDisplay.ColumnName = {'Show'; 'Save'};
            app.UITableDisplay.RowName = {};
            app.UITableDisplay.ColumnEditable = [true true];
            app.UITableDisplay.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDisplayDataChanged, true);
            app.UITableVariable = uitable(app.OptionsTab);
            app.UITableVariable.ColumnName = {'Variable'; 'Min'; 'Max'};
            app.UITableVariable.RowName = {};
            app.UITableVariable.ColumnEditable = [true false false];
            app.UITableVariable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableVariableDisplayDataChanged, true);
            app.PlotoptionsPanel = uipanel(app.OptionsTab);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.AddAxesCheckBox = uicheckbox(app.PlotoptionsPanel);
            app.AddAxesCheckBox.Text = 'Add axes on figures';
            app.AddAxesCheckBox.Value = true;
            app.PixelsPerStickHeightLabel = uilabel(app.PlotoptionsPanel);
            app.PixelsPerStickHeightLabel.Text = 'vertical';
            app.PixelsPerStickSpinnerHeight = uispinner(app.PlotoptionsPanel);
            app.PixelsPerStickSpinnerHeight.Limits = [1 100];
            app.PixelsPerStickSpinnerHeight.ValueDisplayFormat = '%.0f';
            app.PixelsPerStickSpinnerHeight.Editable = 'off';
            app.PixelsPerStickSpinnerHeight.Tooltip = {'controls the density of sticks to be plotted '};
            app.PixelsPerStickSpinnerHeight.Value = 1;
            app.PixelsPerStickSpinnerWidth = uispinner(app.PlotoptionsPanel);
            app.PixelsPerStickSpinnerWidth.Limits = [1 100];
            app.PixelsPerStickSpinnerWidth.ValueDisplayFormat = '%.0f';
            app.PixelsPerStickSpinnerWidth.Editable = 'off';
            app.PixelsPerStickSpinnerWidth.Tooltip = {'controls the density of sticks to be plotted '};
            app.PixelsPerStickSpinnerWidth.Value = 1;
            app.PixelsPerStickWidthLabel = uilabel(app.PlotoptionsPanel);
            app.PixelsPerStickWidthLabel.Text = 'horizontal';
            app.NumberofpixelsseparatingsticksLabel = uilabel(app.PlotoptionsPanel);
            app.NumberofpixelsseparatingsticksLabel.Text = 'Number of pixels separating sticks';
            app.SwitchminmaxLabel = uilabel(app.OptionsTab);
            app.SwitchminmaxLabel.Text = 'Switch min/max';
            app.Switchminmax = uiswitch(app.OptionsTab, 'slider');
            app.Switchminmax.ValueChangedFcn = createCallbackFcn(app, @SwitchminmaxValueChanged, true);
            app.ShowIndividualFitCheckBox = uicheckbox(app.OptionsTab);
            app.ShowIndividualFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowIndividualFitCheckBoxValueChanged, true);
            app.ShowIndividualFitCheckBox.Text = 'Show individual fit';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.BinningPanel = uipanel(app.AdvancedTab);
            app.BinningPanel.Title = 'Binning';
            app.BinwidthEditFieldLabel = uilabel(app.BinningPanel);
            app.BinwidthEditFieldLabel.Text = 'Bin width';
            app.BinWidthEditField = uieditfield(app.BinningPanel, 'numeric');
            app.BinWidthEditField.Limits = [1 Inf];
            app.BinWidthEditField.ValueDisplayFormat = '%.0f';
            app.BinWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BinWidthEditFieldValueChanged, true);
            app.BinWidthEditField.Value = 1;
            app.BinheightEditFieldLabel = uilabel(app.BinningPanel);
            app.BinheightEditFieldLabel.Text = 'Bin height';
            app.BinHeightEditField = uieditfield(app.BinningPanel, 'numeric');
            app.BinHeightEditField.Limits = [1 Inf];
            app.BinHeightEditField.ValueDisplayFormat = '%.0f';
            app.BinHeightEditField.ValueChangedFcn = createCallbackFcn(app, @BinHeightEditFieldValueChanged, true);
            app.BinHeightEditField.Value = 1;
            app.RemovebackgroundPanel = uipanel(app.AdvancedTab);
            app.RemovebackgroundPanel.Title = 'Remove background';
            app.NoisefactorEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoisefactorEditFieldLabel.Text = 'Noise factor';
            app.NoiseFactorEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseFactorEditField.Limits = [0 1];
            app.NoiseFactorEditField.ValueDisplayFormat = '%.2f';
            app.NoiseFactorEditField.Value = 1;
            app.ClickBackgroundButton = uibutton(app.RemovebackgroundPanel, 'push');
            app.ClickBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ClickBackgroundButtonPushed, true);
            app.ClickBackgroundButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_exposure_black_48dp.png');
            app.ClickBackgroundButton.Tooltip = {'Click and select a point on the fluorescence image'};
            app.ClickBackgroundButton.Text = 'Click background';
            app.NoisewidthEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoisewidthEditFieldLabel.Text = 'Noise width';
            app.NoiseWidthEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseWidthEditField.Limits = [1 Inf];
            app.NoiseWidthEditField.ValueDisplayFormat = '%.0f';
            app.NoiseWidthEditField.Value = 3;
            app.NoiseheightEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoiseheightEditFieldLabel.Text = 'Noise height';
            app.NoiseHeightEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseHeightEditField.Limits = [1 Inf];
            app.NoiseHeightEditField.ValueDisplayFormat = '%.0f';
            app.NoiseHeightEditField.Value = 3;
            app.NoiseremovallevelEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoiseremovallevelEditFieldLabel.Text = 'Noise removal level';
            app.NoiseremovallevelEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseremovallevelEditField.ValueDisplayFormat = '%.0f';
            app.NoiseremovallevelEditField.Enable = 'off';
            app.RotationPanel = uipanel(app.AdvancedTab);
            app.RotationPanel.Title = 'Rotation';
            app.StickLabel = uilabel(app.RotationPanel);
            app.StickLabel.Text = 'Stick (deg)';
            app.StickRotationEditField = uieditfield(app.RotationPanel, 'numeric');
            app.StickRotationEditField.Limits = [0 180];
            app.StickRotationEditField.ValueDisplayFormat = '%.0f';
            app.FigureLabel = uilabel(app.RotationPanel);
            app.FigureLabel.Text = 'Figure (deg)';
            app.FigureRotationEditField = uieditfield(app.RotationPanel, 'numeric');
            app.FigureRotationEditField.Limits = [-180 180];
            app.FigureRotationEditField.ValueDisplayFormat = '%.0f';
            app.FigureRotationEditField.ValueChangedFcn = createCallbackFcn(app, @FigureRotationEditFieldValueChanged, true);
            app.FigureRotationEditField.Tooltip = {'positive value for counter-clockwise rotation / negative value for clockwise rotation'};
            app.DiskConeCalibrationDataPanel = uipanel(app.AdvancedTab);
            app.DiskConeCalibrationDataPanel.Title = 'Disk Cone / Calibration Data';
            app.DisplayConeButton = uibutton(app.DiskConeCalibrationDataPanel, 'push');
            app.DisplayConeButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayConeButtonPushed, true);
            app.DisplayConeButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_image_black_48dp.png');
            app.DisplayConeButton.Tooltip = {'Display the selected disk cone'};
            app.DisplayConeButton.Text = 'Display';
            app.NameOfDiskCone = uieditfield(app.DiskConeCalibrationDataPanel, 'text');
            app.NameOfDiskCone.Editable = 'off';
            app.NameOfDiskCone.Enable = 'off';
            app.SelectDiskCone = uidropdown(app.DiskConeCalibrationDataPanel);
            app.SelectDiskCone.Items = {'488 nm (no distortions)', '561 nm (no distortions)', '640 nm (no distortions)', '488 nm (16/03/2020 - 12/04/2022)', '561 nm (16/03/2020 - 12/04/2022)', '640 nm (16/03/2020 - 12/04/2022)', '488 nm (13/12/2019 - 15/03/2020)', '561 nm (13/12/2019 - 15/03/2020)', '640 nm (13/12/2019 - 15/03/2020)', '488 nm (before 13/12/2019)', '561 nm (before 13/12/2019)', '640 nm (before 13/12/2019)', 'no distortions (before 12/04/2022)', 'other'};
            app.SelectDiskCone.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.SelectDiskCone.ValueChangedFcn = createCallbackFcn(app, @SelectDiskConeValueChanged, true);
            app.SelectDiskCone.Tooltip = {'Select disk cone depending on wavelength and acquisition date'};
            app.SelectDiskCone.Value = '1';
            app.Angles4polarDropDown = uidropdown(app.DiskConeCalibrationDataPanel);
            app.Angles4polarDropDown.Items = {'UL0-UR45-LR90-LL135', 'UL0-UR45-LR135-LL90', 'UL0-UR90-LR45-LL135', 'UL0-UR90-LR135-LL45', 'UL0-UR135-LR45-LL90', 'UL0-UR135-LR90-LL45', 'UL45-UR0-LR90-LL135', 'UL45-UR0-LR135-LL90', 'UL45-UR90-LR0-LL135', 'UL45-UR90-LR135-LL0', 'UL45-UR135-LR0-LL90', 'UL45-UR135-LR90-LL0', 'UL90-UR0-LR45-LL135', 'UL90-UR0-LR135-LL45', 'UL90-UR45-LR0-LL135', 'UL90-UR45-LR135-LL0', 'UL90-UR135-LR0-LL45', 'UL90-UR135-LR45-LL0', 'UL135-UR0-LR45-LL90', 'UL135-UR0-LR90-LL45', 'UL135-UR45-LR0-LL90', 'UL135-UR45-LR90-LL0', 'UL135-UR90-LR0-LL45', 'UL135-UR90-LR45-LL0'};
            app.Angles4polarDropDown.ValueChangedFcn = createCallbackFcn(app, @Angles4polarDropDownValueChanged, true);
            app.Angles4polarDropDown.Enable = 'off';
            app.Angles4polarDropDown.Visible = 'off';
            app.Angles4polarDropDown.Tooltip = {'Select repartition of polarizations (0,45,90,135) among quadrants clockwise : Upper Left (UL), Upper Right (UR), Lower Right (LR), Lower Left (LL)'};
            app.Angles4polarDropDown.Value = 'UL90-UR0-LR45-LL135';
            app.DarkPanel = uipanel(app.AdvancedTab);
            app.DarkPanel.Title = 'Dark';
            app.CalculatedDarkValueCheckBox = uicheckbox(app.DarkPanel);
            app.CalculatedDarkValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatedDarkValueCheckBoxValueChanged, true);
            app.CalculatedDarkValueCheckBox.Text = 'Calculated dark value';
            app.CalculatedDarkValueCheckBox.Value = true;
            app.UserdarkvalueEditFieldLabel = uilabel(app.DarkPanel);
            app.UserdarkvalueEditFieldLabel.Text = 'User dark value ';
            app.UserDarkValueEditField = uieditfield(app.DarkPanel, 'numeric');
            app.UserDarkValueEditField.ValueDisplayFormat = '%.0f';
            app.UserDarkValueEditField.ValueChangedFcn = createCallbackFcn(app, @UserDarkValueEditFieldValueChanged, true);
            app.UserDarkValueEditField.Enable = 'off';
            app.UserDarkValueEditField.Value = 480;
            app.CalculatedDarkValueEditField = uieditfield(app.DarkPanel, 'numeric');
            app.CalculatedDarkValueEditField.ValueDisplayFormat = '%.0f';
            app.CalculatedDarkValueEditField.Editable = 'off';
            app.CalculatedDarkValueEditField.Enable = 'off';
            app.CalculatedDarkValueEditField.Value = 480;
            app.OffsetanglePanel = uipanel(app.AdvancedTab);
            app.OffsetanglePanel.Title = 'Offset angle';
            app.OffsetangledegEditFieldLabel = uilabel(app.OffsetanglePanel);
            app.OffsetangledegEditFieldLabel.Text = 'Offset angle (deg)';
            app.OffsetAngleDegEditField = uieditfield(app.OffsetanglePanel, 'numeric');
            app.OffsetAngleDegEditField.Limits = [0 180];
            app.OffsetAngleDegEditField.ValueDisplayFormat = '%.0f';
            app.OffsetAngleDegEditField.Editable = 'off';
            app.OffsetAngleDegEditField.Value = 85;
            app.Switchoffset = uiswitch(app.OffsetanglePanel, 'slider');
            app.Switchoffset.ValueChangedFcn = createCallbackFcn(app, @SwitchoffsetValueChanged, true);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.InfoAbout = uitextarea(app.AboutTab);
            app.InfoAbout.Editable = 'off';
            app.InfoAbout.Value = {'Version: 2.1 (November 3, 2022)  created with App Designer - MATLAB R2022a'; ''; ''; 'Website: www.fresnel.fr/polarimetry/'; ''; ''; 'Source code available at github.com/cchandre/Polarimetry '; ''; ''; ''; ''; 'Based on a code originally developed by Sophie Brasselet (Institut Fresnel, CNRS)'; ''; ''; 'To report bugs, send an email to '; '            manos.mavrakis@cnrs.fr  (Manos Mavrakis, Institut Fresnel, CNRS) '; '            cristel.chandre@cnrs.fr  (Cristel Chandre, Institut de Mathématiques de Marseille, CNRS)'; '            sophie.brasselet@fresnel.fr  (Sophie Brasselet, Institut Fresnel, CNRS)'; ''; ''; ''; 'BSD 2-Clause License'; ''; 'Copyright (c) 2021, Cristel Chandre'; 'All rights reserved.'; ''; ''; 'uses Material Design icons by Google'};
            app.CheckWebsite = uibutton(app.AboutTab, 'push');
            app.CheckWebsite.ButtonPushedFcn = createCallbackFcn(app, @CheckWebsiteButtonPushed, true);
            app.CheckWebsite.Icon = fullfile(pathToMLAPP, 'Icons', 'round_web_black_48dp.png');
            app.CheckWebsite.Text = 'CHECK WEBSITE FOR UPDATES';
            app.ButtonGithub = uibutton(app.AboutTab, 'push');
            app.ButtonGithub.ButtonPushedFcn = createCallbackFcn(app, @ButtonGithubPushed, true);
            app.ButtonGithub.Icon = fullfile(pathToMLAPP, 'Icons', 'GitHub-Mark-64px.png');
            app.ButtonGithub.Text = '';
            app.ButtonManual = uibutton(app.AboutTab, 'push');
            app.ButtonManual.ButtonPushedFcn = createCallbackFcn(app, @ButtonManualPushed, true);
            app.ButtonManual.Icon = fullfile(pathToMLAPP, 'Icons', 'round_help_outline_black_48dp.png');
            app.ButtonManual.Text = '';
            app.ButtonMail = uibutton(app.AboutTab, 'push');
            app.ButtonMail.ButtonPushedFcn = createCallbackFcn(app, @ButtonMailPushed, true);
            app.ButtonMail.Icon = fullfile(pathToMLAPP, 'Icons', 'round_email_black_48dp.png');
            app.ButtonMail.Text = '';
            % Show the figure after all components are created
            app.PolarimetryAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarimetryPlus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolarimetryAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolarimetryAnalysisUIFigure)
        end
    end
end