classdef PolarimetryContour < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolarimetryContourUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DownloadFileButton              matlab.ui.control.Button
        AddROIButton                    matlab.ui.control.Button
        AnalysisButton                  matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        PolarimetryContourLogo          matlab.ui.control.Button
        CloseFiguresButton              matlab.ui.control.Button
        DownloadDataButton              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        FluorescenceTab                 matlab.ui.container.Tab
        UIAxesFluo                      matlab.ui.control.UIAxes
        ContrastFluoSlider              matlab.ui.control.Slider
        MaximumIntensityEditFieldLabel  matlab.ui.control.Label
        MaximumIntensityEditField       matlab.ui.control.NumericEditField
        WidthRuler                      matlab.ui.control.Button
        ContrastFluoButton              matlab.ui.control.Button
        NameOfFile                      matlab.ui.control.EditField
        ShiftRuler                      matlab.ui.control.Button
        EdgedetectionROITab             matlab.ui.container.Tab
        UIAxesThrshld                   matlab.ui.control.UIAxes
        ILowEditFieldLabel              matlab.ui.control.Label
        ILowEditField                   matlab.ui.control.NumericEditField
        ILowImaxLabel                   matlab.ui.control.Label
        ILowImaxSlider                  matlab.ui.control.Slider
        ContrastThrshldSlider           matlab.ui.control.Slider
        ContrastThrshldButton           matlab.ui.control.Button
        OptionsTab                      matlab.ui.container.Tab
        UITableShowSave                 matlab.ui.control.Table
        UITableSaveExtension            matlab.ui.control.Table
        perROISwitchLabel               matlab.ui.control.Label
        perROISwitch                    matlab.ui.control.Switch
        pixelsperstickLabel             matlab.ui.control.Label
        PixelsPerStickSpinner           matlab.ui.control.Spinner
        AddAxesCheckBox                 matlab.ui.control.CheckBox
        EdgedetectionPanel              matlab.ui.container.Panel
        LowThresholdEditFieldLabel      matlab.ui.control.Label
        LowThresholdEditField           matlab.ui.control.NumericEditField
        HighThresholdEditFieldLabel     matlab.ui.control.Label
        HighThresholdEditField          matlab.ui.control.NumericEditField
        MethodDropDownLabel             matlab.ui.control.Label
        EdgeDetectionMethodDropDown     matlab.ui.control.DropDown
        LayerwidthinpixelsLabel         matlab.ui.control.Label
        WidthLayer                      matlab.ui.control.NumericEditField
        DeleteAllButton                 matlab.ui.control.Button
        DeleteSaveButton                matlab.ui.control.Button
        DistancefromcontourinpixelsLabel  matlab.ui.control.Label
        ShiftLayer                      matlab.ui.control.NumericEditField
        ShiftRuler_2                    matlab.ui.control.Button
        WidthRuler_2                    matlab.ui.control.Button
        AboutTab                        matlab.ui.container.Tab
        InfoAbout                       matlab.ui.control.TextArea
        CheckWebsite                    matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        I_tot % Mean intensity
        ROI % ROI map with ROI index numbers
        int_roi % number of ROIs
        Im % intensities
        Patch % Patch objects
        Boundary % X, Y, Color for the contour
        Rho % Rho values from Polarimetry Analysis
        ImageThrshld % label for thresholding image
        FileName % file name to be analyzed
        DataFileName % name of the .mat file
        Mask % Mask file
    end
    
    methods (Access = private)
        
        function Represent_Fluo(app, Itot, DrawNow)
            if ~DrawNow
                XLimFluo = app.UIAxesFluo.XLim;
                YLimFluo = app.UIAxesFluo.YLim;
            end
            im = imadjust(imsharpen(Itot/max(Itot(:))), [0 1], [0 1], app.ContrastFluoSlider.Value);
            imagesc(app.UIAxesFluo, im);
            axis(app.UIAxesFluo, 'image');
            colormap(app.UIAxesFluo, gray); 
            if ~DrawNow 
                app.UIAxesFluo.XLim = XLimFluo;
                app.UIAxesFluo.YLim = YLimFluo;
            end
            if app.int_roi~=0 && ~isempty(app.Patch)
                for it = 1:app.int_roi
                    patch(app.UIAxesFluo, 'XData', app.Patch.XData{it}, 'YData', app.Patch.YData{it},'FaceAlpha', 0, 'EdgeColor', 'w');
                    text(app.UIAxesFluo, app.Patch.XData{it}(1), app.Patch.YData{it}(1), num2str(it), 'FontSize', 20, 'Color','w');
                end
            end
        end
        
        function Itot = Define_I_tot(app, Im)       
            Itot = sum(Im,3);
            app.MaximumIntensityEditField.Value = max(Itot(:));
        end
        
        function PolarimetryContourAnalysis(app, Itot, filename, ROI)
            %% Parameters
            W_sticks = 0.2;                 % width of sticks
            L_sticks = 6;                   % length of sticks
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [foldername, name, ~] = fileparts(filename);
            SaveExtensionList = app.UITableSaveExtension.Data(cell2mat(app.UITableSaveExtension.Data(:,2))==1,1);
            ShowList = app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,2))==1,1);
            SaveList = app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,1);
            [ImageWidth, ImageHeight] = size(Itot);
            
            MapOfRho = NaN(size(Itot));
            MapOfRho(sub2ind(size(MapOfRho), app.Rho.X, app.Rho.Y)) = app.Rho.Values;
            MapOfCT = NaN(size(Itot));
            [Xroi, Yroi] = find(ROI~=0);
            [~, ix, ~] = intersect([uint16(app.Boundary.X) uint16(app.Boundary.Y)], [Xroi Yroi],'rows');
            BoundaryX = app.Boundary.X(ix);
            BoundaryY = app.Boundary.Y(ix);
            BoundaryNormal1 = app.Boundary.Normal(ix,1);
            BoundaryNormal2 = app.Boundary.Normal(ix,2);
            BoundaryAngle = app.Boundary.Angle(ix); 
            for it = [-app.WidthLayer.Value-app.ShiftLayer.Value:-app.ShiftLayer.Value app.ShiftLayer.Value:app.WidthLayer.Value+app.ShiftLayer.Value]
                shiftX = uint16(BoundaryX+it*BoundaryNormal1);
                shiftY = uint16(BoundaryY+it*BoundaryNormal2);
                shiftX(shiftX<=1) = 1;
                shiftX(shiftX>=ImageWidth) = ImageWidth;
                shiftY(shiftY<=1) = 1;
                shiftY(shiftY>=ImageHeight) = ImageHeight;
                MapOfCT(sub2ind(size(MapOfCT), shiftX, shiftY)) = BoundaryAngle;
            end
            MapOfCT(ROI==0) = NaN;
            MapOfCT(~isfinite(MapOfRho)) = NaN;
            MapOfRho_c = wrapTo360(2*(MapOfRho-MapOfCT))/2;
            
            if any(strcmp(ShowList, 'Composite Contour'))
                figCompositeC = figure('Name', 'Composite: Contour');
                ax1 = axes(figCompositeC);
                imagesc(ax1, imsharpen(Itot)); colormap(gray);
                c1 = colorbar; axis image; c1.Visible = 'off';
                set(gca, 'visible', 'on');
                ax2 = axes(figCompositeC); 
                h = imagesc(ax2, MapOfCT, [0 180]);
                set(gca, 'visible', 'off');
                colormap(ax2, hsv(182)); 
                colorbar; axis image;
                alpha(h, isfinite(MapOfCT)*1);
                ax2.Visible = 'off';
                linkaxes([ax1, ax2], 'xy');
                title(name, 'Interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                if any(strcmp(SaveList, 'Composite Contour')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figCompositeC, [foldername filesep name '_Composite_Contour.fig']);
                end
            end
            
            if any(strcmp(ShowList, 'Rho Sticks'))
                [X_c, Y_c] = meshgrid(1:ImageHeight, 1:ImageWidth);
                X_c = X_c(isfinite(MapOfRho));
                Y_c = Y_c(isfinite(MapOfRho));
                RhoRad = MapOfRho(isfinite(MapOfRho))*pi/180;
                RhoDeg = MapOfRho(isfinite(MapOfRho));
                X_c = X_c(1:app.PixelsPerStickSpinner.Value:end);
                Y_c = Y_c(1:app.PixelsPerStickSpinner.Value:end);
                RhoRad = RhoRad(1:app.PixelsPerStickSpinner.Value:end);
                RhoDeg = RhoDeg(1:app.PixelsPerStickSpinner.Value:end);
                figStickRho = figure('Name', 'Rho Sticks');
                ax1 = axes(figStickRho);
                imagesc(ax1, imsharpen(Itot)); colormap(gray);
                c1 = colorbar; axis image; c1.Visible = 'off';
                set(gca, 'visible', 'on');
                ax2 = axes(figStickRho);
                VertexRho_r = (RhoDeg.*ones(1,4)).';
                patch(ax2, [X_c-L_sticks/2*cos(RhoRad)+W_sticks/2*sin(RhoRad) X_c+L_sticks/2*cos(RhoRad)+W_sticks/2*sin(RhoRad)...
                    X_c+L_sticks/2*cos(RhoRad)-W_sticks/2*sin(RhoRad) X_c-L_sticks/2*cos(RhoRad)-W_sticks/2*sin(RhoRad)].',...
                    [Y_c-L_sticks/2*sin(RhoRad)-W_sticks/2*cos(RhoRad) Y_c+L_sticks/2*sin(RhoRad)-W_sticks/2*cos(RhoRad)...
                    Y_c+L_sticks/2*sin(RhoRad)+W_sticks/2*cos(RhoRad) Y_c-L_sticks/2*sin(RhoRad)+W_sticks/2*cos(RhoRad)].', RhoDeg.',...
                    'LineWidth', 2, 'EdgeAlpha', 0.2, 'EdgeColor', 'flat', 'FaceVertexCData', VertexRho_r(:));
                colormap(ax2, hsv(182));
                colorbar; 
                set(gca, 'YDir', 'reverse');
                caxis([0 180]);
                axis image; 
                ax2.Visible = 'off'; 
                linkaxes([ax1, ax2], 'xy');
                title(name, 'interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                if any(strcmp(SaveList, 'Rho Sticks')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figStickRho, [foldername filesep name '_RhoSticks.fig']);
                end
            end
            
            if any(strcmp(ShowList, 'Rho Sticks vs Contour'))
                [X_c, Y_c] = meshgrid(1:ImageHeight, 1:ImageWidth);
                X_c = X_c(isfinite(MapOfRho_c));
                Y_c = Y_c(isfinite(MapOfRho_c));
                RhoRad = MapOfRho(isfinite(MapOfRho_c))*pi/180;
                Rho_c = MapOfRho_c(isfinite(MapOfRho_c));
                X_c = X_c(1:app.PixelsPerStickSpinner.Value:end);
                Y_c = Y_c(1:app.PixelsPerStickSpinner.Value:end);
                RhoRad = RhoRad(1:app.PixelsPerStickSpinner.Value:end);
                Rho_c = Rho_c(1:app.PixelsPerStickSpinner.Value:end);
                figStickRho_vsC = figure('Name', 'Rho Sticks vs Contour');
                ax1 = axes(figStickRho_vsC);
                imagesc(ax1, imsharpen(Itot)); colormap(gray);
                c1 = colorbar; axis image; c1.Visible = 'off';
                set(gca, 'visible', 'on');
                ax2 = axes(figStickRho_vsC);
                VertexRho_r = (Rho_c.*ones(1,4)).';
                patch(ax2, [X_c-L_sticks/2*cos(RhoRad)+W_sticks/2*sin(RhoRad) X_c+L_sticks/2*cos(RhoRad)+W_sticks/2*sin(RhoRad)...
                    X_c+L_sticks/2*cos(RhoRad)-W_sticks/2*sin(RhoRad) X_c-L_sticks/2*cos(RhoRad)-W_sticks/2*sin(RhoRad)].',...
                    [Y_c-L_sticks/2*sin(RhoRad)-W_sticks/2*cos(RhoRad) Y_c+L_sticks/2*sin(RhoRad)-W_sticks/2*cos(RhoRad)...
                    Y_c+L_sticks/2*sin(RhoRad)+W_sticks/2*cos(RhoRad) Y_c-L_sticks/2*sin(RhoRad)+W_sticks/2*cos(RhoRad)].', Rho_c.',...
                    'LineWidth', 2, 'EdgeAlpha', 0.2, 'EdgeColor', 'flat', 'FaceVertexCData', VertexRho_r(:));
                colormap(ax2, hsv(182));
                colorbar; 
                set(gca, 'YDir', 'reverse');
                caxis([0 180]);
                axis image; 
                ax2.Visible = 'off'; 
                linkaxes([ax1, ax2], 'xy');
                title(name, 'interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                if any(strcmp(SaveList, 'Rho Sticks vs Contour')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figStickRho_vsC, [foldername filesep name '_RhoSticks_vs_Contour.fig']);
                end
            end
            
            if any(strcmp(ShowList, 'Histogram Rho vs Contour (0-180)')) || any(strcmp(ShowList, 'Polar Histogram Rho vs Contour (0-180)'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:app.int_roi
                        Rho_c = MapOfRho_c(isfinite(MapOfRho_c) & ROI==it);
                        avgCRho = mean(exp(-2i*Rho_c*pi/180));
                        avgRho = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                        if any(strcmp(ShowList, 'Histogram Rho vs Contour (0-180)'))
                            figHistoRho180 = figure('Name', ['Histogram (0-180): Rho vs Contour for ROI' num2str(it)]);
                            ax1 = axes(figHistoRho180);
                            [NbRho, RhoValues] = histcounts(Rho_c, 0:3:180);
                            b = bar(ax1, RhoValues(1:end-1)+1.5, NbRho, 'hist');
                            b.FaceColor = 'flat';
                            b.CData = linspace(1, 2, length(NbRho));
                            colormap(hsv(182));
                            xlim([0 180]); 
                            xlabel('$\rho - \rho_C $', 'Interpreter', 'latex', 'FontSize', 20);
                            annotation(figHistoRho180, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                            annotation(figHistoRho180, 'textbox', [0.65 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(avgRho, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                            if any(strcmp(SaveList, 'Histogram Rho vs Contour (0-180)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                                savefig(figHistoRho180, [foldername filesep name '_HistoRho_vs_Contour_180_' num2str(it) '.fig']);
                            end
                        end
                        if any(strcmp(ShowList, 'Polar Histogram Rho vs Contour (0-180)'))
                            figPolarHistoRho180 = figure('Name', ['Polar Histogram (0-180): Rho vs Contour for ROI' num2str(it)]);
                            pax = polaraxes(figPolarHistoRho180);
                            polarhistogram(pax, Rho_c*pi/180, (0:3:180)*pi/180);
                            pax.ThetaDir = 'clockwise';
                            pax.ThetaLim = [0 180];
                            pax.ThetaZeroLocation = 'left';
                            annotation(figPolarHistoRho180, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                            annotation(figPolarHistoRho180, 'textbox', [0.65 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(avgRho, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                            if any(strcmp(SaveList, 'Polar Histogram Rho vs Contour (0-180)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                                savefig(figPolarHistoRho180, [foldername filesep name '_PolarHistoRho_vs_Contour_180_' num2str(it) '.fig']);
                            end
                        end
                    end
                else
                    Rho_c = MapOfRho_c(isfinite(MapOfRho_c) & ROI~=0);
                    avgCRho = mean(exp(-2i*Rho_c*pi/180));
                    meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                    if any(strcmp(ShowList, 'Histogram Rho vs Contour (0-180)'))
                        figHistoRho180 = figure('Name', 'Histogram (0-180): Rho vs Contour');
                        ax1 = axes(figHistoRho180);
                        [NbRho, RhoValues] = histcounts(Rho_c, 0:3:180);
                        b = bar(ax1, RhoValues(1:end-1)+1.5, NbRho, 'hist');
                        b.FaceColor = 'flat';
                        b.CData = linspace(1, 2, length(NbRho));
                        colormap(hsv(182));
                        xlim([0 180]); 
                        xlabel('$\rho - \rho_C $', 'Interpreter', 'latex', 'FontSize', 20);
                        annotation(figHistoRho180, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figHistoRho180, 'textbox', [0.65 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(meanCangle, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        if any(strcmp(SaveList, 'Histogram Rho vs Contour (0-180)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figHistoRho180, [foldername filesep name '_HistoRho_vs_Contour_180.fig']);
                        end
                    end
                    if any(strcmp(ShowList, 'Polar Histogram Rho vs Contour (0-180)'))
                        figPolarHistoRho180 = figure('Name', 'Polar Histogram (0-180): Rho vs Contour');
                        pax = polaraxes(figPolarHistoRho180);
                        polarhistogram(pax, Rho_c*pi/180, (0:3:180)*pi/180);
                        pax.ThetaDir = 'clockwise';
                        pax.ThetaLim = [0 180];
                        pax.ThetaZeroLocation = 'left';
                        annotation(figPolarHistoRho180, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figPolarHistoRho180, 'textbox', [0.65 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(meanCangle, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        if any(strcmp(SaveList, 'Polar Histogram Rho vs Contour (0-180)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figPolarHistoRho180, [foldername filesep name '_PolarHistoRho_vs_Contour_180.fig']);
                        end
                    end
                end
            end
            
            if any(strcmp(ShowList, 'Histogram Rho vs Contour (0-90)')) || any(strcmp(ShowList, 'Polar Histogram Rho vs Contour (0-90)'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:app.int_roi
                        Rho_ct = MapOfRho_c(isfinite(MapOfRho_c) & ROI==it);
                        size(Rho_ct)
                        Rho_c(Rho_ct<=90) = Rho_ct(Rho_ct<=90);
                        Rho_c(Rho_ct>=90) = 180-Rho_ct(Rho_ct>=90);
                        avgRho = mean(Rho_c);
                        if any(strcmp(ShowList, 'Histogram Rho vs Contour (0-90)'))
                            figHistoRho90 = figure('Name', ['Histogram (0-90): Rho vs Contour for ROI' num2str(it)]);
                            ax1 = axes(figHistoRho90);
                            [NbRho, RhoValues] = histcounts(Rho_c, 0:3:90);
                            b = bar(ax1, RhoValues(1:end-1)+1.5, NbRho, 'hist');
                            b.FaceColor = 'flat';
                            b.CData = linspace(1, 2, length(NbRho));
                            colormap(hsv(182));
                            xlim([0 90]); 
                            xlabel('$\rho - \rho_C $', 'Interpreter', 'latex', 'FontSize', 20);
                            annotation(figHistoRho90, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                            annotation(figHistoRho90, 'textbox', [0.45 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(avgRho, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                            if any(strcmp(SaveList, 'Histogram Rho vs Contour (0-90)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                                savefig(figHistoRho90, [foldername filesep name '_HistoRho_vs_Contour_90_' num2str(it) '.fig']);
                            end
                        end
                        if any(strcmp(ShowList, 'Polar Histogram Rho vs Contour (0-90)'))
                            figPolarHistoRho90 = figure('Name', ['Polar Histogram (0-90): Rho vs Contour for ROI' num2str(it)]);
                            pax = polaraxes(figPolarHistoRho90);
                            polarhistogram(pax, Rho_c*pi/180, (0:3:90)*pi/180);
                            pax.ThetaDir = 'clockwise';
                            pax.ThetaLim = [0 90];
                            pax.ThetaZeroLocation = 'left';
                            annotation(figPolarHistoRho90, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                            annotation(figPolarHistoRho90, 'textbox', [0.45 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(avgRho, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                            if any(strcmp(SaveList, 'Polar Histogram Rho vs Contour (0-90)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                                savefig(figPolarHistoRho90, [foldername filesep name '_PolarHistoRho_vs_Contour_90_' num2str(it) '.fig']);
                            end
                        end
                    end
                else
                    Rho_ct = MapOfRho_c(isfinite(MapOfRho_c) & ROI~=0);
                    Rho_c(Rho_ct<=90) = Rho_ct(Rho_ct<=90);
                    Rho_c(Rho_ct>=90) = 180-Rho_ct(Rho_ct>=90);
                    avgRho = mean(Rho_c);
                    if any(strcmp(ShowList, 'Histogram Rho vs Contour (0-90)'))
                        figHistoRho90 = figure('Name', 'Histogram (0-90): Rho vs Contour');
                        ax1 = axes(figHistoRho90);
                        [NbRho, RhoValues] = histcounts(Rho_c, 0:3:90);
                        b = bar(ax1, RhoValues(1:end-1)+1.5, NbRho, 'hist');
                        b.FaceColor = 'flat';
                        b.CData = linspace(1, 2, length(NbRho));
                        colormap(hsv(182));
                        xlim([0 90]); 
                        xlabel('$\rho - \rho_C $', 'Interpreter', 'latex', 'FontSize', 20);
                        annotation(figHistoRho90, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figHistoRho90, 'textbox', [0.45 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(avgRho, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        if any(strcmp(SaveList, 'Histogram Rho vs Contour (0-90)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figHistoRho90, [foldername filesep name '_HistoRho_vs_Contour_90.fig']);
                        end
                    end
                    if any(strcmp(ShowList, 'Polar Histogram Rho vs Contour (0-90)'))
                        figPolarHistoRho90 = figure('Name', 'Polar Histogram (0-90): Rho vs Contour');
                        pax = polaraxes(figPolarHistoRho90);
                        polarhistogram(pax, Rho_c*pi/180, (0:3:90)*pi/180);
                        pax.ThetaDir = 'clockwise';
                        pax.ThetaLim = [0 90];
                        pax.ThetaZeroLocation = 'left';
                        annotation(figPolarHistoRho90, 'textbox', [0.12 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figPolarHistoRho90, 'textbox', [0.45 0.99 0 0], 'String', ['\rho - \rho_C = ' num2str(avgRho, '%.1f') '\pm' num2str(std(Rho_c), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        if any(strcmp(SaveList, 'Polar Histogram Rho vs Contour (0-90)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figPolarHistoRho90, [foldername filesep name '_PolarHistoRho_vs_Contour_90.fig']);
                        end
                    end
                end
            end
            
            %% Saving data
            [foldername, name, ~] = fileparts(app.DataFileName);
            if any(strcmp(SaveExtensionList, 'data (.mat)')) && strcmp(app.perROISwitch.Value, 'On')
                for it = 1:app.int_roi
                    [X_c, Y_c] = meshgrid(1:ImageWidth, 1:ImageHeight);
                    X_c = X_c(isfinite(MapOfRho_c) & ROI==it);
                    Y_c = Y_c(isfinite(MapOfRho_c) & ROI==it);
                    Rho_c = MapOfRho_c(isfinite(MapOfRho_c) & ROI==it);
                    load(app.DataFileName, 'Psi', 'Rho', 'DeltaRho', 'Int', 'X', 'Y');
                    save([foldername filesep name '_Contour_ROI_' num2str(it) '.mat'], 'Psi', 'Rho', 'DeltaRho', 'Int', 'X', 'Y', 'X_c', 'Y_c', 'Rho_c');
                end
            elseif any(strcmp(SaveExtensionList, 'data (.mat)')) && strcmp(app.perROISwitch.Value, 'Off')
                [X_c, Y_c] = meshgrid(1:ImageWidth, 1:ImageHeight);
                X_c = X_c(isfinite(MapOfRho_c));
                Y_c = Y_c(isfinite(MapOfRho_c));
                Rho_c = MapOfRho_c(isfinite(MapOfRho_c));
                load(app.DataFileName, 'Psi', 'Rho', 'DeltaRho', 'Int', 'X', 'Y');
                save([foldername filesep name '_Contour.mat'], 'Psi', 'Rho', 'DeltaRho', 'Int', 'X', 'Y', 'X_c', 'Y_c', 'Rho_c');
            end
        end
        
        function Initialize(app)
            app.Patch = [];
            app.Boundary = [];
            app.ContrastFluoSlider.Enable = 'on';
            app.ContrastThrshldSlider.Enable = 'on';
            app.int_roi = 0;
            app.ILowEditField.Value = 0;
            app.ILowImaxSlider.Value = 0;
        end
        
        function Represent_Thrshld(app, Itot, boundary, DrawNow)
            Ithrshld = Itot/max(Itot(:));
            if DrawNow
                im = imadjust(imsharpen(Ithrshld), [0 1], [0 1], app.ContrastThrshldSlider.Value);
                app.ImageThrshld = imagesc(app.UIAxesThrshld, im);
                axis(app.UIAxesThrshld, 'image'); 
                colormap(app.UIAxesThrshld, hot);
            end
            app.ImageThrshld.AlphaData = ones(size(Ithrshld));
            app.ImageThrshld.AlphaData(Ithrshld<=app.ILowImaxSlider.Value) = 0.1;
            if app.int_roi~=0 && ~isempty(app.Patch)
                for it = 1:app.int_roi
                    patch(app.UIAxesThrshld, 'XData', app.Patch.XData{it}, 'YData', app.Patch.YData{it},'FaceAlpha', 0, 'EdgeColor', 'w');
                    text(app.UIAxesThrshld, app.Patch.XData{it}(1), app.Patch.YData{it}(1), num2str(it), 'FontSize', 20, 'Color', 'w');
                end
            end
            if ~isempty(boundary)
                W_sticks = 0.2;            
                L_sticks = 10; 
                nbrdiff = 10;
                X_c = boundary.Y(1:nbrdiff:end);
                Y_c = boundary.X(1:nbrdiff:end);
                RhoRad = pi/180*boundary.Angle(1:nbrdiff:end);
                XData = [X_c-L_sticks/2*cos(RhoRad)+W_sticks/2*sin(RhoRad) X_c+L_sticks/2*cos(RhoRad)+W_sticks/2*sin(RhoRad)...
                    X_c+L_sticks/2*cos(RhoRad)-W_sticks/2*sin(RhoRad) X_c-L_sticks/2*cos(RhoRad)-W_sticks/2*sin(RhoRad)].';
                YData = [Y_c-L_sticks/2*sin(RhoRad)-W_sticks/2*cos(RhoRad) Y_c+L_sticks/2*sin(RhoRad)-W_sticks/2*cos(RhoRad)...
                    Y_c+L_sticks/2*sin(RhoRad)+W_sticks/2*cos(RhoRad) Y_c-L_sticks/2*sin(RhoRad)+W_sticks/2*cos(RhoRad)].';
                patch(app.UIAxesThrshld, 'XData', XData, 'YData', YData,...
                    'LineWidth', 2, 'FaceAlpha', 1, 'EdgeColor', 'b');
            end
        end  
        
        function [nangle, Im] = OpenFile(app, filename)
            info = imfinfo(filename);
            nangle = numel(info);
            Im = zeros(info(1).Height, info(1).Width, nangle, 'double');
            TifLink = Tiff(filename, 'r');
            for k = 1:nangle
               TifLink.setDirectory(k);
               Im(:,:,k) = TifLink.read();
            end
            TifLink.close();
            [~, name, ~] = fileparts(filename);
            app.NameOfFile.Visible = 'on';
            app.NameOfFile.Value = name;
        end
        
        function [AngleContour, Normal] = AngleC(~, boundary)
            tangentX = diff([boundary(:,1) boundary(:,2)]);
            tangentX(end+1,:) = tangentX(end,:);
            slopeX = tangentX(:,1)./tangentX(:,2);
            AngleContour = ((atan(slopeX)>=0).*(atan(slopeX))+(atan(slopeX)<=0).*(pi+atan(slopeX)))*180/pi;
            Normal = tangentX*[0 -1;1 0];
        end
        
        function ShowSaveFluo(app, filename)
            [foldername, name, ~] = fileparts(filename);
            fig = figure('Name', 'Fluorescence Image');
            figAxes = axes(fig);
            figAxes.Colormap = app.UIAxesFluo.Colormap;
            figAxes.YDir = app.UIAxesFluo.YDir;
            copyobj(app.UIAxesFluo.XAxis.Parent.Children, figAxes);
            xlim auto, ylim auto, axis image
            if ~app.AddAxesCheckBox.Value
                set(gca,'visible','off');
            end
            title(name, 'Interpreter', 'none');
            set(findall(gca, 'type', 'text'), 'visible', 'on');
            drawnow
            if app.UITableShowSave.Data{8,3}
                savefig(fig, [foldername filesep name '_Fluo.fig']);
            end
            if ~app.UITableShowSave.Data{8,2}
                close(fig);
            end 
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uialert(app.PolarimetryContourUIFigure, {'Welcome to Polarimetry Contour', '   (1)  Download an image file', '   (2)  Download the corresponding Polarimetry data file', '   (3)  Select the method of analysis', '   (4)  Select one or several regions of interest', '   (5)  Click on Analysis'}, 'Polarimetry Contour', 'Icon', 'round_multiline_chart_black_48dp.png');
            app.UITableShowSave.Data = {'Composite Contour', false, false; 'Rho Sticks', false, false; 'Rho Sticks vs Contour', false, false;...
                'Histogram Rho vs Contour (0-180)', false, false; 'Polar Histogram Rho vs Contour (0-180)', false, false;...
                'Histogram Rho vs Contour (0-90)', false, false; 'Polar Histogram Rho vs Contour (0-90)', false, false;...
                'Fluorescence Image', false, false};
            app.UITableSaveExtension.Data = {'figures (.fig)', false; 'data (.mat)', false}; 
        end

        % Button pushed function: PolarimetryContourLogo
        function PolarimetryContourLogoPushed(app, event)
            app.TabGroup.SelectedTab = app.AboutTab;
        end

        % Button pushed function: DownloadFileButton
        function DownloadFileButtonPushed(app, event)
            [file, pathtif] = uigetfile({'*.tiff;*.tif'});
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryContourUIFigure);
            if ~isequal(file, 0)
                Initialize(app);
                app.FileName = [pathtif file];
                [~, app.Im] = OpenFile(app, app.FileName);
                app.I_tot = Define_I_tot(app, app.Im);
                Represent_Fluo(app, app.I_tot, true);
                Represent_Thrshld(app, app.I_tot, app.Boundary, true);
                app.Rho = [];
                app.ROI = zeros(size(app.I_tot));
                app.WidthRuler.Enable = 'on';
                app.ShiftRuler.Enable = 'on';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.Boundary = [];
            Represent_Thrshld(app, app.I_tot, app.Boundary, true);
            if strcmp(app.DropDown.Value, 'Mask')
                app.ILowEditField.Enable = 'off';
                app.ILowImaxSlider.Enable = 'off';
                [filemask, pathmask] = uigetfile('*.png');
                Name_Mask = [pathmask filemask];
                BinarizedIm = imread(Name_Mask);
                bw = double(BinarizedIm/max(BinarizedIm(:)));
                app.Mask = bw;
            elseif strcmp(app.DropDown.Value, 'Edge detection')
                app.ILowEditField.Enable = 'on';
                app.ILowImaxSlider.Enable = 'on';
                uialert(app.PolarimetryContourUIFigure, {'Select the region where the contour has to be computed', 'Use thresholding to improve edge detection if needed'}, 'Polarimetry Contour', 'Icon', 'round_multiline_chart_black_48dp.png');
                app.TabGroup.SelectedTab = app.EdgedetectionROITab;
                roi = drawpolygon(app.UIAxesThrshld);
                mask = createMask(roi);
                delete(roi); pause(1);
                Itot_thrshld = app.I_tot;
                Itot_thrshld(app.I_tot<=app.ILowEditField.Value) = 0;
                if strcmp(app.EdgeDetectionMethodDropDown.Value, 'Canny') || strcmp(app.EdgeDetectionMethodDropDown.Value, 'approxcanny')
                    parameters = [app.LowThresholdEditField.Value app.HighThresholdEditField.Value];
                else
                    parameters = app.LowThresholdEditField.Value;
                end
                bw = edge(double(Itot_thrshld).*double(mask), app.EdgeDetectionMethodDropDown.Value, parameters); 
                app.ILowEditField.Enable = 'off';
                app.ILowImaxSlider.Enable = 'off';
            elseif strcmp(app.DropDown.Value, 'Choose method')
                app.ILowEditField.Enable = 'off';
                app.ILowImaxSlider.Enable = 'off';
                app.AddROIButton.Enable = 'off';
                app.AnalysisButton.Enable = 'off';
                app.Patch = [];
                app.Boundary = [];
                app.int_roi = 0;
                app.ROI = zeros(size(app.I_tot)); 
                app.ILowEditField.Value = 0;
                app.ILowImaxSlider.Value = 0;
                Represent_Fluo(app, app.I_tot, true);
                Represent_Thrshld(app, app.I_tot, app.Boundary, true);
            end
            if strcmp(app.DropDown.Value, 'Mask') || strcmp(app.DropDown.Value, 'Edge detection')
                B = bwboundaries(bw);
                app.TabGroup.SelectedTab = app.EdgedetectionROITab;
                %% smoothing the boundaries using a Savitzky-Golay finite impulse response smoothing filter
                windowWidth = 23;
                polynomialOrder = 3;
                CatSmooth = [];
                CatAngle = [];
                CatNormal = [];
                for k = 1:length(B)
                    if ~isempty(B{k}) && length(B{k}(:,1))>=windowWidth
                        Smooth = sgolayfilt(B{k}, polynomialOrder, windowWidth);
                        [TempAngle, TempNormal] = AngleC(app, Smooth);
                        CatSmooth = [CatSmooth; Smooth];
                        CatAngle = [CatAngle; TempAngle];
                        CatNormal = [CatNormal; TempNormal];
                    end
                end
                app.Boundary.X = CatSmooth(:,1);
                app.Boundary.Y = CatSmooth(:,2);
                app.Boundary.Angle = CatAngle;
                app.Boundary.Normal = CatNormal;
                [height, width] = size(app.I_tot);
                app.Boundary.X(app.Boundary.X<=1) = 1;
                app.Boundary.X(app.Boundary.X>=height) = height;
                app.Boundary.Y(app.Boundary.Y<=1) = 1;
                app.Boundary.Y(app.Boundary.Y>=width) = width;
                Represent_Thrshld(app, app.I_tot, app.Boundary, false);
                app.AddROIButton.Enable = 'on';
                app.AnalysisButton.Enable = 'on';
            end
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            %app.TabGroup.SelectedTab = app.EdgedetectionROITab;
            h_roi = drawpolygon(app.UIAxesThrshld);
            selection = uiconfirm(app.PolarimetryContourUIFigure, 'Confirm ROI?', 'Confirm Close',...
                'Icon', 'round_center_focus_weak_black_48dp.png', 'Options', {'Yes','No'}, 'DefaultOption',1);
            if strcmp(selection, 'Yes')
                BW = createMask(h_roi);
                app.int_roi = app.int_roi+1;
                app.ROI(BW) = app.int_roi;
                app.Patch.XData{app.int_roi} = h_roi.Position(:,1);
                app.Patch.YData{app.int_roi} = h_roi.Position(:,2);
                Represent_Fluo(app, app.I_tot, false);
                Represent_Thrshld(app, app.I_tot, app.Boundary, false);
            end
            delete(h_roi);
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            app.AnalysisButton.Icon = 'round_pause_circle_filled_black_48dp.png';
            pause(1/5);
            if app.int_roi == 0
                app.ROI = ones(size(app.I_tot)); 
            end
            PolarimetryContourAnalysis(app, app.I_tot, app.FileName, app.ROI);
            if app.UITableShowSave.Data{5,2} || app.UITableShowSave.Data{5,3} 
                ShowSaveFluo(app, app.FileName);
            end
            figure(app.PolarimetryContourUIFigure);
            app.Patch = [];
            app.Boundary = [];
            app.int_roi = 0;
            app.ROI = zeros(size(app.I_tot)); 
            app.ILowEditField.Value = 0;
            app.ILowImaxSlider.Value = 0;
            Represent_Fluo(app, app.I_tot, true);
            Represent_Thrshld(app, app.I_tot, app.Boundary, true);
            app.DropDown.Value = 'Choose method'; 
            app.AddROIButton.Enable ='off';
            app.AnalysisButton.Enable = 'off';
            app.AnalysisButton.Icon = 'round_play_circle_filled_black_48dp.png';
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: ContrastFluoSlider
        function ContrastFluoSliderValueChanged(app, event)
            if app.ContrastFluoSlider.Value<=0.05
                app.ContrastFluoSlider.Value = 0.05;
            end
            Represent_Fluo(app, app.I_tot, false);
        end

        % Value changed function: ILowEditField
        function ILowEditFieldValueChanged(app, event)
            if app.ILowEditField.Value>=0.999*max(app.I_tot(:))
                app.ILowEditField.Value = 0.999*max(app.I_tot(:));
            end
            app.ILowImaxSlider.Value = app.ILowEditField.Value/max(app.I_tot(:));
            Represent_Thrshld(app, app.I_tot, app.Boundary, false);
        end

        % Value changed function: ILowImaxSlider
        function ILowImaxSliderValueChanged(app, event)
            if app.ILowImaxSlider.Value>=0.999
                app.ILowImaxSlider.Value = 0.999;
            end
            app.ILowEditField.Value = max(app.I_tot(:))*app.ILowImaxSlider.Value;
            Represent_Thrshld(app, app.I_tot, app.Boundary, false);
        end

        % Value changed function: ContrastThrshldSlider
        function ContrastThrshldSliderValueChanged(app, event)
            if app.ContrastThrshldSlider.Value<=0.005
                app.ContrastThrshldSlider.Value = 0.005;
            end
            Represent_Thrshld(app, app.I_tot, app.Boundary, true);
        end

        % Cell edit callback: UITableShowSave
        function UITableShowSaveCellEdit(app, event)
            if isempty(app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,1))
                app.UITableSaveExtension.Data{1,2} = false;
            else
                app.UITableSaveExtension.Data{1,2} = true;
            end
            app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,2) = {true};
        end

        % Cell edit callback: UITableSaveExtension
        function UITableSaveExtensionCellEdit(app, event)
            if ~app.UITableSaveExtension.Data{1,2}
                app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,3) = {false};
            end
            if app.UITableSaveExtension.Data{1,2} && ~any(cell2mat(app.UITableShowSave.Data(:,3)))
                uialert(app.PolarimetryContourUIFigure, 'Select the type of figures to be saved (Figures table, Save column)', 'Polarimetry Contour', 'Icon', 'round_add_photo_alternate_black_48dp.png');
            end
        end

        % Callback function
        function PolarimetryContourAboutPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
        end

        % Button pushed function: DownloadDataButton
        function DownloadDataButtonPushed(app, event)
            [file, pathmat] = uigetfile('*.mat');
            app.DataFileName = [pathmat file];
            figure(app.PolarimetryContourUIFigure);
            if ~isequal(file, 0)
                load(app.DataFileName, 'X', 'Y', 'Rho');
                app.Rho.X = X;
                app.Rho.Y = Y;
                app.Rho.Values = Rho;
                app.DropDown.Enable = 'on';
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.UITableShowSave.Data(:,2) = {false};
            app.UITableShowSave.Data(:,3) = {false};
            app.UITableSaveExtension.Data{1,2} = false;
        end

        % Button pushed function: DeleteSaveButton
        function DeleteSaveButtonPushed(app, event)
            app.UITableShowSave.Data(:,3) = {false};
            app.UITableSaveExtension.Data{1,2} = false;
        end

        % Value changed function: EdgeDetectionMethodDropDown
        function EdgeDetectionMethodDropDownValueChanged(app, event)
            if ~strcmp(app.EdgeDetectionMethodDropDown.Value, 'Canny') && ~strcmp(app.EdgeDetectionMethodDropDown.Value, 'approxcanny') 
                app.HighThresholdEditField.Enable = 'off';
                app.LowThresholdEditField.RoundFractionalValues = true;
                app.LowThresholdEditField.ValueDisplayFormat = '%.0f';
                app.LowThresholdEditField.Limits = [0 Inf];
                app.LowThresholdEditField.Value = 500;
            else 
                app.HighThresholdEditField.Enable = 'on';
                app.HighThresholdEditField.Value = 0.6;
                app.LowThresholdEditField.RoundFractionalValues = false;
                app.LowThresholdEditField.ValueDisplayFormat = '%0.2f';
                app.LowThresholdEditField.Limits = [0 1];
                app.LowThresholdEditField.Value = 0.1;
            end
        end

        % Button pushed function: WidthRuler
        function WidthRulerButtonPushed(app, event)
            hline = drawline(app.UIAxesFluo);
            Lseg = uint32(norm(hline.Position(2,:)-hline.Position(1,:)));
            selection = uiconfirm(app.PolarimetryContourUIFigure, ['Width = ' num2str(Lseg) ' pixels'], 'Number of pixels', 'Options', {'Set Layer Width', 'Close'}, 'Icon', 'round_height_black_48dp.png');
            if strcmp(selection, 'Set Layer Width')
                app.WidthLayer.Value = double(Lseg);
            end
            delete(hline);
        end

        % Button pushed function: ShiftRuler
        function ShiftRulerButtonPushed(app, event)
            hline = drawline(app.UIAxesFluo);
            Lseg = uint32(norm(hline.Position(2,:)-hline.Position(1,:)));
            selection = uiconfirm(app.PolarimetryContourUIFigure, ['Shift = ' num2str(Lseg) ' pixels'], 'Number of pixels', 'Options', {'Set Shift', 'Close'}, 'Icon', 'round_vertical_align_bottom_black_48dp.png');
            if strcmp(selection, 'Set Shift')
                app.ShiftLayer.Value = double(Lseg);
            end
            delete(hline);
        end

        % Button pushed function: CheckWebsite
        function CheckWebsiteButtonPushed(app, event)
            web('https://sites.google.com/view/cell-morphogenesis-lab/polarimetry', '-browser')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PolarimetryContourUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {572, 572};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {173, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolarimetryContourUIFigure = uifigure('Visible', 'off');
            app.PolarimetryContourUIFigure.Name = 'Polarimetry Contour';
            app.PolarimetryContourUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PolarimetryContourUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DownloadFileButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFileButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFileButtonPushed, true);
            app.DownloadFileButton.Icon = 'round_add_photo_alternate_black_48dp.png';
            app.DownloadFileButton.Text = 'Download file ';
            app.AddROIButton = uibutton(app.LeftPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Icon = 'round_center_focus_weak_black_48dp.png';
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Tooltip = {'select a region of interest which includes its corresponding edge'};
            app.AddROIButton.Text = 'Add ROI';
            app.AnalysisButton = uibutton(app.LeftPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Icon = 'round_play_circle_filled_black_48dp.png';
            app.AnalysisButton.Enable = 'off';
            app.AnalysisButton.Tooltip = {'Polarimetry analysis'};
            app.AnalysisButton.Text = 'Analysis';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Choose method', 'Edge detection', 'Mask'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Tooltip = {'select the method of analysis'};
            app.DropDown.Value = 'Choose method';
            app.PolarimetryContourLogo = uibutton(app.LeftPanel, 'push');
            app.PolarimetryContourLogo.ButtonPushedFcn = createCallbackFcn(app, @PolarimetryContourLogoPushed, true);
            app.PolarimetryContourLogo.Icon = 'round_multiline_chart_black_48dp.png';
            app.PolarimetryContourLogo.Text = {'POLARIMETRY '; 'CONTOUR'};
            app.CloseFiguresButton = uibutton(app.LeftPanel, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Icon = 'round_close_black_48dp.png';
            app.CloseFiguresButton.Tooltip = {''};
            app.CloseFiguresButton.Text = 'Close figures';
            app.DownloadDataButton = uibutton(app.LeftPanel, 'push');
            app.DownloadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDataButtonPushed, true);
            app.DownloadDataButton.Icon = 'round_post_add_black_48dp.png';
            app.DownloadDataButton.Text = 'Download data';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FluorescenceTab = uitab(app.TabGroup);
            app.FluorescenceTab.Title = 'Fluorescence';
            app.UIAxesFluo = uiaxes(app.FluorescenceTab);
            title(app.UIAxesFluo, '')
            xlabel(app.UIAxesFluo, '')
            ylabel(app.UIAxesFluo, '')
            app.UIAxesFluo.XTick = [];
            app.UIAxesFluo.YTick = [];
            app.UIAxesFluo.Visible = 'off';
            app.ContrastFluoSlider = uislider(app.FluorescenceTab);
            app.ContrastFluoSlider.Limits = [0 1];
            app.ContrastFluoSlider.MajorTicks = [0 0.5 1];
            app.ContrastFluoSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastFluoSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastFluoSliderValueChanged, true);
            app.ContrastFluoSlider.Enable = 'off';
            app.ContrastFluoSlider.Tooltip = {'adjust contrast'};
            app.ContrastFluoSlider.Value = 1;
            app.MaximumIntensityEditFieldLabel = uilabel(app.FluorescenceTab);
            app.MaximumIntensityEditFieldLabel.Text = 'Maximum Intensity';
            app.MaximumIntensityEditField = uieditfield(app.FluorescenceTab, 'numeric');
            app.MaximumIntensityEditField.Limits = [0 Inf];
            app.MaximumIntensityEditField.RoundFractionalValues = 'on';
            app.MaximumIntensityEditField.ValueDisplayFormat = '%.0f';
            app.MaximumIntensityEditField.Editable = 'off';
            app.MaximumIntensityEditField.Enable = 'off';
            app.WidthRuler = uibutton(app.FluorescenceTab, 'push');
            app.WidthRuler.ButtonPushedFcn = createCallbackFcn(app, @WidthRulerButtonPushed, true);
            app.WidthRuler.Icon = 'round_height_black_48dp.png';
            app.WidthRuler.Enable = 'off';
            app.WidthRuler.Tooltip = {'Layer width (in pixels)'};
            app.WidthRuler.Text = '';
            app.ContrastFluoButton = uibutton(app.FluorescenceTab, 'push');
            app.ContrastFluoButton.Icon = 'round_brightness_6_black_48dp.png';
            app.ContrastFluoButton.Text = '';
            app.NameOfFile = uieditfield(app.FluorescenceTab, 'text');
            app.NameOfFile.Editable = 'off';
            app.NameOfFile.Visible = 'off';
            app.NameOfFile.Tooltip = {'displays the name of the file currently analyzed'};
            app.ShiftRuler = uibutton(app.FluorescenceTab, 'push');
            app.ShiftRuler.ButtonPushedFcn = createCallbackFcn(app, @ShiftRulerButtonPushed, true);
            app.ShiftRuler.Icon = 'round_vertical_align_bottom_black_48dp.png';
            app.ShiftRuler.Enable = 'off';
            app.ShiftRuler.Tooltip = {'Distance from contour (in pixels)'};
            app.ShiftRuler.Text = '';
            app.EdgedetectionROITab = uitab(app.TabGroup);
            app.EdgedetectionROITab.Title = 'Edge detection / ROI';
            app.UIAxesThrshld = uiaxes(app.EdgedetectionROITab);
            title(app.UIAxesThrshld, '')
            xlabel(app.UIAxesThrshld, '')
            ylabel(app.UIAxesThrshld, '')
            app.UIAxesThrshld.XTick = [];
            app.UIAxesThrshld.YTick = [];
            app.UIAxesThrshld.Visible = 'off';
            app.ILowEditFieldLabel = uilabel(app.EdgedetectionROITab);
            app.ILowEditFieldLabel.Text = 'ILow';
            app.ILowEditField = uieditfield(app.EdgedetectionROITab, 'numeric');
            app.ILowEditField.Limits = [0 Inf];
            app.ILowEditField.RoundFractionalValues = 'on';
            app.ILowEditField.ValueDisplayFormat = '%.0f';
            app.ILowEditField.ValueChangedFcn = createCallbackFcn(app, @ILowEditFieldValueChanged, true);
            app.ILowEditField.Enable = 'off';
            app.ILowImaxLabel = uilabel(app.EdgedetectionROITab);
            app.ILowImaxLabel.Text = 'ILow/Imax';
            app.ILowImaxSlider = uislider(app.EdgedetectionROITab);
            app.ILowImaxSlider.Limits = [0 1];
            app.ILowImaxSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ILowImaxSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ILowImaxSlider.ValueChangedFcn = createCallbackFcn(app, @ILowImaxSliderValueChanged, true);
            app.ILowImaxSlider.Enable = 'off';
            app.ILowImaxSlider.Tooltip = {'used only for optimizing edge detection'};
            app.ContrastThrshldSlider = uislider(app.EdgedetectionROITab);
            app.ContrastThrshldSlider.Limits = [0 1];
            app.ContrastThrshldSlider.MajorTicks = [0 0.5 1];
            app.ContrastThrshldSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastThrshldSliderValueChanged, true);
            app.ContrastThrshldSlider.Enable = 'off';
            app.ContrastThrshldSlider.Tooltip = {'adjust the transparency of the background image'};
            app.ContrastThrshldSlider.Value = 1;
            app.ContrastThrshldButton = uibutton(app.EdgedetectionROITab, 'push');
            app.ContrastThrshldButton.Icon = 'round_brightness_6_black_48dp.png';
            app.ContrastThrshldButton.Text = '';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.UITableShowSave = uitable(app.OptionsTab);
            app.UITableShowSave.ColumnName = {'Figures'; 'Show'; 'Save'};
            app.UITableShowSave.RowName = {};
            app.UITableShowSave.ColumnEditable = [false true true];
            app.UITableShowSave.CellEditCallback = createCallbackFcn(app, @UITableShowSaveCellEdit, true);
            app.UITableSaveExtension = uitable(app.OptionsTab);
            app.UITableSaveExtension.ColumnName = {'Save extension'; ''};
            app.UITableSaveExtension.RowName = {};
            app.UITableSaveExtension.ColumnEditable = [false true];
            app.UITableSaveExtension.CellEditCallback = createCallbackFcn(app, @UITableSaveExtensionCellEdit, true);
            app.perROISwitchLabel = uilabel(app.OptionsTab);
            app.perROISwitchLabel.Text = 'per ROI';
            app.perROISwitch = uiswitch(app.OptionsTab, 'slider');
            app.perROISwitch.Tooltip = {'show and save data/figures separately for each region of interest'};
            app.pixelsperstickLabel = uilabel(app.OptionsTab);
            app.pixelsperstickLabel.Text = 'pixels per stick';
            app.PixelsPerStickSpinner = uispinner(app.OptionsTab);
            app.PixelsPerStickSpinner.Limits = [1 100];
            app.PixelsPerStickSpinner.ValueDisplayFormat = '%.0f';
            app.PixelsPerStickSpinner.Editable = 'off';
            app.PixelsPerStickSpinner.Tooltip = {'controls the density of sticks to be plotted '};
            app.PixelsPerStickSpinner.Value = 1;
            app.AddAxesCheckBox = uicheckbox(app.OptionsTab);
            app.AddAxesCheckBox.Text = 'Add axes on figures';
            app.AddAxesCheckBox.Value = true;
            app.EdgedetectionPanel = uipanel(app.OptionsTab);
            app.EdgedetectionPanel.Title = 'Edge detection';
            app.LowThresholdEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.LowThresholdEditFieldLabel.Text = 'Low Threshold';
            app.LowThresholdEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.LowThresholdEditField.Limits = [0 1];
            app.LowThresholdEditField.ValueDisplayFormat = '%0.2f';
            app.LowThresholdEditField.Value = 0.1;
            app.HighThresholdEditFieldLabel = uilabel(app.EdgedetectionPanel);
            app.HighThresholdEditFieldLabel.Text = 'High Threshold';
            app.HighThresholdEditField = uieditfield(app.EdgedetectionPanel, 'numeric');
            app.HighThresholdEditField.Limits = [0 1];
            app.HighThresholdEditField.ValueDisplayFormat = '%0.2f';
            app.HighThresholdEditField.Value = 0.6;
            app.MethodDropDownLabel = uilabel(app.EdgedetectionPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.EdgeDetectionMethodDropDown = uidropdown(app.EdgedetectionPanel);
            app.EdgeDetectionMethodDropDown.Items = {'Sobel', 'Prewitt', 'Roberts', 'log', 'zerocross', 'Canny', 'approxcanny'};
            app.EdgeDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionMethodDropDownValueChanged, true);
            app.EdgeDetectionMethodDropDown.Value = 'Canny';
            app.LayerwidthinpixelsLabel = uilabel(app.OptionsTab);
            app.LayerwidthinpixelsLabel.Text = 'Layer width  (in pixels)';
            app.WidthLayer = uieditfield(app.OptionsTab, 'numeric');
            app.WidthLayer.Limits = [0 1000];
            app.WidthLayer.RoundFractionalValues = 'on';
            app.WidthLayer.ValueDisplayFormat = '%.0f';
            app.WidthLayer.Tooltip = {'Width of the region to be analyzed'};
            app.WidthLayer.Value = 10;
            app.DeleteAllButton = uibutton(app.OptionsTab, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Icon = 'round_delete_forever_black_48dp.png';
            app.DeleteAllButton.Tooltip = {'Delete the Show and Save lists'};
            app.DeleteAllButton.Text = '';
            app.DeleteSaveButton = uibutton(app.OptionsTab, 'push');
            app.DeleteSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSaveButtonPushed, true);
            app.DeleteSaveButton.Icon = 'round_delete_sweep_black_48dp.png';
            app.DeleteSaveButton.Tooltip = {'Delete the Save list'};
            app.DeleteSaveButton.Text = '';
            app.DistancefromcontourinpixelsLabel = uilabel(app.OptionsTab);
            app.DistancefromcontourinpixelsLabel.Text = 'Distance from contour  (in pixels)';
            app.ShiftLayer = uieditfield(app.OptionsTab, 'numeric');
            app.ShiftLayer.Limits = [0 1000];
            app.ShiftLayer.RoundFractionalValues = 'on';
            app.ShiftLayer.ValueDisplayFormat = '%.0f';
            app.ShiftLayer.Tooltip = {'Distance from contour of the region to be analyzed'};
            app.ShiftRuler_2 = uibutton(app.OptionsTab, 'push');
            app.ShiftRuler_2.Icon = 'round_vertical_align_bottom_black_48dp.png';
            app.ShiftRuler_2.Text = '';
            app.WidthRuler_2 = uibutton(app.OptionsTab, 'push');
            app.WidthRuler_2.Icon = 'round_height_black_48dp.png';
            app.WidthRuler_2.Text = '';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.InfoAbout = uitextarea(app.AboutTab);
            app.InfoAbout.Editable = 'off';
            app.InfoAbout.Value = {'POLARIMETRY CONTOUR'; ''; 'Version: 1.2.2 (July 9, 2020)'; ''; ''; 'To report bugs, send an email to '; '            manos.mavrakis@univ-amu.fr  (Manos Mavrakis, Institut Fresnel, CNRS) '; '            cristel.chandre@univ-amu.fr  (Cristel Chandre, Institut de Mathématiques de Marseille, CNRS)'; ''; ''; 'Copyright (c) 2019 Cristel Chandre.'; 'All rights reserved.'; ''; 'Redistribution and use in source and binary forms are permitted provided that the above copyright notice and this paragraph are duplicated in all such forms and that any documentation, advertising materials, and other materials related to such distribution and use acknowledge that the software was developed by the CNRS. The name of the CNRS may not be used to endorse or promote products derived from this software without specific prior written permission.'; 'THIS SOFTWARE IS PROVIDED "AS IS" AND WITHOUT ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, WITHOUT LIMITATION, THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE.'};
            app.CheckWebsite = uibutton(app.AboutTab, 'push');
            app.CheckWebsite.ButtonPushedFcn = createCallbackFcn(app, @CheckWebsiteButtonPushed, true);
            app.CheckWebsite.Icon = 'round_web_black_48dp.png';
            app.CheckWebsite.Text = 'CHECK WEBSITE FOR UPDATES';
            % Show the figure after all components are created
            app.PolarimetryContourUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarimetryContour

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolarimetryContourUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolarimetryContourUIFigure)
        end
    end
end