classdef PolarimetryPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolarimetryAnalysisUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PolarimetryMethod               matlab.ui.control.DropDown
        CloseFiguresButton              matlab.ui.control.Button
        PolarimetryAnalysisLogo         matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        DownloadFolderButton            matlab.ui.control.Button
        AnalysisButton                  matlab.ui.control.Button
        AddROIButton                    matlab.ui.control.Button
        DownloadFileButton              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        FluorescenceTab                 matlab.ui.container.Tab
        Reload                          matlab.ui.control.Button
        NameOfFile                      matlab.ui.control.EditField
        ContrastFluoButton              matlab.ui.control.Button
        DetAngle                        matlab.ui.control.Button
        MaximumIntensityEditField       matlab.ui.control.NumericEditField
        MaximumIntensityEditFieldLabel  matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        UIAxesFluo                      matlab.ui.control.UIAxes
        ThresholdingMaskTab             matlab.ui.container.Tab
        ContrastThrshldButton           matlab.ui.control.Button
        ContrastThrshldSlider           matlab.ui.control.Slider
        ExportMask                      matlab.ui.control.Button
        NoBackgroundButton              matlab.ui.control.Button
        TransparencyLabel               matlab.ui.control.Label
        TransparencySlider              matlab.ui.control.Slider
        ILowImaxSlider                  matlab.ui.control.Slider
        ILowImaxLabel                   matlab.ui.control.Label
        ILowEditField                   matlab.ui.control.NumericEditField
        ILowEditFieldLabel              matlab.ui.control.Label
        UIAxesThrshld                   matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        PsiMinEditField                 matlab.ui.control.NumericEditField
        PsimindegLabel                  matlab.ui.control.Label
        ColormapThrshld                 matlab.ui.control.DropDown
        ColormapThresholdingDropDownLabel  matlab.ui.control.Label
        DeleteSaveButton                matlab.ui.control.Button
        DeleteAllButton                 matlab.ui.control.Button
        AddAxesCheckBox                 matlab.ui.control.CheckBox
        ShowIndividualFitCheckBox       matlab.ui.control.CheckBox
        PixelsPerStickSpinner           matlab.ui.control.Spinner
        pixelsperstickLabel             matlab.ui.control.Label
        perROISwitch                    matlab.ui.control.Switch
        perROISwitchLabel               matlab.ui.control.Label
        UITableSaveExtension            matlab.ui.control.Table
        UITableShowSave                 matlab.ui.control.Table
        AdvancedTab                     matlab.ui.container.Tab
        DarkPanel                       matlab.ui.container.Panel
        CalculatedDarkValueEditField    matlab.ui.control.NumericEditField
        UserDarkValueEditField          matlab.ui.control.NumericEditField
        UserdarkvalueEditFieldLabel     matlab.ui.control.Label
        CalculatedDarkValueCheckBox     matlab.ui.control.CheckBox
        DiskConePanel                   matlab.ui.container.Panel
        SelectDiskCone                  matlab.ui.control.DropDown
        NameOfDiskCone                  matlab.ui.control.EditField
        DisplayConeButton               matlab.ui.control.Button
        RotationPanel                   matlab.ui.container.Panel
        FigureRotationEditField         matlab.ui.control.NumericEditField
        FigurerotationEditFieldLabel    matlab.ui.control.Label
        StickRotationEditField          matlab.ui.control.NumericEditField
        StickrotationEditFieldLabel     matlab.ui.control.Label
        OffsetAngleDegEditField         matlab.ui.control.NumericEditField
        OffsetangledegEditFieldLabel    matlab.ui.control.Label
        RemovebackgroundPanel           matlab.ui.container.Panel
        InfoClickBackground             matlab.ui.control.TextArea
        NoiseHeightEditField            matlab.ui.control.NumericEditField
        NoiseheightEditFieldLabel       matlab.ui.control.Label
        NoiseWidthEditField             matlab.ui.control.NumericEditField
        NoisewidthEditFieldLabel        matlab.ui.control.Label
        ClickBackgroundButton           matlab.ui.control.Button
        NoiseFactorEditField            matlab.ui.control.NumericEditField
        NoisefactorEditFieldLabel       matlab.ui.control.Label
        BinningPanel                    matlab.ui.container.Panel
        BinHeightEditField              matlab.ui.control.NumericEditField
        BinheightEditFieldLabel         matlab.ui.control.Label
        BinWidthEditField               matlab.ui.control.NumericEditField
        BinwidthEditFieldLabel          matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        CheckWebsite                    matlab.ui.control.Button
        InfoAbout                       matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        I_tot % Mean intensity
        n_angle % number of angles per stack
        ROI % ROI map with ROI index numbers
        int_roi % index of ROIs
        Im % intensities
        P % parameters
        Folder % folder name for batch
        MaskFolder % folder name for masks
        Sum_cor % mean intensity 
        AdvanceFile % automatic or manual advance for batch
        path % path of folder
        fs % file stack names for batch
        Mask % Mask 
        Patch % Patch objects
        SingleFile % Single file or folder
        ROI_ILow % same as ROI; values of ILow instead of the ROI index numbers
        ClickbackgroundChanged % if true, change of Noise_tot
        ClickbackgroundCoord % Coordinates (x1,x2,y1,y2) of the patch to compute Noise_tot
        ImageThrshld % Label for image Thresholding 
        Variables % Structure of the variables to be displayed (rho, psi, etc...) 
    end
    
    methods (Access = private)
        
        function Represent_Fluo(app, Itot, DrawNow)
            if ~DrawNow
                XLimFluo = app.UIAxesFluo.XLim;
                YLimFluo = app.UIAxesFluo.YLim;
            end
            im = imadjust(imsharpen(Itot/max(Itot(:))), [0 1], [0 1], app.ContrastSlider.Value);
            if app.FigureRotationEditField.Value~=0
                im = imrotate(im, -app.FigureRotationEditField.Value, 'bilinear', 'crop');
            end
            imagesc(app.UIAxesFluo, im);
            axis(app.UIAxesFluo, 'image');
            colormap(app.UIAxesFluo, gray); 
            if ~DrawNow 
                app.UIAxesFluo.XLim = XLimFluo;
                app.UIAxesFluo.YLim = YLimFluo;
            end
            [ImageWidth, ImageHeight] = size(Itot);
            if app.int_roi~=0 && ~isempty(app.Patch.XData)
                for it = 1:app.int_roi
                    prot = patch(app.UIAxesFluo, 'XData', app.Patch.XData{it}, 'YData', app.Patch.YData{it},'FaceAlpha', 0, 'EdgeColor', 'w');
                    rotate(prot, [0 0 1], app.FigureRotationEditField.Value, [ImageHeight/2, ImageWidth/2,0]);
                    htext = text(app.UIAxesFluo, app.Patch.XData{it}(1), app.Patch.YData{it}(1), num2str(it), 'FontSize', 20, 'Color','w');
                    rotate(htext, [0 0 1], app.FigureRotationEditField.Value, [ImageHeight/2, ImageWidth/2,0]);
                end
            end
        end
        
        function [Sumcor, Itot] = Define_I_tot(app, Im)
            Dark = ComputeDark(app, Im);
            app.CalculatedDarkValueEditField.Value = Dark;
            if ~app.CalculatedDarkValueCheckBox.Value
                Dark = app.UserDarkValueEditField.Value;
            end
            Im_cor = Im-Dark;     
            Im_cor = Im_cor.*(Im_cor>=0);       
            Sumcor = sum(Im_cor,3);
            BIN = ones(app.BinWidthEditField.Value, app.BinHeightEditField.Value);                                                     
            Itot = convn(Sumcor,BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value; 
            app.MaximumIntensityEditField.Value = max(Itot(:));
        end
        
        function PolarimetryAnalysis(app, Im, P, Mask, ROI)
            %% Parameters
            Chi2Threshold = 500; 
            W_sticks = 0.2;                 % width of sticks
            L_sticks = 6;                   % length of sticks
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [foldername, name, ~] = fileparts(P.filename);
            SaveExtensionList = app.UITableSaveExtension.Data(cell2mat(app.UITableSaveExtension.Data(:,2))==1,1);
            ShowList = app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,2))==1,1);
            SaveList = app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,1);
            %% Data analysis
            [ImageWidth, ImageHeight, ImageAngle] = size(Im);
            Angle_deg = (0:ImageAngle-1)/ImageAngle*180;
            Angle_rad = Angle_deg*pi/180;
            OffsetAngle_rad = app.OffsetAngleDegEditField.Value*pi/180;
            Angle3D = permute(repmat(Angle_rad+OffsetAngle_rad, [ImageWidth 1 ImageHeight]), [1 3 2]);
            C2 = cos(2*Angle3D);
            S2 = sin(2*Angle3D);
            if app.CalculatedDarkValueCheckBox.Value
                Dark = app.CalculatedDarkValueEditField.Value;
            else
                Dark = app.UserDarkValueEditField.Value;
            end
            Im_cor = Im-Dark;
            Im_cor = Im_cor.*(Im_cor>=0);
            TheStack = Im_cor-P.Noise_tot*app.NoiseFactorEditField.Value; 
            I0Map = sum(TheStack, 3);
            A0Map = mean(TheStack, 3);
            if app.BinHeightEditField.Value~=1 || app.BinWidthEditField.Value~=1
                BIN = ones(app.BinWidthEditField.Value, app.BinHeightEditField.Value);
                A0Map = convn(A0Map, BIN);
                A2Map = convn(2*mean(TheStack.*C2, 3), BIN)./A0Map;
                B2Map = convn(2*mean(TheStack.*S2, 3), BIN)./A0Map;
                I0Map = convn(I0Map, BIN);
                Angle3D = permute(repmat(Angle_rad+OffsetAngle_rad, [ImageWidth+app.BinWidthEditField.Value-1 1 ImageHeight+app.BinHeightEditField.Value-1]), [1 3 2]); 
                TheStack = convn(TheStack, BIN);   
                TheFitStack = repmat(A0Map, [1 1 ImageAngle]).*(1+repmat(A2Map, [1 1 ImageAngle]).*cos(2*Angle3D)+...
                    repmat(B2Map, [1 1 ImageAngle]).*sin(2*Angle3D));
                Chi2Map = mean((TheStack-TheFitStack).^2./TheFitStack, 3);
            else   
                A2Map = 2*mean(TheStack.*C2, 3)./A0Map;
                B2Map = 2*mean(TheStack.*S2, 3)./A0Map;
                TheFitStack = repmat(A0Map, [1 1 ImageAngle]).*(1+repmat(A2Map, [1 1 ImageAngle]).*C2...
                        + repmat(B2Map, [1 1 ImageAngle]).*S2);
                Chi2Map = mean((TheStack-TheFitStack).^2./TheFitStack, 3);
            end
            [X_center, Y_center] = find(((ROI>0).*Mask~=0).*(A2Map<1).*(A2Map>-1).*(B2Map<1).*(B2Map>-1).*...
                (Chi2Map<=Chi2Threshold).*(Chi2Map>0));
            Rho = app.P.RoTest(sub2ind(size(app.P.RoTest), 1+round((A2Map(sub2ind(size(A2Map), X_center,Y_center))+1)*(app.P.NbMapValues-1)/2),...
                    1+round((B2Map(sub2ind(size(B2Map),X_center,Y_center))+1)*(app.P.NbMapValues-1)/2)));
            Psi = app.P.PsiTest(sub2ind(size(app.P.RoTest), 1+round((A2Map(sub2ind(size(A2Map), X_center,Y_center))+1)*(app.P.NbMapValues-1)/2),...
                    1+round((B2Map(sub2ind(size(B2Map),X_center,Y_center))+1)*(app.P.NbMapValues-1)/2)));    
            Intensity = A0Map(sub2ind(size(A0Map), X_center, Y_center));
            Filter = find((~isnan(Rho))&(~isnan(Psi)));
            Rho_Filt = Rho(Filter)+app.StickRotationEditField.Value;
            Rho_Filt = wrapTo360(2*(Rho_Filt+90))/2;
            Psi_Filt = Psi(Filter);
            Int_Filt = Intensity(Filter);
            MapOfRho_Filtered = NaN(size(A2Map));
            MapOfPsi_Filtered = NaN(size(A2Map));
            MapOfInt_Filtered = NaN(size(A2Map));
            MapOfX_Filtered = NaN(size(A2Map));
            MapOfY_Filtered = NaN(size(A2Map));
            Chi2Map_Filtered = NaN(size(A2Map));
            MapOfRho_Filtered(sub2ind(size(MapOfRho_Filtered), X_center(Filter), Y_center(Filter))) = Rho_Filt;
            MapOfPsi_Filtered(sub2ind(size(MapOfPsi_Filtered), X_center(Filter), Y_center(Filter))) = Psi_Filt;
            MapOfInt_Filtered(sub2ind(size(MapOfInt_Filtered), X_center(Filter), Y_center(Filter))) = Int_Filt;
            MapOfX_Filtered(sub2ind(size(MapOfX_Filtered), X_center(Filter), Y_center(Filter))) = X_center(Filter);
            MapOfY_Filtered(sub2ind(size(MapOfY_Filtered), X_center(Filter), Y_center(Filter))) = Y_center(Filter);
            Chi2Map_Filtered(sub2ind(size(Chi2Map_Filtered), X_center(Filter), Y_center(Filter))) = Chi2Map(sub2ind(size(Chi2Map_Filtered), X_center(Filter), Y_center(Filter)));
            %% Background fluo for Composite and Stick figures
            imbackground = imadjust(imsharpen(app.I_tot/max(app.I_tot(:))), [0 1], [0 1], app.ContrastSlider.Value);
            if app.FigureRotationEditField.Value~=0
                imbackground = imrotate(imbackground, -app.FigureRotationEditField.Value, 'bilinear', 'crop');
            end
            %% Composite image of Psi 
            if any(strcmp(ShowList, 'Composite Psi'))
                figCompositePsi = figure('Name', 'Composite image of Psi (deg)');
                ax1 = axes(figCompositePsi);
                imagesc(ax1, imbackground); 
                colormap(ax1, gray); 
                c1 = colorbar; axis image; c1.Visible = 'off';
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figCompositePsi);
                im = MapOfPsi_Filtered;
                if app.FigureRotationEditField.Value~=0
                    im = imrotate(im, -app.FigureRotationEditField.Value, 'nearest', 'crop');
                    im(im==0) = NaN;
                end
                h2 = imagesc(ax2, im, [app.PsiMinEditField.Value 180]); 
                colormap(ax2, jet(182)); 
                colorbar; axis image;
                alpha(h2, isfinite(im)*1);
                ax2.Visible = 'off';
                linkaxes([ax1, ax2], 'xy');
                title(name, 'Interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                drawnow
                if any(strcmp(SaveList, 'Composite Psi')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figCompositePsi, [foldername filesep name '_CompositePsi.fig']);
                end
            end
            %% Composite image of Rho
            if any(strcmp(ShowList, 'Composite Rho'))
                figCompositeRho = figure('Name', 'Composite image of Rho (deg)');
                ax1 = axes(figCompositeRho);
                imagesc(ax1, imbackground);
                colormap(ax1, gray); 
                c1 = colorbar; axis image; c1.Visible = 'off';
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figCompositeRho);
                im = MapOfRho_Filtered;
                if app.FigureRotationEditField.Value~=0
                    im = wrapTo360(2*(MapOfRho_Filtered+app.FigureRotationEditField.Value))/2;
                    im = imrotate(im, -app.FigureRotationEditField.Value, 'nearest', 'crop');
                    im(im==0) = NaN;
                end
                h2 = imagesc(ax2, im, [0 180]);
                colormap(ax2, hsv(182)); 
                colorbar; axis image;
                alpha(h2, isfinite(im)*1);
                ax2.Visible = 'off';
                linkaxes([ax1, ax2], 'xy');
                title(name, 'Interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                drawnow
                if any(strcmp(SaveList, 'Composite Rho')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figCompositeRho, [foldername filesep name '_CompositeRho.fig']);
                end
            end
            %% Stick images
            Rho_r = MapOfRho_Filtered(1:app.PixelsPerStickSpinner.Value:end, 1:app.PixelsPerStickSpinner.Value:end).';
            Psi_r = MapOfPsi_Filtered(1:app.PixelsPerStickSpinner.Value:end, 1:app.PixelsPerStickSpinner.Value:end).';
            [Y, X] = meshgrid(1:app.PixelsPerStickSpinner.Value:ImageWidth+app.BinWidthEditField.Value-1, 1:app.PixelsPerStickSpinner.Value:ImageHeight+app.BinHeightEditField.Value-1);
            X = X(isfinite(Rho_r));
            Y = Y(isfinite(Rho_r));
            Psi_r = Psi_r(isfinite(Rho_r));
            Rho_r = Rho_r(isfinite(Rho_r))*pi/180;
            %% Psi sticks image
            if any(strcmp(ShowList, 'Psi Sticks'))
                figPsiSticks = figure('Name', 'Psi Sticks');
                ax1 = axes(figPsiSticks); 
                imagesc(ax1, imbackground); 
                colormap(ax1, gray); 
                c1 = colorbar; axis image; c1.Visible = 'off'; 
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figPsiSticks);
                VertexPsi_r = (Psi_r.*ones(1,4)).';
                prot = patch(ax2, [X-L_sticks/2*cos(Rho_r)+W_sticks/2*sin(Rho_r) X+L_sticks/2*cos(Rho_r)+W_sticks/2*sin(Rho_r)...
                    X+L_sticks/2*cos(Rho_r)-W_sticks/2*sin(Rho_r) X-L_sticks/2*cos(Rho_r)-W_sticks/2*sin(Rho_r)].',...
                    [Y-L_sticks/2*sin(Rho_r)-W_sticks/2*cos(Rho_r) Y+L_sticks/2*sin(Rho_r)-W_sticks/2*cos(Rho_r)...
                    Y+L_sticks/2*sin(Rho_r)+W_sticks/2*cos(Rho_r) Y-L_sticks/2*sin(Rho_r)+W_sticks/2*cos(Rho_r)].', Psi_r.',...
                    'LineWidth', 2, 'EdgeAlpha', 0.2, 'EdgeColor', 'flat', 'FaceVertexCData', VertexPsi_r(:));
                rotate(prot, [0 0 1], app.FigureRotationEditField.Value, [ImageHeight/2, ImageWidth/2,0]);
                colormap(ax2, jet);
                colorbar; 
                set(gca, 'YDir', 'reverse');
                caxis([app.PsiMinEditField.Value 180]);
                axis(ax2, 'image'); 
                ax2.Visible = 'off'; 
                linkaxes([ax1, ax2], 'xy');
                title(name, 'Interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                drawnow
                if any(strcmp(SaveList, 'Psi Sticks')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figPsiSticks, [foldername filesep name '_PsiSticks.fig']);
                end
            end
            %% Rho sticks image
            if any(strcmp(ShowList, 'Rho Sticks'))
                figRhoSticks = figure('Name', 'Rho Sticks');
                ax1 = axes(figRhoSticks); 
                imagesc(ax1, imbackground); 
                colormap(ax1, gray); 
                c1 = colorbar; axis image; c1.Visible = 'off'; 
                if ~app.AddAxesCheckBox.Value
                    set(gca,'visible','off');
                end
                ax2 = axes(figRhoSticks);
                Rho_r_color = wrapTo2Pi(2*(Rho_r+app.FigureRotationEditField.Value*pi/180))/2;
                VertexRho_r = (Rho_r_color.*ones(1,4)).'*180/pi;
                prot = patch(ax2, [X-L_sticks/2*cos(Rho_r)+W_sticks/2*sin(Rho_r) X+L_sticks/2*cos(Rho_r)+W_sticks/2*sin(Rho_r)...
                    X+L_sticks/2*cos(Rho_r)-W_sticks/2*sin(Rho_r) X-L_sticks/2*cos(Rho_r)-W_sticks/2*sin(Rho_r)].',...
                    [Y-L_sticks/2*sin(Rho_r)-W_sticks/2*cos(Rho_r) Y+L_sticks/2*sin(Rho_r)-W_sticks/2*cos(Rho_r)...
                    Y+L_sticks/2*sin(Rho_r)+W_sticks/2*cos(Rho_r) Y-L_sticks/2*sin(Rho_r)+W_sticks/2*cos(Rho_r)].', Rho_r_color.'*180/pi,...
                    'LineWidth', 2, 'EdgeAlpha', 0.2, 'EdgeColor', 'flat', 'FaceVertexCData', VertexRho_r(:));
                rotate(prot, [0 0 1], app.FigureRotationEditField.Value, [ImageHeight/2, ImageWidth/2,0]);
                colormap(ax2, hsv(182));
                colorbar; 
                set(gca, 'YDir', 'reverse');
                caxis([0 180]);
                axis image; 
                ax2.Visible = 'off'; 
                linkaxes([ax1, ax2], 'xy');
                title(name, 'Interpreter', 'none');
                set(findall(gca, 'type', 'text'), 'visible', 'on');
                drawnow
                if any(strcmp(SaveList, 'Rho Sticks')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                    savefig(figRhoSticks, [foldername filesep name '_RhoSticks.fig']);
                end
            end
            %% Psi histogram
            introi = max(ROI(:));
            if any(strcmp(ShowList, 'Histogram Psi'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:introi
                        figHistoPsi_perROI = figure('Name', ['Psi Histogram for ROI ' num2str(it)]);
                        Psi_roi = MapOfPsi_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        [NbPsi, PsiValues] = histcounts(Psi_roi, app.PsiMinEditField.Value:3:180);
                        b = bar(PsiValues(1:end-1)+1.5, NbPsi, 'hist');
                        b.EdgeColor = 'none';
                        b.FaceColor = 'flat';
                        b.CData = linspace(1, 2, length(NbPsi));
                        colormap(jet(182));
                        xlim([app.PsiMinEditField.Value 180]); 
                        xlabel('$\psi$', 'Interpreter', 'latex', 'FontSize', 20);
                        annotation(figHistoPsi_perROI, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'Interpreter', 'none');
                        annotation(figHistoPsi_perROI, 'textbox', [0.2 0.91 0 0], 'String', ['\psi = ' num2str(mean(Psi_roi), '%.1f') '\pm' num2str(std(Psi_roi), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        drawnow
                        if any(strcmp(SaveList, 'Histogram Psi')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figHistoPsi_perROI, [foldername filesep name '_HistoPsi_perROI' '_' num2str(it) '.fig']);
                        end
                    end
                else
                    figHistoPsi = figure('Name', 'Psi Histogram');
                    [NbPsi, PsiValues] = histcounts(Psi_Filt, app.PsiMinEditField.Value:3:180);
                    b = bar(PsiValues(1:end-1)+1.5, NbPsi, 'hist');
                    b.FaceColor = 'flat';
                    b.CData = linspace(1, 2, length(NbPsi));
                    colormap(jet(182));
                    xlim([app.PsiMinEditField.Value 180]); 
                    xlabel('$\psi$', 'Interpreter', 'latex', 'FontSize', 20);
                    annotation(figHistoPsi, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'Interpreter', 'none', 'FitBoxToText', 'on');
                    annotation(figHistoPsi, 'textbox', [0.2 0.91 0 0], 'String', ['\psi = ' num2str(mean(Psi_Filt), '%.1f') '\pm' num2str(std(Psi_Filt), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                    drawnow
                    if any(strcmp(SaveList, 'Histogram Psi')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                        savefig(figHistoPsi, [foldername filesep name '_HistoPsi.fig']);
                    end
                end
            end
            %% Rho-<Rho> histogram
            if any(strcmp(ShowList, 'Histogram Delta Rho'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:introi
                        Rho_roi = MapOfRho_Filtered(ROI==it & isfinite(MapOfRho_Filtered));
                        avgCRho = mean(exp(-2i*Rho_roi*pi/180));
                        meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                        DeltaRho = Rho_roi - meanCangle;
                        DeltaRho = wrapTo180(2*DeltaRho)/2;
                        figHistoDeltaRho_perROI = figure('Name', ['Rho-<Rho> Histogram for ROI ' num2str(it)]);
                        [NbRho, RhoValues] = histcounts(DeltaRho, -90:3:90);
                        b = bar(RhoValues(1:end-1)+1.5, NbRho, 'hist');
                        b.FaceColor = 'flat';
                        b.CData = linspace(1, 2, length(NbRho));
                        colormap(hsv(182));
                        xlim([-90 90]); 
                        xlabel('$\rho-\langle \rho \rangle$', 'Interpreter', 'latex', 'FontSize', 20);
                        annotation(figHistoDeltaRho_perROI, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figHistoDeltaRho_perROI, 'textbox', [0.2 0.91 0 0], 'String', ['\rho - \langle\rho\rangle = ' num2str(mean(DeltaRho), '%.1f') '\pm' num2str(std(DeltaRho), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        drawnow
                        if any(strcmp(SaveList, 'Histogram Delta Rho')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figHistoDeltaRho_perROI, [foldername filesep name '_HistoDeltaRho_perROI' '_' num2str(it) '.fig']);
                        end
                    end
                else
                    avgCRho = mean(exp(-2i*Rho_Filt*pi/180));
                    meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                    DeltaRho = Rho_Filt - meanCangle;
                    DeltaRho = wrapTo180(2*DeltaRho)/2;
                    figHistoDeltaRho = figure('Name', 'Rho-<Rho> Histogram');
                    [NbRho, RhoValues] = histcounts(DeltaRho, -90:3:90);
                    b = bar(RhoValues(1:end-1)+1.5, NbRho, 'hist');
                    b.FaceColor = 'flat';
                    b.CData = linspace(1, 2, length(NbRho));
                    colormap(hsv(182));
                    xlim([-90 90]); 
                    xlabel('$\rho-\langle \rho \rangle$', 'Interpreter', 'latex', 'FontSize', 20);
                    annotation(figHistoDeltaRho, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                    annotation(figHistoDeltaRho, 'textbox', [0.2 0.91 0 0], 'String', ['\rho - \langle\rho\rangle = ' num2str(mean(DeltaRho), '%.1f') '\pm' num2str(std(DeltaRho), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                    drawnow
                    if any(strcmp(SaveList, 'Histogram Delta Rho')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                        savefig(figHistoDeltaRho, [foldername filesep name '_HistoDeltaRho.fig']);
                    end
                end
            end
            %% Rho histogram
            if any(strcmp(ShowList, 'Histogram Rho'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:introi
                        Rho_roi = wrapTo360(2*(MapOfRho_Filtered(ROI==it & isfinite(MapOfRho_Filtered))+app.FigureRotationEditField.Value))/2;
                        avgCRho = mean(exp(-2i*Rho_roi*pi/180));
                        meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                        DeltaRho = Rho_roi - meanCangle;
                        DeltaRho = wrapTo180(2*DeltaRho)/2;
                        figHistoRho_perROI = figure('Name', ['Rho Histogram for ROI ' num2str(it)]);
                        [NbRho, RhoValues] = histcounts(Rho_roi, 0:3:180);
                        b = bar(RhoValues(1:end-1)+1.5, NbRho, 'hist');
                        b.FaceColor = 'flat';
                        b.CData = linspace(1, 2, length(NbRho));
                        colormap(hsv(182));
                        xlim([0 180]); 
                        xlabel('$\rho $', 'Interpreter', 'latex', 'FontSize', 20);
                        annotation(figHistoRho_perROI, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figHistoRho_perROI, 'textbox', [0.2 0.91 0 0], 'String', ['\rho = ' num2str(wrapTo360(2*meanCangle)/2, '%.1f') '\pm' num2str(std(DeltaRho), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        drawnow
                        if any(strcmp(SaveList, 'Histogram Rho')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figHistoRho_perROI, [foldername filesep name '_HistoRho_perROI' '_' num2str(it) '.fig']);
                        end
                    end
                else
                    avgCRho = mean(exp(-2i*wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2*pi/180));
                    meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                    DeltaRho = wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2 - meanCangle;
                    DeltaRho = wrapTo180(2*DeltaRho)/2;
                    figHistoRho = figure('Name', 'Rho Histogram');
                    [NbRho, RhoValues] = histcounts(wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2, 0:3:180);
                    b = bar(RhoValues(1:end-1)+1.5, NbRho, 'hist');
                    b.FaceColor = 'flat';
                    b.CData = linspace(1, 2, length(NbRho));
                    colormap(hsv(182));
                    xlim([0 180]); 
                    xlabel('$\rho  $', 'Interpreter', 'latex', 'FontSize', 20);
                    annotation(figHistoRho, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                    annotation(figHistoRho, 'textbox', [0.2 0.91 0 0], 'String', ['\rho = ' num2str(wrapTo360(2*meanCangle)/2, '%.1f') '\pm' num2str(std(DeltaRho), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                    drawnow
                    if any(strcmp(SaveList, 'Histogram Rho')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                        savefig(figHistoRho, [foldername filesep name '_HistoRho.fig']);
                    end
                end
            end
            %% Rho histogram (polar)
            if any(strcmp(ShowList, 'Histogram Rho (polar)'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:introi
                        Rho_roi = wrapTo360(2*(MapOfRho_Filtered(ROI==it & isfinite(MapOfRho_Filtered))+app.FigureRotationEditField.Value))/2;
                        avgCRho = mean(exp(-2i*Rho_roi*pi/180));
                        meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                        DeltaRho = Rho_roi - meanCangle;
                        DeltaRho = wrapTo180(2*DeltaRho)/2;
                        figPolarHistoRho_perROI = figure('Name', ['Rho Polar Histogram for ROI ' num2str(it)]);
                        pax = polaraxes(figPolarHistoRho_perROI);
                        polarhistogram(pax, wrapTo360(2*(Rho_roi+app.FigureRotationEditField.Value))/2*pi/180, (0:3:180)*pi/180);
                        pax.ThetaDir = 'clockwise';
                        pax.ThetaLim = [0 180];
                        pax.ThetaZeroLocation = 'left';
                        annotation(figPolarHistoRho_perROI, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                        annotation(figPolarHistoRho_perROI, 'textbox', [0.2 0.91 0 0], 'String', ['\rho = ' num2str(wrapTo360(2*meanCangle)/2, '%.1f') '\pm' num2str(std(DeltaRho), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                        drawnow
                        if any(strcmp(SaveList, 'Histogram Rho (polar)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                            savefig(figPolarHistoRho_perROI, [foldername filesep name '_PolarHistoRho_perROI' '_' num2str(it) '.fig']);
                        end
                    end
                else
                    avgCRho = mean(exp(-2i*wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2*pi/180));
                    meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                    DeltaRho = wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2 - meanCangle;
                    DeltaRho = wrapTo180(2*DeltaRho)/2;
                    figPolarHistoRho = figure('Name', 'Rho Polar Histogram');
                    pax = polaraxes(figPolarHistoRho);
                    polarhistogram(pax, wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2*pi/180, (0:3:180)*pi/180);
                    pax.ThetaDir = 'clockwise';
                    pax.ThetaLim = [0 180];
                    pax.ThetaZeroLocation = 'left';
                    annotation(figPolarHistoRho, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on', 'Interpreter', 'none');
                    annotation(figPolarHistoRho, 'textbox', [0.2 0.91 0 0], 'String', ['\rho = ' num2str(wrapTo360(2*meanCangle)/2, '%.1f') '\pm' num2str(std(DeltaRho), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                    drawnow
                    if any(strcmp(SaveList, 'Histogram Rho (polar)')) && any(strcmp(SaveExtensionList, 'figures (.fig)'))
                        savefig(figPolarHistoRho, [foldername filesep name '_PolarHistoRho.fig']);
                    end
                end
            end
            %% Polar response visualization at a pixel
            Show = 'Yes';
            if app.ShowIndividualFitCheckBox.Value
                selectionOK = uiconfirm(app.PolarimetryAnalysisUIFigure, {'(1) Zoom into the region of interest, then press OK.','(2) Click on the pixel where you want to see the polar plots'}, 'Polar response visualization at a pixel', 'Icon', 'Icons/round_timeline_black_48dp.png');
                while strcmp(Show, 'Yes') && strcmp(selectionOK, 'OK')
                    figure(figCompositePsi);
                    [y_image, x_image] = ginput(1);
                    x_center = round(x_image);
                    y_center = round(y_image);
                    TheSignal = permute(TheStack(x_center,y_center,:), [1 3 2]);
                    TheFit = permute(TheFitStack(x_center,y_center,:), [1 3 2]);
                    ThePsi = MapOfPsi_Filtered(x_center,y_center);
                    TheRho = wrapTo360(2*(MapOfRho_Filtered(x_center,y_center)+app.FigureRotationEditField.Value))/2;
                    TheChi2 = Chi2Map_Filtered(x_center,y_center);
                    disp(I0Map(x_center,y_center));
                    disp(A2Map(x_center,y_center));
                    disp(B2Map(x_center,y_center));
                    figure('name', 'Individual fit');
                    clf;
                    subplot(2,1,1);
                    plot(Angle_deg, TheSignal, '*', Angle_deg, TheFit, '-');
                    xlabel('$\alpha$', 'Interpreter', 'latex'); ylabel('counts');
                    title(['\rho = ' num2str(TheRho) ', \psi = ' num2str(ThePsi)]);
                    xlim([0 180]);
                    subplot(2,1,2);
                    plot(Angle_deg, TheSignal-TheFit, '+', Angle_deg, 2*sqrt(TheFit), 'r-', Angle_deg, -2*sqrt(TheFit), 'r-');
                    xlabel('$\alpha$', 'Interpreter', 'latex'); ylabel('Residuals');
                    title(['\rho = ' num2str(TheRho) ', \psi = ' num2str(ThePsi) ', \chi_2 = ' num2str(TheChi2) ' with ' ... 
                        num2str(app.I_tot(x_center,y_center)) ' photons']);
                    xlim([0 180]);
                    Show = uiconfirm(app.PolarimetryAnalysisUIFigure, 'To see another data plot, change the region of interest and press Yes', 'Polar response visualization at a pixel',...
                       'Options', {'Yes','No'}, 'DefaultOption', 2, 'Icon', 'Icons/round_timeline_black_48dp.png');
                end
            end 
            %% Saving to Microsoft Excel
            if any(strcmp(SaveExtensionList, 'mean values (.xlsx)'))
                Tdata = [];
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:introi
                        Psi_roi = MapOfPsi_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        Rho_roi = wrapTo360(2*(MapOfRho_Filtered(ROI==it & isfinite(MapOfPsi_Filtered))+app.FigureRotationEditField.Value))/2;
                        Int_roi = MapOfInt_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        MeanInt = mean(Int_roi);
                        TotalInt = MeanInt*app.n_angle;
                        StdInt = std(Int_roi);
                        MeanPsi = mean(Psi_roi);
                        StdPsi = std(Psi_roi);
                        avgCRho = mean(exp(-2i*Rho_roi*pi/180));
                        meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                        DeltaRho = Rho_roi - meanCangle;
                        DeltaRho = wrapTo180(2*DeltaRho)/2;
                        MeanDeltaRho = mean(DeltaRho);
                        MeanRho = wrapTo360(2*meanCangle)/2;
                        StdRho = std(DeltaRho);
                        N = numel(Psi_roi);
                        Results = [it, MeanPsi, StdPsi, MeanRho, StdRho, MeanDeltaRho, MeanInt, StdInt, TotalInt, N]; 
                        Tdata = [Tdata; Results];
                    end
                else
                    MeanInt = mean(Int_Filt);
                    TotalInt = MeanInt*app.n_angle;
                    StdInt = std(Int_Filt);
                    MeanPsi = mean(Psi_Filt);
                    StdPsi = std(Psi_Filt);
                    avgCRho = mean(exp(-2i*wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2*pi/180));
                    meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                    DeltaRho = wrapTo360(2*(Rho_Filt+app.FigureRotationEditField.Value))/2 - meanCangle;
                    DeltaRho = wrapTo180(2*DeltaRho)/2;
                    MeanDeltaRho = mean(DeltaRho);
                    MeanRho = wrapTo360(2*meanCangle)/2;
                    StdRho = std(DeltaRho);
                    N = numel(Psi_Filt);
                    Results = [1, MeanPsi, StdPsi, MeanRho, StdRho, MeanDeltaRho, MeanInt, StdInt, TotalInt, N];
                    Tdata = [Tdata; Results];
                end
                T = array2table(Tdata,'VariableNames',...
                    {'ROI', 'MeanPsi', 'StdPsi', 'MeanRho', 'StdRho', 'MeanDeltaRho', 'MeanInt', 'StdInt','TotalInt', 'N'});
                T = [repmat({matlab.lang.makeValidName(name)},size(Tdata,1),1) T repmat({matlab.lang.makeValidName(app.NameOfDiskCone.Value)},size(Tdata,1),1)];
                T.Properties.VariableNames{'Var1'} = 'File';
                T.Properties.VariableNames{'Var12'} = 'Disk Cone';
                if app.SingleFile
                    writetable(T, [foldername filesep name '_Stats.xlsx'], 'Sheet', 1, 'WriteMode', 'Append', 'WriteRowNames', true);
                else
                    writetable(T, [foldername filesep 'Folder_Stats.xlsx'], 'Sheet', 1, 'WriteMode', 'Append', 'WriteRowNames', true);
                end
            end
            %% Saving to Matlab data files
            if any(strcmp(SaveExtensionList, 'data (.mat)'))
                if strcmp(app.perROISwitch.Value, 'On')
                    for it = 1:introi
                        Psi = MapOfPsi_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        Rho = wrapTo360(2*(MapOfRho_Filtered(ROI==it & isfinite(MapOfPsi_Filtered))+app.FigureRotationEditField.Value))/2;
                        Int = MapOfInt_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        X = MapOfX_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        Y = MapOfY_Filtered(ROI==it & isfinite(MapOfPsi_Filtered));
                        avgCRho = mean(exp(-2i*Rho*pi/180));
                        meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                        DeltaRho = wrapTo180(2*(Rho-meanCangle))/2;
                        save([foldername filesep name '_ROI' num2str(it) '.mat'], 'Psi', 'Rho', 'DeltaRho', 'Int','X', 'Y');
                    end
                else
                    Psi = MapOfPsi_Filtered(ROI~=0 & isfinite(MapOfPsi_Filtered));
                    Rho = wrapTo360(2*(MapOfRho_Filtered(ROI~=0 & isfinite(MapOfPsi_Filtered))+app.FigureRotationEditField.Value))/2;
                    Int = MapOfInt_Filtered(ROI~=0 & isfinite(MapOfPsi_Filtered));
                    X = MapOfX_Filtered(ROI~=0 & isfinite(MapOfPsi_Filtered));
                    Y = MapOfY_Filtered(ROI~=0 & isfinite(MapOfPsi_Filtered));
                    avgCRho = mean(exp(-2i*Rho*pi/180));
                    meanCangle = wrapTo360(2*(180-angle(avgCRho)*180/pi/2))/2;
                    DeltaRho = wrapTo180(2*(Rho-meanCangle))/2;
                    save([foldername filesep name '.mat'], 'Psi', 'Rho', 'DeltaRho', 'Int','X', 'Y');
                end
            end
        end
        
        function Initialize(app)
            app.Patch.XData = [];
            app.Patch.YData = [];
            % Fluorescence & Thresholding
            app.int_roi = 0;
            app.ContrastSlider.Enable = 'on';
            app.ILowEditField.Enable = 'on';
            app.ILowImaxSlider.Enable = 'on';
            app.TransparencySlider.Enable = 'on';
            app.DetAngle.Enable = 'on';
            app.Reload.Enable = 'on';
            app.NoBackgroundButton.Enable = 'on';
            app.ExportMask.Enable = 'on';
            app.ContrastSlider.Value = 1;
            app.ILowEditField.Value = 0;
            app.ILowImaxSlider.Value = 0;
            app.UIAxesThrshld.CLim(1) = 0;
            % Advanced Options
            app.NoiseWidthEditField.Value = 3;
            app.NoiseHeightEditField.Value = 3;
            app.NoiseFactorEditField.Value = 1;
        end
        
        function Represent_Thrshld(app, Itot, DrawNow, FirstDraw)
            Ithrshld = Itot/max(Itot(:));
            if strcmp(app.DropDown.Value, 'Mask (manual)') || strcmp(app.DropDown.Value, 'Mask (auto)')
                [~, name, ~] = fileparts(app.P.filename);
                Name_Mask = [app.MaskFolder filesep name '.png'];
                if isfile(Name_Mask)
                    BinarizedIm = imread(Name_Mask);
                    MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                end
                Ithrshld = Ithrshld.*MaskTh;
            end
            if DrawNow
                if ~FirstDraw
                    XLimThrshld = app.UIAxesThrshld.XLim;
                    YLimThrshld = app.UIAxesThrshld.YLim;
                end
                colormap(app.UIAxesThrshld, app.ColormapThrshld.Value);
                im = imadjust(imsharpen(Itot/max(Itot(:))), [0 1], [0 1], app.ContrastThrshldSlider.Value);
                app.ImageThrshld = imagesc(app.UIAxesThrshld, im);
                axis(app.UIAxesThrshld, 'image'); 
                if ~FirstDraw
                    app.UIAxesThrshld.XLim = XLimThrshld;
                    app.UIAxesThrshld.YLim = YLimThrshld;
                end
            end
            app.ImageThrshld.AlphaData = ones(size(Ithrshld));
            app.ImageThrshld.AlphaData(Ithrshld<=app.ILowImaxSlider.Value) = app.TransparencySlider.Value*app.ImageThrshld.AlphaData(Ithrshld<=app.ILowImaxSlider.Value);
            if app.int_roi~=0 && ~isempty(app.Patch.XData)
                for it = 1:app.int_roi
                    patch(app.UIAxesThrshld, 'XData', app.Patch.XData{it}, 'YData', app.Patch.YData{it},'FaceAlpha', 0, 'EdgeColor', 'w');
                    text(app.UIAxesThrshld, app.Patch.XData{it}(1), app.Patch.YData{it}(1), num2str(it), 'FontSize', 20, 'Color', 'w');
                end
            end
        end
        
        function ShowSaveFluo(app)
            [foldername, name, ~] = fileparts(app.P.filename);
            fig = figure('Name', 'Fluorescence Image');
            figAxes = axes(fig);
            figAxes.Colormap = app.UIAxesFluo.Colormap;
            figAxes.YDir = app.UIAxesFluo.YDir;
            copyobj(app.UIAxesFluo.XAxis.Parent.Children, figAxes);
            xlim auto, ylim auto, axis image
            if ~app.AddAxesCheckBox.Value
                set(gca,'visible','off');
            end
            title(name, 'Interpreter', 'none');
            set(findall(gca, 'type', 'text'), 'visible', 'on');
            drawnow
            if app.UITableShowSave.Data{9,3}
                savefig(fig, [foldername filesep name '_Fluo.fig']);
            end
            if ~app.UITableShowSave.Data{9,2}
                close(fig);
            end 
        end
        
        function Dark = ComputeDark(~, Im)
            SizeCell = 20;
            [ImageWidth, ImageHeight, ImageAngle] = size(Im);
            NCellHeight = floor(ImageHeight/SizeCell);
            NCellWidth = floor(ImageWidth/SizeCell);
            Ncol = SizeCell*ones(1,NCellHeight);
            Nrow = SizeCell*ones(1,NCellWidth);
            cropIm = Im(1:SizeCell*NCellWidth,1:SizeCell*NCellHeight,1:ImageAngle);
            ImCG = mat2cell(cropIm, Nrow, Ncol, ImageAngle);
            mImCG = zeros(NCellWidth,NCellHeight);
            for it = 1:NCellWidth
                for jt = 1:NCellHeight
                    CellInd = ImCG{it,jt}(:,:,1);
                    mImCG(it,jt) = mean(CellInd(CellInd~=0));
                end
            end
            val = min(mImCG, [], 'all');
            [IndI, IndJ] = find(mImCG == val);
            CellInd = ImCG{IndI,IndJ};
            Dark = mean(CellInd(CellInd~=0));
        end
        
        function [nangle, Im] = OpenFile(app, filename)
            info = imfinfo(filename);
            nangle = numel(info);
            Im = zeros(info(1).Height, info(1).Width, nangle, 'double');
            TifLink = Tiff(filename, 'r');
            for k = 1:nangle
               TifLink.setDirectory(k);
               Im(:,:,k) = TifLink.read();
            end
            TifLink.close();
            [~, name, ~] = fileparts(filename);
            app.NameOfFile.Visible = 'on';
            app.NameOfFile.Value = name;
        end
        
        function Composite(app, data, imbackground, save)
            title = strcat('Composite image of ', data.name, ' (deg)');
            suffix = strcat('_Composite', data.name, '.fig');
            figComposite = figure('Name', title);
            ax1 = axes(figComposite);
            imagesc(ax1, imbackground); 
            colormap(ax1, gray); 
            c1 = colorbar; axis image; c1.Visible = 'off';
            if ~app.AddAxesCheckBox.Value
                set(gca,'visible','off');
            end
            ax2 = axes(figComposite);
            im = data.value;
            if app.FigureRotationEditField.Value~=0
                if data.wrap
                    im = wrapTo360(2*(data.value+app.FigureRotationEditField.Value))/2;
                end
                im = imrotate(im, -app.FigureRotationEditField.Value, 'nearest', 'crop');
                im(im==0) = NaN;
            end
            h2 = imagesc(ax2, im, [data.min 180]); 
            colormap(ax2, data.colormap); 
            colorbar; axis image;
            alpha(h2, isfinite(im)*1);
            ax2.Visible = 'off';
            linkaxes([ax1, ax2], 'xy');
            title(name, 'Interpreter', 'none');
            set(findall(gca, 'type', 'text'), 'visible', 'on');
            drawnow
            if save
                savefig(figCompositePsi, [foldername filesep name suffix]);
            end
        end
        
        function Sticks(app, data, imbackground, save)
            title = strcat(data.name, ' Sticks');
            suffix = strcat('_', data.name, 'Sticks.fig');
            figPsiSticks = figure('Name', title);
            ax1 = axes(figSticks); 
            imagesc(ax1, imbackground); 
            colormap(ax1, gray); 
            c1 = colorbar; axis image; c1.Visible = 'off'; 
            if ~app.AddAxesCheckBox.Value
                set(gca,'visible','off');
            end
            ax2 = axes(figSticks);
            VertexPsi_r = (Psi_r.*ones(1,4)).';
            prot = patch(ax2, [X-L_sticks/2*cos(Rho_r)+W_sticks/2*sin(Rho_r) X+L_sticks/2*cos(Rho_r)+W_sticks/2*sin(Rho_r)...
                X+L_sticks/2*cos(Rho_r)-W_sticks/2*sin(Rho_r) X-L_sticks/2*cos(Rho_r)-W_sticks/2*sin(Rho_r)].',...
                [Y-L_sticks/2*sin(Rho_r)-W_sticks/2*cos(Rho_r) Y+L_sticks/2*sin(Rho_r)-W_sticks/2*cos(Rho_r)...
                Y+L_sticks/2*sin(Rho_r)+W_sticks/2*cos(Rho_r) Y-L_sticks/2*sin(Rho_r)+W_sticks/2*cos(Rho_r)].', Psi_r.',...
                'LineWidth', 2, 'EdgeAlpha', 0.2, 'EdgeColor', 'flat', 'FaceVertexCData', VertexPsi_r(:));
            rotate(prot, [0 0 1], app.FigureRotationEditField.Value, [ImageHeight/2, ImageWidth/2,0]);
            colormap(ax2, data.colormap);
            colorbar; 
            set(gca, 'YDir', 'reverse');
            caxis([data.min 180]);
            axis(ax2, 'image'); 
            ax2.Visible = 'off'; 
            linkaxes([ax1, ax2], 'xy');
            title(name, 'Interpreter', 'none');
            set(findall(gca, 'type', 'text'), 'visible', 'on');
            drawnow
            if save
                savefig(figPsiSticks, [foldername filesep name suffix]);
            end
            
        end
        
        function Histos(app, data, ROI, save)
            if strcmp(app.perROISwitch.Value, 'On')
                for it = 1:max(ROI(:))
                    title = strcat(data.name,' Histogram for ROI ',num2str(it)); 
                    suffix = strcat('_Histo',data.name,'_perROI_',num2str(it),'.fig');
                    figHisto_perROI = figure('Name',title);
                    data_roi = data.value(ROI==it & isfinite(data.value));
                    [Nb, Values] = histcounts(data_roi, data.min:3:180);
                    b = bar(Values(1:end-1)+1.5, Nb, 'hist');
                    b.EdgeColor = 'none';
                    b.FaceColor = 'flat';
                    b.CData = linspace(1, 2, length(Nb));
                    colormap(data.colormap);
                    xlim([data.min 180]); 
                    xlabel(data.latex, 'Interpreter', 'latex', 'FontSize', 20);
                    annotation(figHisto_perROI, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'Interpreter', 'none');
                    annotation(figHisto_perROI, 'textbox', [0.2 0.91 0 0], 'String', [data.latex ' = ' num2str(mean(data_roi), '%.1f') '\pm' num2str(std(data_roi), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                    drawnow
                    if save
                        savefig(figHisto_perROI, [foldername filesep name suffix]);
                    end
                end
            else
                title = strcat(data.name,' Histogram'); 
                suffix = strcat('_Histo',data.name,'.fig');
                figHisto = figure('Name',title);
                [Nb, Values] = histcounts(data.value, data.min:3:180);
                b = bar(Values(1:end-1)+1.5, Nb, 'hist');
                b.FaceColor = 'flat';
                b.CData = linspace(1, 2, length(Nb));
                colormap(data.colorbar);
                xlim([data.min 180]); 
                xlabel(data.latex, 'Interpreter', 'latex', 'FontSize', 20);
                annotation(figHisto, 'textbox', [0.2 0.99 0 0], 'String', name, 'LineStyle', 'none', 'FontSize', 14, 'Interpreter', 'none', 'FitBoxToText', 'on');
                annotation(figHisto, 'textbox', [0.2 0.91 0 0], 'String', ['\psi = ' num2str(mean(data.value), '%.1f') '\pm' num2str(std(data.value), '%.1f')], 'LineStyle', 'none', 'FontSize', 14, 'FitBoxToText', 'on');
                drawnow
                if save
                    savefig(figHisto, [foldername filesep name suffix]);
                end
            end
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uialert(app.PolarimetryAnalysisUIFigure, {'Welcome to Polarimetry Analysis', '   (1)  Select the polarimetry method', '   (2)  Download a file or a folder', '   (3)  Select the method of analysis', '   (4)  If needed, select one or several regions of interest', '   (5)  Click on Analysis'}, 'Polarimetry Analysis', 'Icon', 'Icons/round_blur_circular_black_48dp.png');
            app.UITableShowSave.Data = {'Composite Psi', false, false; 'Composite Rho', false, false; 'Psi Sticks', false, false;...
                'Rho Sticks', false, false; 'Histogram Psi', false, false; 'Histogram Rho', false, false; 'Histogram Delta Rho', false, false; 'Histogram Rho (polar)', false, false; 'Fluorescence Image', false, false};
            app.UITableSaveExtension.Data = {'figures (.fig)', false; 'data (.mat)', false; 'mean values (.xlsx)', false}; 
            app.ClickbackgroundChanged = false;
            app.UIAxesThrshld.BackgroundColor = 'k';
            load('DiskCones/Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0.mat', 'RoTest', 'PsiTest', 'NbMapValues');
            app.NameOfDiskCone.Value = 'Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0';
            app.P.RoTest = double(RoTest);
            app.P.PsiTest = double(PsiTest);
            app.P.NbMapValues = NbMapValues;
        end

        % Button pushed function: PolarimetryAnalysisLogo
        function PolarimetryAnalysisLogoPushed(app, event)
            app.TabGroup.SelectedTab = app.AboutTab;
        end

        % Button pushed function: DownloadFileButton
        function DownloadFileButtonPushed(app, event)
            app.SingleFile = true;
            app.AdvanceFile = false;
            app.DropDown.Value = 'Thresholding (manual)';
            [file, app.path] = uigetfile({'*.tiff;*.tif'});
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryAnalysisUIFigure);
            if ~isequal(file, 0)
                Initialize(app);
                app.P.filename = [app.path file];
                [app.n_angle, app.Im] = OpenFile(app, app.P.filename);
                [app.Sum_cor, app.I_tot] = Define_I_tot(app, app.Im);
                Represent_Fluo(app, app.I_tot, true);
                Represent_Thrshld(app, app.I_tot, true, true);
                app.ROI = zeros(size(app.I_tot));
                app.ROI_ILow = zeros(size(app.I_tot));
                app.Mask = zeros(size(app.I_tot));
                app.DropDown.Enable = 'on';
                app.AddROIButton.Enable = 'on';
                app.AnalysisButton.Enable = 'on';
            end
        end

        % Button pushed function: DownloadFolderButton
        function DownloadFolderButtonPushed(app, event)
            app.SingleFile = false;
            app.DropDown.Value = 'Thresholding (manual)';
            app.Folder = uigetdir;
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            figure(app.PolarimetryAnalysisUIFigure);
            if isequal(app.Folder, 0) || (isempty(dir([app.Folder filesep '*.tif'])) && isempty(dir([app.Folder filesep '*.tiff'])))
                uialert(app.PolarimetryAnalysisUIFigure, 'The folder does not contain .tiff or .tif files', 'Empty folder', 'Icon', 'Icons/round_warning_black_48dp.png');
            else
                app.fs = matlab.io.datastore.DsFileSet(app.Folder, 'FileExtensions', {'.tif','.tiff'});
                app.path = [app.Folder filesep];
                if hasfile(app.fs)
                    Initialize(app);
                    file_n = nextfile(app.fs);
                    app.P.filename = char(file_n.FileName); 
                    [app.n_angle, app.Im] = OpenFile(app, app.P.filename);
                    [app.Sum_cor, app.I_tot] = Define_I_tot(app, app.Im);  
                    Represent_Fluo(app, app.I_tot, true);
                    Represent_Thrshld(app, app.I_tot, true, true);
                    app.ROI = zeros(size(app.I_tot));
                    app.ROI_ILow = zeros(size(app.I_tot));
                    app.Mask = zeros(size(app.I_tot));
                    app.DropDown.Enable = 'on';
                    app.AddROIButton.Enable = 'on';
                    app.AnalysisButton.Enable = 'on';
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Value
                case 'Thresholding (auto)'
                    app.AdvanceFile = true;
                case 'Thresholding (manual)'
                    app.AdvanceFile = false;
                case 'Mask (auto)'
                    app.AdvanceFile = true;
                    app.MaskFolder = uigetdir;
                    app.ILowEditField.Value = 0;
                    app.ILowImaxSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    Represent_Thrshld(app, app.I_tot, false, false);
                case 'Mask (manual)'
                    app.AdvanceFile = false;
                    app.MaskFolder = uigetdir;
                    app.ILowEditField.Value = 0;
                    app.ILowImaxSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    Represent_Thrshld(app, app.I_tot, false, false);
            end
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ThresholdingMaskTab;
            h_roi = drawpolygon(app.UIAxesThrshld);
            selection = uiconfirm(app.PolarimetryAnalysisUIFigure, 'Add ROI?', 'Confirm Close',...
                'Icon', 'Icons/round_center_focus_weak_black_48dp.png', 'Options', {'Yes','No'}, 'DefaultOption',1);
            if strcmp(selection, 'Yes')
                BW = createMask(h_roi);
                app.int_roi = app.int_roi+1;
                app.ROI(BW) = app.int_roi;
                app.ROI_ILow(BW) = app.ILowEditField.Value;
                app.Patch.XData{app.int_roi} = h_roi.Position(:,1);
                app.Patch.YData{app.int_roi} = h_roi.Position(:,2);
                Represent_Fluo(app, app.I_tot, false);
                Represent_Thrshld(app, app.I_tot, false, false);
            end
            delete(h_roi);
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
            app.AnalysisButton.Icon = 'Icons/round_pause_circle_filled_black_48dp.png';
            pause(1/5);
            if app.int_roi == 0
                app.ROI = ones(size(app.I_tot)); 
                app.ROI_ILow = app.ILowEditField.Value*app.ROI;
                app.Mask = zeros(size(app.I_tot)); 
            end
            if app.AdvanceFile && ~isempty(app.fs)
                for it = 1:app.fs.NumFiles 
                    if app.ClickbackgroundChanged
                        app.P.Noise_tot = mean(mean(app.I_tot(app.ClickbackgroundCoord.x1:app.ClickbackgroundCoord.x2,app.ClickbackgroundCoord.y1:app.ClickbackgroundCoord.y2),1),2)/app.n_angle;
                    else
                        app.P.Noise_tot = 0;
                    end
                    MaskTh = ones(size(app.I_tot));
                    if strcmp(app.DropDown.Value, 'Mask (auto)')
                        [~, name, ~] = fileparts(app.P.filename);
                        Name_Mask = [app.MaskFolder filesep name '.png'];
                        if isfile(Name_Mask)
                            BinarizedIm = imread(Name_Mask);
                            MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                        end
                    end
                    app.Mask = (app.I_tot>=app.ROI_ILow).*MaskTh;
                    PolarimetryAnalysis(app, app.Im, app.P, app.Mask, app.ROI);
                    if app.UITableShowSave.Data{9,2} || app.UITableShowSave.Data{9,3} 
                        ShowSaveFluo(app);
                    end
                    if hasfile(app.fs)
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName);
                        [app.n_angle, app.Im] = OpenFile(app, app.P.filename);
                        [app.Sum_cor, app.I_tot] = Define_I_tot(app, app.Im);  
                        Represent_Fluo(app, app.I_tot, true);
                        Represent_Thrshld(app, app.I_tot, true, true);
                        pause(1/5);
                    end
                end
                uialert(app.PolarimetryAnalysisUIFigure, 'End of analysis.', 'End of analysis', 'Icon', 'Icons/round_warning_black_48dp.png');       
                reset(app.fs);
                app.int_roi = 0;
                app.ILowEditField.Value = 0;
                app.ILowImaxSlider.Value = 0;
                app.UIAxesThrshld.CLim(1) = 0;
                app.NoiseWidthEditField.Value = 3;
                app.NoiseHeightEditField.Value = 3;
                app.NoiseFactorEditField.Value = 1;
                file_n = nextfile(app.fs);
                app.P.filename = char(file_n.FileName); 
                [app.n_angle, app.Im] = OpenFile(app, app.P.filename);
                [app.Sum_cor, app.I_tot] = Define_I_tot(app, app.Im);  
                Represent_Fluo(app, app.I_tot, true);
                Represent_Thrshld(app, app.I_tot, true, true);
                app.ROI = zeros(size(app.I_tot));
                app.ROI_ILow = zeros(size(app.I_tot));
                app.Mask = zeros(size(app.I_tot));
            elseif ~app.AdvanceFile 
                if app.ClickbackgroundChanged
                    app.P.Noise_tot = mean(mean(app.I_tot(app.ClickbackgroundCoord.x1:app.ClickbackgroundCoord.x2,app.ClickbackgroundCoord.y1:app.ClickbackgroundCoord.y2),1),2)/app.n_angle;
                else 
                    app.P.Noise_tot = 0;
                end
                app.ClickbackgroundChanged = false;
                MaskTh = ones(size(app.I_tot));
                if strcmp(app.DropDown.Value, 'Mask (manual)')
                    [~, name, ~] = fileparts(app.P.filename);
                    Name_Mask = [app.MaskFolder filesep name '.png'];
                    if isfile(Name_Mask)
                        BinarizedIm = imread(Name_Mask);
                        MaskTh = double(BinarizedIm/max(BinarizedIm(:)));
                    end
                end
                app.Mask = (app.I_tot>=app.ROI_ILow).*MaskTh;
                PolarimetryAnalysis(app, app.Im, app.P, app.Mask, app.ROI);
                if app.UITableShowSave.Data{9,2} || app.UITableShowSave.Data{9,3}
                    ShowSaveFluo(app);
                end
                figure(app.PolarimetryAnalysisUIFigure);
                if ~app.SingleFile 
                    if hasfile(app.fs)
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName);
                        [app.n_angle, app.Im] = OpenFile(app, app.P.filename);
                        [app.Sum_cor, app.I_tot] = Define_I_tot(app, app.Im);
                        app.ILowEditField.Value = 0;
                        app.ILowImaxSlider.Value = 0;
                        app.UIAxesThrshld.CLim(1) = 0;
                        app.NoiseWidthEditField.Value = 3;
                        app.NoiseHeightEditField.Value = 3;
                        app.NoiseFactorEditField.Value = 1;
                    else
                        uialert(app.PolarimetryAnalysisUIFigure, 'End of analysis.', 'End of analysis', 'Icon', 'Icons/round_warning_black_48dp.png');
                        reset(app.fs);
                        app.int_roi = 0;
                        app.ILowEditField.Value = 0;
                        app.ILowImaxSlider.Value = 0;
                        app.UIAxesThrshld.CLim(1) = 0;
                        app.NoiseWidthEditField.Value = 3;
                        app.NoiseHeightEditField.Value = 3;
                        app.NoiseFactorEditField.Value = 1;
                        file_n = nextfile(app.fs);
                        app.P.filename = char(file_n.FileName); 
                        [app.n_angle, app.Im] = OpenFile(app, app.P.filename);
                        [app.Sum_cor, app.I_tot] = Define_I_tot(app, app.Im);  
                        Represent_Fluo(app, app.I_tot, true);
                        Represent_Thrshld(app, app.I_tot, true, true);
                        app.ROI = zeros(size(app.I_tot));
                        app.ROI_ILow = zeros(size(app.I_tot));
                        app.Mask = zeros(size(app.I_tot));
                    end
                end
                app.Patch.XData = [];
                app.Patch.YData = [];
                Represent_Fluo(app, app.I_tot, true);
                Represent_Thrshld(app, app.I_tot, true, true);
                app.int_roi = 0;
                app.ROI = zeros(size(app.I_tot));
                app.ROI_ILow = zeros(size(app.I_tot));
                app.Mask = zeros(size(app.I_tot)); 
                if app.SingleFile
                    app.DropDown.Value = 'Thresholding (manual)'; 
                    app.ILowEditField.Value = 0;
                    app.ILowImaxSlider.Value = 0;
                    app.UIAxesThrshld.CLim(1) = 0;
                    app.NoiseWidthEditField.Value = 3;
                    app.NoiseHeightEditField.Value = 3;
                    app.NoiseFactorEditField.Value = 1;
                    Represent_Fluo(app, app.I_tot, true);
                    Represent_Thrshld(app, app.I_tot, true, true);
                end
            end 
            app.AnalysisButton.Icon = 'Icons/round_play_circle_filled_black_48dp.png';
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            if app.ContrastSlider.Value<=0.001
                app.ContrastSlider.Value = 0.001;
            end
            Represent_Fluo(app, app.I_tot, false);
        end

        % Button pushed function: DetAngle
        function DetAngleButtonPushed(app, event)
            hline = drawline(app.UIAxesFluo);
            slope = atand((hline.Position(2,2)-hline.Position(1,2))/(hline.Position(2,1)-hline.Position(1,1)));
            slope = wrapTo360(2*slope)/2;
            uialert(app.PolarimetryAnalysisUIFigure, ['Angle = ' num2str(slope) ' (deg)'], 'Value of the angle', 'Icon', 'Icons/round_square_foot_black_48dp.png');
            delete(hline);
        end

        % Value changed function: ILowEditField
        function ILowEditFieldValueChanged(app, event)
            if app.ILowEditField.Value>=0.999*max(app.I_tot(:))
                app.ILowEditField.Value = 0.999*max(app.I_tot(:));
            end
            app.ILowImaxSlider.Value = app.ILowEditField.Value/max(app.I_tot(:));
            Represent_Thrshld(app, app.I_tot, false, false);
        end

        % Value changed function: ILowImaxSlider
        function ILowImaxSliderValueChanged(app, event)
            if app.ILowImaxSlider.Value>=0.999
                app.ILowImaxSlider.Value = 0.999;
            end
            app.ILowEditField.Value = max(app.I_tot(:))*app.ILowImaxSlider.Value;
            Represent_Thrshld(app, app.I_tot, false, false);
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            if app.TransparencySlider.Value<=0.001
                app.TransparencySlider.Value = 0.001;
            end
            Represent_Thrshld(app, app.I_tot, false, false);
        end

        % Button pushed function: ExportMask
        function ExportMaskButtonPushed(app, event)
            [filepath, name, ~] = fileparts(app.P.filename);
            selection = uiconfirm(app.PolarimetryAnalysisUIFigure, 'Create Mask by ROI or by Intensity', 'Create Mask',...
                'Options', {'ROI','Intensity','ROI*Intensity','Cancel'}, 'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'Icons/round_open_in_new_black_48dp.png');
            if strcmp(selection, 'ROI')
                A = app.ROI;
                A(app.ROI~=0) = 1;
                imwrite(A, [filepath filesep name '_mask.png']);
            elseif strcmp(selection, 'Intensity')
                A = zeros(size(app.I_tot));
                A(app.I_tot>=app.ILowEditField.Value) = 1;
                imwrite(A, [filepath filesep name '_mask.png']);
            elseif strcmp(selection, 'ROI*Intensity')
                A = zeros(size(app.I_tot));
                A(app.I_tot>=app.ROI_ILow) = 1;
                imwrite(A.*app.ROI, [filepath filesep name '_mask.png']);
            end
        end

        % Button pushed function: NoBackgroundButton
        function NoBackgroundButtonPushed(app, event)
            if app.UIAxesThrshld.BackgroundColor(1) == 0
                app.UIAxesThrshld.BackgroundColor = [0.65 0.65 0.65];
                app.NoBackgroundButton.Icon = "outline_insert_photo_black_48dp.png";
            else
                app.UIAxesThrshld.BackgroundColor = [0 0 0];
                app.NoBackgroundButton.Icon = "Icons/round_image_black_48dp.png";
            end 
            Represent_Thrshld(app, app.I_tot, false, false);
        end

        % Cell edit callback: UITableShowSave
        function UITableShowSaveCellEdit(app, event)
            if isempty(app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,1))
                app.UITableSaveExtension.Data{1,2} = false;
            else
                app.UITableSaveExtension.Data{1,2} = true;
            end
            if ~app.UITableShowSave.Data{1,2}
                app.ShowIndividualFitCheckBox.Value = false;
            end
            app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,2) = {true};
        end

        % Cell edit callback: UITableSaveExtension
        function UITableSaveExtensionCellEdit(app, event)
            if ~app.UITableSaveExtension.Data{1,2}
                app.UITableShowSave.Data(cell2mat(app.UITableShowSave.Data(:,3))==1,3) = {false};
            end
            if app.UITableSaveExtension.Data{1,2} && ~any(cell2mat(app.UITableShowSave.Data(:,3)))
                uialert(app.PolarimetryAnalysisUIFigure, 'Select the type of figures to be saved (Figures table, Save column)', 'Polarimetry Analysis', 'Icon', 'Icons/round_add_photo_alternate_black_48dp.png');
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.UITableShowSave.Data(:,2) = {false};
            app.UITableShowSave.Data(:,3) = {false};
            app.UITableSaveExtension.Data{1,2} = false;
        end

        % Button pushed function: DeleteSaveButton
        function DeleteSaveButtonPushed(app, event)
            app.UITableShowSave.Data(:,3) = {false};
            app.UITableSaveExtension.Data{1,2} = false;
        end

        % Value changed function: ShowIndividualFitCheckBox
        function ShowIndividualFitCheckBoxValueChanged(app, event)
            if app.ShowIndividualFitCheckBox.Value 
                app.UITableShowSave.Data{1,2} = true;
            end
        end

        % Value changed function: CalculatedDarkValueCheckBox
        function CalculatedDarkValueCheckBoxValueChanged(app, event)
            if app.CalculatedDarkValueCheckBox.Value
                app.UserDarkValueEditField.Enable = 'off';
                app.CalculatedDarkValueEditField.Visible = 'on';
            elseif ~app.CalculatedDarkValueCheckBox.Value
                app.UserDarkValueEditField.Enable = 'on';
            end
        end

        % Button pushed function: ClickBackgroundButton
        function ClickBackgroundButtonPushed(app, event)
            app.ClickbackgroundChanged = true;
            if ~isfield(app.P, 'filename')
                    uialert(app.PolarimetryAnalysisUIFigure, 'Download a file or a folder.', 'Polarimetry Analysis', 'Icon', 'Icons/round_add_photo_alternate_black_48dp.png');
            else    
                fig = figure('Name', 'Remove background'); ax = axes(fig);
                imagesc(ax, app.I_tot); colormap(ax, gray.^app.ContrastSlider.Value);
                axis image;
                ax.XLim = app.UIAxesFluo.XLim;
                ax.YLim = app.UIAxesFluo.YLim;
                [x_image, y_image] = ginput(1);
                close(fig);
                if ~isempty(x_image) && ~isempty(y_image)
                    x_noise_center = round(x_image);
                    y_noise_center = round(y_image);
                    app.ClickbackgroundCoord.x1 = x_noise_center-(app.NoiseWidthEditField.Value-1)/2;
                    app.ClickbackgroundCoord.x2 = x_noise_center+(app.NoiseWidthEditField.Value-1)/2;
                    app.ClickbackgroundCoord.y1 = y_noise_center-(app.NoiseHeightEditField.Value-1)/2;
                    app.ClickbackgroundCoord.y2 = y_noise_center+(app.NoiseHeightEditField.Value-1)/2;
                end
            end
        end

        % Value changed function: BinWidthEditField
        function BinWidthEditFieldValueChanged(app, event)
            BIN = ones(app.BinWidthEditField.Value, app.BinHeightEditField.Value);                                                     
            app.I_tot = convn(app.Sum_cor, BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value;
            app.ROI = zeros(size(app.I_tot));
            app.ROI_ILow = zeros(size(app.I_tot));
            app.Mask = zeros(size(app.I_tot));
            app.Patch.XData = [];
            app.Patch.YData = [];
            app.int_roi = 0;
            Represent_Fluo(app, app.I_tot, false);
            Represent_Thrshld(app, app.I_tot, false, false);
        end

        % Value changed function: BinHeightEditField
        function BinHeightEditFieldValueChanged(app, event)
            BIN = ones(app.BinWidthEditField.Value, app.BinHeightEditField.Value);                                                     
            app.I_tot = convn(app.Sum_cor, BIN)/app.BinWidthEditField.Value/app.BinHeightEditField.Value;
            app.ROI = zeros(size(app.I_tot));
            app.ROI_ILow = zeros(size(app.I_tot));
            app.Mask = zeros(size(app.I_tot));
            app.Patch.XData = [];
            app.Patch.YData = [];
            app.int_roi = 0;
            Represent_Fluo(app, app.I_tot, false);
            Represent_Thrshld(app, app.I_tot, false, false);
        end

        % Button pushed function: DisplayConeButton
        function DisplayConeButtonPushed(app, event)
            figure('Name', ['Disk Cone : ' app.NameOfDiskCone.Value]);
            subplot(1,2,1);
            imshow(app.P.RoTest, jet); axis image; title('Rho Test');
            set(gca, 'visible', 'on');
            subplot(1,2,2)
            imshow(app.P.PsiTest, jet); axis image; title('Psi Test');   
            set(gca, 'visible', 'on');
        end

        % Value changed function: FigureRotationEditField
        function FigureRotationEditFieldValueChanged(app, event)
            Represent_Fluo(app, app.I_tot, false);
        end

        % Callback function
        function PolarimetryAnalysisAboutPushed(app, event)
            app.TabGroup.SelectedTab = app.FluorescenceTab;
        end

        % Value changed function: ColormapThrshld
        function ColormapThrshldValueChanged(app, event)
            if ~isempty(app.I_tot)
                Represent_Thrshld(app, app.I_tot, true, false);
            end
        end

        % Value changed function: ContrastThrshldSlider
        function ContrastThrshldSliderValueChanged(app, event)
            if app.ContrastThrshldSlider.Value<=0.001
                app.ContrastThrshldSlider.Value = 0.001;
            end
            Represent_Thrshld(app, app.I_tot, true, false);
        end

        % Value changed function: SelectDiskCone
        function SelectDiskConeValueChanged(app, event)
            filepath = '';
            if strcmp(app.SelectDiskCone.Value, '1')
                name = 'Disk_Ga0_Pa20_Ta45_Gb-0.1_Pb0_Tb0_Gc-0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '2')
                name = 'Disk_Ga-0.2_Pa0_Ta0_Gb0.1_Pb0_Tb0_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '3')
                name = 'Disk_Ga-0.2_Pa0_Ta45_Gb0.1_Pb0_Tb45_Gc-0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '4')
                name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb20_Tb45_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '5')
                name = 'Disk_Ga-0.2_Pa0_Ta0_Gb0.2_Pb20_Tb0_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '6')
                name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb10_Tb45_Gc-0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '7')
                name = 'Disk_Ga-0.1_Pa20_Ta0_Gb-0.1_Pb10_Tb45_Gc0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '8')
                name = 'Disk_Ga0.1_Pa0_Ta45_Gb-0.1_Pb20_Tb0_Gc-0.1_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '9')
                name = 'Disk_Ga-0.1_Pa10_Ta0_Gb0.1_Pb30_Tb0_Gc0.2_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '10')
                name = 'Disk_Ga0_Pa0_Ta0_Gb0_Pb0_Tb0_Gc0_Pc0_Tc0';
            elseif strcmp(app.SelectDiskCone.Value, '11')
                [file, filepath] = uigetfile('Disk*.mat');
                [~, name, ~] = fileparts([filepath file]);
            end
            load([filepath name '.mat'], 'RoTest', 'PsiTest', 'NbMapValues');
            app.NameOfDiskCone.Value = name;
            app.P.RoTest = double(RoTest);
            app.P.PsiTest = double(PsiTest);
            app.P.NbMapValues = NbMapValues;
        end

        % Button pushed function: CheckWebsite
        function CheckWebsiteButtonPushed(app, event)
            web('https://www.fresnel.fr/polarimetry', '-browser')
        end

        % Button pushed function: Reload
        function ReloadButtonPushed(app, event)
            app.int_roi = 0;
            app.Patch.XData = [];
            app.Patch.YData = [];
            Represent_Fluo(app, app.I_tot, true);
            Represent_Thrshld(app, app.I_tot, true, true);
            app.ROI = zeros(size(app.I_tot));
            app.ROI_ILow = zeros(size(app.I_tot));
            app.Mask = zeros(size(app.I_tot));
        end

        % Value changed function: PolarimetryMethod
        function PolarimetryMethodValueChanged(app, event)
            if strcmp(app.PolarimetryMethod.Value, '1PF')
                app.Variables(1).name = 'Rho';
                app.Variables(1).latex = '$\rho$';
                app.Variables(1).min = 0;
                app.Variables(1).wrap = true;
                app.Variables(1).colormap = jet(182);
                app.Variables(2).name = 'Psi';
                app.Variables(2).latex = '$\psi$';
                app.Variables(2).min = app.PsiMinEditField.Value;
                app.Variables(2).wrap = false;
                app.Variables(2).colormap = hsv(182);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PolarimetryAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {572, 572};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {173, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PolarimetryAnalysisUIFigure = uifigure('Visible', 'off');
            app.PolarimetryAnalysisUIFigure.Name = 'Polarimetry Analysis';
            app.PolarimetryAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PolarimetryAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DownloadFileButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFileButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFileButtonPushed, true);
            app.DownloadFileButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_add_photo_alternate_black_48dp.png');
            app.DownloadFileButton.Text = 'Download file ';
            app.AddROIButton = uibutton(app.LeftPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_center_focus_weak_black_48dp.png');
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Tooltip = {'add a region of interest'};
            app.AddROIButton.Text = 'Add ROI';
            app.AnalysisButton = uibutton(app.LeftPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_play_circle_filled_black_48dp.png');
            app.AnalysisButton.Enable = 'off';
            app.AnalysisButton.Tooltip = {'Polarimetry analysis'};
            app.AnalysisButton.Text = 'Analysis';
            app.DownloadFolderButton = uibutton(app.LeftPanel, 'push');
            app.DownloadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFolderButtonPushed, true);
            app.DownloadFolderButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_create_new_folder_black_48dp.png');
            app.DownloadFolderButton.Text = 'Download folder';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Thresholding (manual)', 'Thresholding (auto)', 'Mask (manual)', 'Mask (auto)'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Tooltip = {'select the method of analysis: intensity thresholding or segmentation mask, for single file analysis ("manual") or batch processing ("auto"). The mask has to be binary and in a png format and have the same file name as the respective polarimetry data file.'};
            app.DropDown.Value = 'Thresholding (manual)';
            app.PolarimetryAnalysisLogo = uibutton(app.LeftPanel, 'push');
            app.PolarimetryAnalysisLogo.ButtonPushedFcn = createCallbackFcn(app, @PolarimetryAnalysisLogoPushed, true);
            app.PolarimetryAnalysisLogo.Icon = fullfile(pathToMLAPP, 'Icons', 'round_blur_circular_black_48dp.png');
            app.PolarimetryAnalysisLogo.Text = {'POLARIMETRY '; 'ANALYSIS'};
            app.CloseFiguresButton = uibutton(app.LeftPanel, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_close_black_48dp.png');
            app.CloseFiguresButton.Tooltip = {''};
            app.CloseFiguresButton.Text = 'Close figures';
            app.PolarimetryMethod = uidropdown(app.LeftPanel);
            app.PolarimetryMethod.Items = {'1PF', 'CARS', 'SRS', 'SHG', '2PF'};
            app.PolarimetryMethod.ValueChangedFcn = createCallbackFcn(app, @PolarimetryMethodValueChanged, true);
            app.PolarimetryMethod.Value = '1PF';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.FluorescenceTab = uitab(app.TabGroup);
            app.FluorescenceTab.Title = 'Fluorescence';
            app.UIAxesFluo = uiaxes(app.FluorescenceTab);
            app.UIAxesFluo.XTick = [];
            app.UIAxesFluo.YTick = [];
            app.UIAxesFluo.Visible = 'off';
            app.ContrastSlider = uislider(app.FluorescenceTab);
            app.ContrastSlider.Limits = [0 1];
            app.ContrastSlider.MajorTicks = [0 0.5 1];
            app.ContrastSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Enable = 'off';
            app.ContrastSlider.Tooltip = {''};
            app.ContrastSlider.Value = 1;
            app.MaximumIntensityEditFieldLabel = uilabel(app.FluorescenceTab);
            app.MaximumIntensityEditFieldLabel.Text = 'Maximum Intensity';
            app.MaximumIntensityEditField = uieditfield(app.FluorescenceTab, 'numeric');
            app.MaximumIntensityEditField.Limits = [0 Inf];
            app.MaximumIntensityEditField.RoundFractionalValues = 'on';
            app.MaximumIntensityEditField.ValueDisplayFormat = '%.0f';
            app.MaximumIntensityEditField.Editable = 'off';
            app.MaximumIntensityEditField.Enable = 'off';
            app.DetAngle = uibutton(app.FluorescenceTab, 'push');
            app.DetAngle.ButtonPushedFcn = createCallbackFcn(app, @DetAngleButtonPushed, true);
            app.DetAngle.Icon = fullfile(pathToMLAPP, 'Icons', 'round_square_foot_black_48dp.png');
            app.DetAngle.Enable = 'off';
            app.DetAngle.Tooltip = {'left click and hold to trace a line segment and determine its angle'; ''};
            app.DetAngle.Text = '';
            app.ContrastFluoButton = uibutton(app.FluorescenceTab, 'push');
            app.ContrastFluoButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_brightness_6_black_48dp.png');
            app.ContrastFluoButton.Tooltip = {'the chosen contrast will be the one used for the fluorescence images in figures'};
            app.ContrastFluoButton.Text = '';
            app.NameOfFile = uieditfield(app.FluorescenceTab, 'text');
            app.NameOfFile.Editable = 'off';
            app.NameOfFile.Visible = 'off';
            app.NameOfFile.Tooltip = {''};
            app.Reload = uibutton(app.FluorescenceTab, 'push');
            app.Reload.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.Reload.Icon = fullfile(pathToMLAPP, 'Icons', 'round_autorenew_black_48dp.png');
            app.Reload.Enable = 'off';
            app.Reload.Tooltip = {'erase ROIs and reload image'; ''};
            app.Reload.Text = '';
            app.ThresholdingMaskTab = uitab(app.TabGroup);
            app.ThresholdingMaskTab.Title = 'Thresholding/Mask';
            app.UIAxesThrshld = uiaxes(app.ThresholdingMaskTab);
            app.UIAxesThrshld.XTick = [];
            app.UIAxesThrshld.YTick = [];
            app.UIAxesThrshld.Visible = 'off';
            app.ILowEditFieldLabel = uilabel(app.ThresholdingMaskTab);
            app.ILowEditFieldLabel.Text = 'ILow';
            app.ILowEditField = uieditfield(app.ThresholdingMaskTab, 'numeric');
            app.ILowEditField.Limits = [0 Inf];
            app.ILowEditField.ValueDisplayFormat = '%.0f';
            app.ILowEditField.ValueChangedFcn = createCallbackFcn(app, @ILowEditFieldValueChanged, true);
            app.ILowEditField.Enable = 'off';
            app.ILowImaxLabel = uilabel(app.ThresholdingMaskTab);
            app.ILowImaxLabel.Text = 'ILow/Imax';
            app.ILowImaxSlider = uislider(app.ThresholdingMaskTab);
            app.ILowImaxSlider.Limits = [0 1];
            app.ILowImaxSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ILowImaxSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ILowImaxSlider.ValueChangedFcn = createCallbackFcn(app, @ILowImaxSliderValueChanged, true);
            app.ILowImaxSlider.Enable = 'off';
            app.TransparencySlider = uislider(app.ThresholdingMaskTab);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.MajorTicks = [0 0.5 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.Enable = 'off';
            app.TransparencySlider.Tooltip = {'adjust the transparency of the background image'};
            app.TransparencyLabel = uilabel(app.ThresholdingMaskTab);
            app.TransparencyLabel.Text = 'Transparency';
            app.NoBackgroundButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.NoBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @NoBackgroundButtonPushed, true);
            app.NoBackgroundButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_image_black_48dp.png');
            app.NoBackgroundButton.Enable = 'off';
            app.NoBackgroundButton.Tooltip = {'change background to enhance visibility'};
            app.NoBackgroundButton.Text = '';
            app.ExportMask = uibutton(app.ThresholdingMaskTab, 'push');
            app.ExportMask.ButtonPushedFcn = createCallbackFcn(app, @ExportMaskButtonPushed, true);
            app.ExportMask.Icon = fullfile(pathToMLAPP, 'Icons', 'round_open_in_new_black_48dp.png');
            app.ExportMask.Enable = 'off';
            app.ExportMask.Tooltip = {'Export mask as .png '};
            app.ExportMask.Text = '';
            app.ContrastThrshldSlider = uislider(app.ThresholdingMaskTab);
            app.ContrastThrshldSlider.Limits = [0 1];
            app.ContrastThrshldSlider.MajorTicks = [0 0.5 1];
            app.ContrastThrshldSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.ContrastThrshldSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastThrshldSliderValueChanged, true);
            app.ContrastThrshldSlider.Tooltip = {''};
            app.ContrastThrshldSlider.Value = 1;
            app.ContrastThrshldButton = uibutton(app.ThresholdingMaskTab, 'push');
            app.ContrastThrshldButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_brightness_6_black_48dp.png');
            app.ContrastThrshldButton.Tooltip = {''};
            app.ContrastThrshldButton.Text = '';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.UITableShowSave = uitable(app.OptionsTab);
            app.UITableShowSave.ColumnName = {'Figures'; 'Show'; 'Save'};
            app.UITableShowSave.RowName = {};
            app.UITableShowSave.ColumnEditable = [false true true];
            app.UITableShowSave.CellEditCallback = createCallbackFcn(app, @UITableShowSaveCellEdit, true);
            app.UITableSaveExtension = uitable(app.OptionsTab);
            app.UITableSaveExtension.ColumnName = {'Save extension'; ''};
            app.UITableSaveExtension.RowName = {};
            app.UITableSaveExtension.ColumnEditable = [false true];
            app.UITableSaveExtension.CellEditCallback = createCallbackFcn(app, @UITableSaveExtensionCellEdit, true);
            app.perROISwitchLabel = uilabel(app.OptionsTab);
            app.perROISwitchLabel.Text = 'per ROI';
            app.perROISwitch = uiswitch(app.OptionsTab, 'slider');
            app.perROISwitch.Tooltip = {'show and save data/figures separately for each region of interest'};
            app.pixelsperstickLabel = uilabel(app.OptionsTab);
            app.pixelsperstickLabel.Text = '^2 pixels per stick';
            app.PixelsPerStickSpinner = uispinner(app.OptionsTab);
            app.PixelsPerStickSpinner.Limits = [1 100];
            app.PixelsPerStickSpinner.ValueDisplayFormat = '%.0f';
            app.PixelsPerStickSpinner.Editable = 'off';
            app.PixelsPerStickSpinner.Tooltip = {'controls the density of sticks to be plotted '};
            app.PixelsPerStickSpinner.Value = 1;
            app.ShowIndividualFitCheckBox = uicheckbox(app.OptionsTab);
            app.ShowIndividualFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowIndividualFitCheckBoxValueChanged, true);
            app.ShowIndividualFitCheckBox.Text = 'Show individual fit (from Composite Psi)';
            app.AddAxesCheckBox = uicheckbox(app.OptionsTab);
            app.AddAxesCheckBox.Text = 'Add axes on figures';
            app.AddAxesCheckBox.Value = true;
            app.DeleteAllButton = uibutton(app.OptionsTab, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_delete_forever_black_48dp.png');
            app.DeleteAllButton.Tooltip = {'Delete the Show and Save lists'};
            app.DeleteAllButton.Text = '';
            app.DeleteSaveButton = uibutton(app.OptionsTab, 'push');
            app.DeleteSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSaveButtonPushed, true);
            app.DeleteSaveButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_delete_sweep_black_48dp.png');
            app.DeleteSaveButton.Tooltip = {'Delete the Save list'};
            app.DeleteSaveButton.Text = '';
            app.ColormapThresholdingDropDownLabel = uilabel(app.OptionsTab);
            app.ColormapThresholdingDropDownLabel.Text = 'Colormap (Thresholding)';
            app.ColormapThrshld = uidropdown(app.OptionsTab);
            app.ColormapThrshld.Items = {'hot', 'gray'};
            app.ColormapThrshld.ValueChangedFcn = createCallbackFcn(app, @ColormapThrshldValueChanged, true);
            app.ColormapThrshld.Value = 'hot';
            app.PsimindegLabel = uilabel(app.OptionsTab);
            app.PsimindegLabel.Tooltip = {'Minimum value of Psi used for Psi colobars and Psi histograms'};
            app.PsimindegLabel.Text = 'Psi min (deg)';
            app.PsiMinEditField = uieditfield(app.OptionsTab, 'numeric');
            app.PsiMinEditField.Limits = [0 180];
            app.PsiMinEditField.ValueDisplayFormat = '%.0f';
            app.PsiMinEditField.Tooltip = {'Minimum value of Psi used for Psi colobars and Psi histograms'};
            app.PsiMinEditField.Value = 40;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.BinningPanel = uipanel(app.AdvancedTab);
            app.BinningPanel.Title = 'Binning';
            app.BinwidthEditFieldLabel = uilabel(app.BinningPanel);
            app.BinwidthEditFieldLabel.Text = 'Bin width';
            app.BinWidthEditField = uieditfield(app.BinningPanel, 'numeric');
            app.BinWidthEditField.Limits = [1 Inf];
            app.BinWidthEditField.ValueDisplayFormat = '%.0f';
            app.BinWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BinWidthEditFieldValueChanged, true);
            app.BinWidthEditField.Value = 1;
            app.BinheightEditFieldLabel = uilabel(app.BinningPanel);
            app.BinheightEditFieldLabel.Text = 'Bin height';
            app.BinHeightEditField = uieditfield(app.BinningPanel, 'numeric');
            app.BinHeightEditField.Limits = [1 Inf];
            app.BinHeightEditField.ValueDisplayFormat = '%.0f';
            app.BinHeightEditField.ValueChangedFcn = createCallbackFcn(app, @BinHeightEditFieldValueChanged, true);
            app.BinHeightEditField.Value = 1;
            app.RemovebackgroundPanel = uipanel(app.AdvancedTab);
            app.RemovebackgroundPanel.Title = 'Remove background';
            app.NoisefactorEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoisefactorEditFieldLabel.Text = 'Noise factor';
            app.NoiseFactorEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseFactorEditField.Limits = [0 1];
            app.NoiseFactorEditField.Value = 1;
            app.ClickBackgroundButton = uibutton(app.RemovebackgroundPanel, 'push');
            app.ClickBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @ClickBackgroundButtonPushed, true);
            app.ClickBackgroundButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_exposure_black_48dp.png');
            app.ClickBackgroundButton.Text = 'Click background';
            app.NoisewidthEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoisewidthEditFieldLabel.Text = 'Noise width';
            app.NoiseWidthEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseWidthEditField.Limits = [1 Inf];
            app.NoiseWidthEditField.ValueDisplayFormat = '%.0f';
            app.NoiseWidthEditField.Value = 3;
            app.NoiseheightEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.NoiseheightEditFieldLabel.Text = 'Noise height';
            app.NoiseHeightEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.NoiseHeightEditField.Limits = [1 Inf];
            app.NoiseHeightEditField.ValueDisplayFormat = '%.0f';
            app.NoiseHeightEditField.Value = 3;
            app.InfoClickBackground = uitextarea(app.RemovebackgroundPanel);
            app.InfoClickBackground.Editable = 'off';
            app.InfoClickBackground.Enable = 'off';
            app.InfoClickBackground.Value = {'(zoom using the fluorescence tab)'};
            app.OffsetangledegEditFieldLabel = uilabel(app.AdvancedTab);
            app.OffsetangledegEditFieldLabel.Text = 'Offset angle (deg)';
            app.OffsetAngleDegEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.OffsetAngleDegEditField.Limits = [0 180];
            app.OffsetAngleDegEditField.ValueDisplayFormat = '%.0f';
            app.OffsetAngleDegEditField.Editable = 'off';
            app.OffsetAngleDegEditField.Value = 100;
            app.RotationPanel = uipanel(app.AdvancedTab);
            app.RotationPanel.Title = 'Rotation';
            app.StickrotationEditFieldLabel = uilabel(app.RotationPanel);
            app.StickrotationEditFieldLabel.Text = 'Stick rotation';
            app.StickRotationEditField = uieditfield(app.RotationPanel, 'numeric');
            app.StickRotationEditField.Limits = [0 180];
            app.StickRotationEditField.ValueDisplayFormat = '%.0f';
            app.FigurerotationEditFieldLabel = uilabel(app.RotationPanel);
            app.FigurerotationEditFieldLabel.Text = 'Figure rotation';
            app.FigureRotationEditField = uieditfield(app.RotationPanel, 'numeric');
            app.FigureRotationEditField.Limits = [-180 180];
            app.FigureRotationEditField.ValueDisplayFormat = '%.0f';
            app.FigureRotationEditField.ValueChangedFcn = createCallbackFcn(app, @FigureRotationEditFieldValueChanged, true);
            app.FigureRotationEditField.Tooltip = {'positive value for clockwise rotation / negative value for counter-clockwise rotation'};
            app.DiskConePanel = uipanel(app.AdvancedTab);
            app.DiskConePanel.Title = 'Disk Cone';
            app.DisplayConeButton = uibutton(app.DiskConePanel, 'push');
            app.DisplayConeButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayConeButtonPushed, true);
            app.DisplayConeButton.Icon = fullfile(pathToMLAPP, 'Icons', 'round_image_black_48dp.png');
            app.DisplayConeButton.Text = 'Display';
            app.NameOfDiskCone = uieditfield(app.DiskConePanel, 'text');
            app.NameOfDiskCone.Editable = 'off';
            app.NameOfDiskCone.Enable = 'off';
            app.SelectDiskCone = uidropdown(app.DiskConePanel);
            app.SelectDiskCone.Items = {'488 nm ', '561 nm', '640 nm', '488 nm (13/12/2019 - 15/03/2020)', '561 nm (13/12/2019 - 15/03/2020)', '640 nm (13/12/2019 - 15/03/2020)', '488 nm (before 13/12/2019)', '561 nm (before 13/12/2019)', '640 nm (before 13/12/2019)', 'no distortions', 'other'};
            app.SelectDiskCone.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectDiskCone.ValueChangedFcn = createCallbackFcn(app, @SelectDiskConeValueChanged, true);
            app.SelectDiskCone.Tooltip = {'Select disk cone depending on wavelength and acquisition date'};
            app.SelectDiskCone.Value = '1';
            app.DarkPanel = uipanel(app.AdvancedTab);
            app.DarkPanel.Title = 'Dark';
            app.CalculatedDarkValueCheckBox = uicheckbox(app.DarkPanel);
            app.CalculatedDarkValueCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatedDarkValueCheckBoxValueChanged, true);
            app.CalculatedDarkValueCheckBox.Text = 'Calculated dark value';
            app.CalculatedDarkValueCheckBox.Value = true;
            app.UserdarkvalueEditFieldLabel = uilabel(app.DarkPanel);
            app.UserdarkvalueEditFieldLabel.Text = 'User dark value ';
            app.UserDarkValueEditField = uieditfield(app.DarkPanel, 'numeric');
            app.UserDarkValueEditField.Limits = [480 Inf];
            app.UserDarkValueEditField.ValueDisplayFormat = '%.0f';
            app.UserDarkValueEditField.Enable = 'off';
            app.UserDarkValueEditField.Value = 480;
            app.CalculatedDarkValueEditField = uieditfield(app.DarkPanel, 'numeric');
            app.CalculatedDarkValueEditField.Limits = [0 Inf];
            app.CalculatedDarkValueEditField.ValueDisplayFormat = '%.0f';
            app.CalculatedDarkValueEditField.Editable = 'off';
            app.CalculatedDarkValueEditField.Enable = 'off';
            app.CalculatedDarkValueEditField.Value = 480;
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.InfoAbout = uitextarea(app.AboutTab);
            app.InfoAbout.Editable = 'off';
            app.InfoAbout.Value = {'POLARIMETRY ANALYSIS '; ''; ''; 'Version: 2.0.0 (March 29, 2022)'; ''; ''; ''; 'Based on a code originally developed by Sophie Brasselet (Institut Fresnel, CNRS)'; ''; 'The source code is available at github.com/cchandre/Polarimetry '; ''; 'To report bugs, send an email to '; '            manos.mavrakis@cnrs.fr  (Manos Mavrakis, Institut Fresnel, CNRS) '; '            cristel.chandre@cnrs.fr  (Cristel Chandre, Institut de Mathématiques de Marseille, CNRS)'; ''; ''; 'Copyright (C) 2019 by Cristel Chandre <cristel.chandre@cnrs.fr>'; ''; 'Permission to use, copy, modify, and/or distribute this software for any purpose with or without fee is hereby granted.'; ''; 'THE SOFTWARE IS PROVIDED "AS IS" AND THE AUTHOR DISCLAIMS ALL WARRANTIES WITH REGARD TO THIS SOFTWARE INCLUDING ALL IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS. IN NO EVENT SHALL THE AUTHOR BE LIABLE FOR ANY SPECIAL, DIRECT, INDIRECT, OR CONSEQUENTIAL DAMAGES OR ANY DAMAGES WHATSOEVER RESULTING FROM LOSS OF USE, DATA OR PROFITS, WHETHER IN AN ACTION OF CONTRACT, NEGLIGENCE OR OTHER TORTIOUS ACTION, ARISING OUT OF OR IN CONNECTION WITH THE USE OR PERFORMANCE OF THIS SOFTWARE.'};
            app.CheckWebsite = uibutton(app.AboutTab, 'push');
            app.CheckWebsite.ButtonPushedFcn = createCallbackFcn(app, @CheckWebsiteButtonPushed, true);
            app.CheckWebsite.Icon = fullfile(pathToMLAPP, 'Icons', 'round_web_black_48dp.png');
            app.CheckWebsite.Text = 'CHECK WEBSITE FOR UPDATES';
            % Show the figure after all components are created
            app.PolarimetryAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolarimetryPlus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolarimetryAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolarimetryAnalysisUIFigure)
        end
    end
end